classdef Install_MAGEMin < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InstallMAGEMinUIFigure      matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        IntroTab                    matlab.ui.container.Tab
        Image                       matlab.ui.control.Image
        InstallMAGEMinLabel         matlab.ui.control.Label
        TextArea                    matlab.ui.control.TextArea
        IlldoitmyselfButton         matlab.ui.control.Button
        GuidemeButton               matlab.ui.control.Button
        Step13Tab                   matlab.ui.container.Tab
        Image_2                     matlab.ui.control.Image
        installJuliaLabel           matlab.ui.control.Label
        TextArea_2                  matlab.ui.control.TextArea
        NextButton_2                matlab.ui.control.Button
        DirectoryEditFieldLabel     matlab.ui.control.Label
        DirectoryEditField          matlab.ui.control.EditField
        Step23Tab                   matlab.ui.container.Tab
        Image_3                     matlab.ui.control.Image
        downloadMAGEMinLabel        matlab.ui.control.Label
        TextArea_3                  matlab.ui.control.TextArea
        NextButton                  matlab.ui.control.Button
        DownloadMAGEMinButton       matlab.ui.control.Button
        Step33Tab                   matlab.ui.container.Tab
        Image_4                     matlab.ui.control.Image
        testMAGEMinLabel            matlab.ui.control.Label
        TextArea_4                  matlab.ui.control.TextArea
        FinishButton                matlab.ui.control.Button
        TestButton                  matlab.ui.control.Button
        PressurekbarEditFieldLabel  matlab.ui.control.Label
        PressurekbarEditField       matlab.ui.control.NumericEditField
        TemperatureCEditFieldLabel  matlab.ui.control.Label
        TemperatureCEditField       matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('./pics/');     % add figures
            
            app.InstallMAGEMinUIFigure.Visible = 'off';
            movegui(app.InstallMAGEMinUIFigure,"center")
            app.InstallMAGEMinUIFigure.Visible = 'on';
            
        end

        % Button pushed function: IlldoitmyselfButton
        function IlldoitmyselfButtonPushed(app, event)
            app.delete;
        end

        % Button pushed function: GuidemeButton
        function GuidemeButtonPushed(app, event)
            % Move to tab 2
            app.TabGroup.SelectedTab = app.Step13Tab;
            
        end

        % Button pushed function: NextButton_2
        function NextButton_2Pushed(app, event)
            % Move to tab 3
            app.TabGroup.SelectedTab = app.Step23Tab;
            app.DownloadMAGEMinButton.FontColor =[0.29,0.48,0.05];
            app.DownloadMAGEMinButton.Enable = true;
            app.NextButton_2.FontColor = 'k';
              
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            % Move to tab 4
            app.TabGroup.SelectedTab = app.Step33Tab;
            
            app.TestButton.Enable = true;
            app.TestButton.FontColor =[0.29,0.48,0.05];
            
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
             f = msgbox('Please restart PlotPseudoSection');
              
            % We actually have to restart the GUI after this
            app.delete;
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            % Run a MAGEMin calculation
            P = app.PressurekbarEditField.Value;
            T = app.TemperatureCEditField.Value;
            
            Computation.BinaryMethod = 'Default';
            exe = MAGEMin_exe(Computation);
            command = [exe, ' --Pres=',num2str(P),' --Temp=',num2str(T),' --test=0'];
            
            environmental_variables;
            Computation.path_bin = path_bin;
            Computation.path_dylib = path_dylib;
            
            
            command = add_dynamic_libs(command, Computation);
            system(command);
            
            
            app.TestButton.FontColor = 'k';
            app.FinishButton.Enable = true;
            app.FinishButton.FontColor =[0.29,0.48,0.05];
            
            
        end

        % Button pushed function: DownloadMAGEMinButton
        function DownloadMAGEMinButtonPushed(app, event)
            % Call julia & install MAGEMin_jll
            
            % Julia binary
            julia_dir = app.DirectoryEditField.Value;
            if isunix
                julia_exec = [julia_dir,filesep,'julia']
            else
                julia_exec = [julia_dir,filesep,'julia.exe']
            end
            
            % Call julia 
            command = [julia_exec,' .',filesep,'julia',filesep,'install_MAGEMin.jl'];
            
            % Install MAGEMin_jll
            system(command)
            
            % Change color 
            app.DownloadMAGEMinButton.FontColor = 'k';
            app.DownloadMAGEMinButton.Enable = true;
            
            app.NextButton.FontColor =[0.29,0.48,0.05];
            app.NextButton.Enable = true;
            
        end

        % Value changed function: DirectoryEditField
        function DirectoryEditFieldValueChanged(app, event)
            value = app.DirectoryEditField.Value;
            
            app.NextButton_2.Enable = true;
            app.NextButton_2.FontColor = [0.29,0.48,0.05];
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InstallMAGEMinUIFigure = uifigure('Visible', 'off');
            app.InstallMAGEMinUIFigure.Name = 'Install MAGEMin';
            app.TabGroup = uitabgroup(app.InstallMAGEMinUIFigure);
            app.IntroTab = uitab(app.TabGroup);
            app.IntroTab.Title = 'Intro';
            app.Image = uiimage(app.IntroTab);
            app.Image.ImageSource = 'Intro.png';
            app.InstallMAGEMinLabel = uilabel(app.IntroTab);
            app.InstallMAGEMinLabel.Text = 'Install MAGEMin';
            app.TextArea = uitextarea(app.IntroTab);
            app.TextArea.Value = {'Thanks for your interest in MAGEMin, the parallel Mineral Assemble Gibbs Energy Minimisation package!'; 'Before you can start, you''ll need to install the MAGEMin binary.'; 'You can either compile it yourself (see webpage) or use the precompiled binaries we ship through julia.'; ''};
            app.IlldoitmyselfButton = uibutton(app.IntroTab, 'push');
            app.IlldoitmyselfButton.ButtonPushedFcn = createCallbackFcn(app, @IlldoitmyselfButtonPushed, true);
            app.IlldoitmyselfButton.Text = 'I''ll do it myself';
            app.GuidemeButton = uibutton(app.IntroTab, 'push');
            app.GuidemeButton.ButtonPushedFcn = createCallbackFcn(app, @GuidemeButtonPushed, true);
            app.GuidemeButton.Text = 'Guide me >>';
            app.Step13Tab = uitab(app.TabGroup);
            app.Step13Tab.Title = 'Step 1/3';
            app.Image_2 = uiimage(app.Step13Tab);
            app.Image_2.ImageSource = 'Step1.png';
            app.installJuliaLabel = uilabel(app.Step13Tab);
            app.installJuliaLabel.Text = 'install Julia';
            app.TextArea_2 = uitextarea(app.Step13Tab);
            app.TextArea_2.Value = {'Please install the julia scientific programming language from julialang.org on your machine (version >1.6). '; 'After that start julia & type: '; 'julia> println(Sys.BINDIR)'; 'Copy this in the directory below (and push enter)'};
            app.NextButton_2 = uibutton(app.Step13Tab, 'push');
            app.NextButton_2.ButtonPushedFcn = createCallbackFcn(app, @NextButton_2Pushed, true);
            app.NextButton_2.Enable = 'off';
            app.NextButton_2.Text = 'Next >>';
            app.DirectoryEditFieldLabel = uilabel(app.Step13Tab);
            app.DirectoryEditFieldLabel.Text = 'Directory:';
            app.DirectoryEditField = uieditfield(app.Step13Tab, 'text');
            app.DirectoryEditField.ValueChangedFcn = createCallbackFcn(app, @DirectoryEditFieldValueChanged, true);
            app.Step23Tab = uitab(app.TabGroup);
            app.Step23Tab.Title = 'Step 2/3';
            app.Image_3 = uiimage(app.Step23Tab);
            app.Image_3.ImageSource = 'Step2.png';
            app.downloadMAGEMinLabel = uilabel(app.Step23Tab);
            app.downloadMAGEMinLabel.Text = 'download MAGEMin';
            app.TextArea_3 = uitextarea(app.Step23Tab);
            app.TextArea_3.Value = {'Now we will download & install the MAGEMin_jll julia package'; ''; 'This is also a good time to check how many cores you have on your machine (you can use them all!) '};
            app.NextButton = uibutton(app.Step23Tab, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Enable = 'off';
            app.NextButton.Text = 'Next >>';
            app.DownloadMAGEMinButton = uibutton(app.Step23Tab, 'push');
            app.DownloadMAGEMinButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadMAGEMinButtonPushed, true);
            app.DownloadMAGEMinButton.Enable = 'off';
            app.DownloadMAGEMinButton.Text = 'Download MAGEMin';
            app.Step33Tab = uitab(app.TabGroup);
            app.Step33Tab.Title = 'Step 3/3';
            app.Image_4 = uiimage(app.Step33Tab);
            app.Image_4.ImageSource = 'Step3.png';
            app.testMAGEMinLabel = uilabel(app.Step33Tab);
            app.testMAGEMinLabel.Text = 'test MAGEMin';
            app.TextArea_4 = uitextarea(app.Step33Tab);
            app.TextArea_4.Value = {'Lets see if it works, by changing P/T (check out the MATLAB Command Window)'};
            app.FinishButton = uibutton(app.Step33Tab, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Enable = 'off';
            app.FinishButton.Text = 'Finish';
            app.TestButton = uibutton(app.Step33Tab, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Enable = 'off';
            app.TestButton.Text = 'Test';
            app.PressurekbarEditFieldLabel = uilabel(app.Step33Tab);
            app.PressurekbarEditFieldLabel.Text = 'Pressure [kbar]';
            app.PressurekbarEditField = uieditfield(app.Step33Tab, 'numeric');
            app.PressurekbarEditField.Value = 15;
            app.TemperatureCEditFieldLabel = uilabel(app.Step33Tab);
            app.TemperatureCEditFieldLabel.Text = 'Temperature [C]';
            app.TemperatureCEditField = uieditfield(app.Step33Tab, 'numeric');
            app.TemperatureCEditField.Value = 800;
            % Show the figure after all components are created
            app.InstallMAGEMinUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Install_MAGEMin

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InstallMAGEMinUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InstallMAGEMinUIFigure)
        end
    end
end