classdef PlotPseudosection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MAGEMinPseudoSectionViewerUIFigure  matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        InstallMAGEMinMenu              matlab.ui.container.Menu
        LoadMenu                        matlab.ui.container.Menu
        SaveasMenu                      matlab.ui.container.Menu
        OpeninnewFigureMenu             matlab.ui.container.Menu
        ExporttoLaMEMGeoParamsMenu      matlab.ui.container.Menu
        QuitMenu                        matlab.ui.container.Menu
        TabGroup3                       matlab.ui.container.TabGroup
        SimulationTab                   matlab.ui.container.Tab
        SimulationinfoPanel             matlab.ui.container.Panel
        SaveButton                      matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
        SaveFileName                    matlab.ui.control.Button
        DiagramtypeListBoxLabel         matlab.ui.control.Label
        DiagramtypeListBox              matlab.ui.control.ListBox
        SimulationParametersPanel       matlab.ui.container.Panel
        UITable                         matlab.ui.control.Table
        CoarsegridresolutionLabel       matlab.ui.control.Label
        RefinementsDropDownLabel        matlab.ui.control.Label
        RefinementsDropDown             matlab.ui.control.DropDown
        VerboselevelDropDownLabel       matlab.ui.control.Label
        VerboselevelDropDown            matlab.ui.control.DropDown
        ParallelcomputationsCheckBox    matlab.ui.control.CheckBox
        GammaestimationCheckBox         matlab.ui.control.CheckBox
        MPIRanksEditFieldLabel_2        matlab.ui.control.EditField
        MPIRanksEditFieldLabel_1        matlab.ui.control.Label
        MPIRanksEditFieldLabel_3        matlab.ui.control.Label
        MPIRanksEditFieldLabel_4        matlab.ui.control.EditField
        SelectMPIexec                   matlab.ui.control.Button
        UsexEOSuponrefinementCheckBox   matlab.ui.control.CheckBox
        xEOSDropDown                    matlab.ui.control.DropDown
        num_Neighbours                  matlab.ui.control.NumericEditField
        Gamma_Dropdown                  matlab.ui.control.DropDown
        EMFracDropDown                  matlab.ui.control.DropDown
        UseEMFractionuponrefinementCheckBox  matlab.ui.control.CheckBox
        num_Neighbours_EMFrac           matlab.ui.control.NumericEditField
        PerformcomputationsonremoteserverCheckBox  matlab.ui.control.CheckBox
        MAGEMinexecutableLabel          matlab.ui.control.Label
        Binary_Switch                   matlab.ui.control.Switch
        SolverDropDownLabel             matlab.ui.control.Label
        SolverDropDown                  matlab.ui.control.DropDown
        PressureLabel                   matlab.ui.control.Label
        TemperatureLabel                matlab.ui.control.Label
        TemperatureEditField            matlab.ui.control.NumericEditField
        PressureEditField               matlab.ui.control.NumericEditField
        Labelstyle                      matlab.ui.control.Label
        LabelstyleDropDown              matlab.ui.control.DropDown
        BufferDropDownLabel             matlab.ui.control.Label
        buffer                          matlab.ui.control.DropDown
        MultiplierLabel                 matlab.ui.control.Label
        buffer_n                        matlab.ui.control.NumericEditField
        ChemicalcompositionPanel        matlab.ui.container.Panel
        UITableChemistry                matlab.ui.control.Table
        PredefinedtestDropDownLabel     matlab.ui.control.Label
        PredefinedtestDropDown          matlab.ui.control.DropDown
        Switch                          matlab.ui.control.Switch
        LoadfromfileButton              matlab.ui.control.Button
        SystemunitDropDown              matlab.ui.control.DropDown
        BulkCaption                     matlab.ui.control.Label
        TitleEditField                  matlab.ui.control.EditField
        TitleEditFieldLabel             matlab.ui.control.Label
        FilenameEditField               matlab.ui.control.EditField
        FilenameEditFieldLabel          matlab.ui.control.Label
        ThermodynamicdatabasePanel      matlab.ui.container.Panel
        DatabaseListBox                 matlab.ui.control.ListBox
        LimitCaopxCheckBox              matlab.ui.control.CheckBox
        SwitchCpx                       matlab.ui.control.Switch
        CaOpxLim                        matlab.ui.control.NumericEditField
        StartnewcomputationButton       matlab.ui.control.Button
        Image2                          matlab.ui.control.Image
        v137Label                       matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        PseudosectionTab                matlab.ui.container.Tab
        TabGroup6                       matlab.ui.container.TabGroup
        InformationRefineTab            matlab.ui.container.Tab
        AxesFontsizeLabel               matlab.ui.control.Label
        Axes_Fontsize                   matlab.ui.control.NumericEditField
        TitleFontsizeLabel              matlab.ui.control.Label
        Title_Fontsize                  matlab.ui.control.NumericEditField
        ColoraxisLabel                  matlab.ui.control.Label
        Caxis_min                       matlab.ui.control.NumericEditField
        Caxis_max                       matlab.ui.control.NumericEditField
        DISPLAYOPTIONSLabel             matlab.ui.control.Label
        AddlabelsCheckBox               matlab.ui.control.CheckBox
        removealllabelsButton           matlab.ui.control.Button
        removelastlabelButton           matlab.ui.control.Button
        AddlabellineCheckBox            matlab.ui.control.CheckBox
        LABELPHASEASSEMBLAGELabel       matlab.ui.control.Label
        SINGLEPOINTOPTIONSLabel         matlab.ui.control.Label
        REFINEGRIDCALCULATIONLabel      matlab.ui.control.Label
        StartrefinementButton           matlab.ui.control.Button
        ShowcellcentersselectpointsButton  matlab.ui.control.Button
        RefineselectedareaButton        matlab.ui.control.Button
        RefineallphaseboundariesButton  matlab.ui.control.Button
        UniformrefinementButton         matlab.ui.control.Button
        RefineunivariantpointsButton    matlab.ui.control.Button
        StableAssemblagePanel           matlab.ui.container.Panel
        UITableStableAssemblage         matlab.ui.control.Table
        DatapointPanel                  matlab.ui.container.Panel
        TextAreaInfo                    matlab.ui.control.TextArea
        SaveequilibriumdataButton       matlab.ui.control.Button
        newpointsTextAreaLabel          matlab.ui.control.Label
        newpointsTextArea               matlab.ui.control.TextArea
        StyleLabel_3                    matlab.ui.control.Label
        SelectPt_MarkerStyle            matlab.ui.control.EditField
        ColormapDropDownLabel           matlab.ui.control.Label
        ColormapDropDown                matlab.ui.control.DropDown
        DisplayfieldDropDownLabel       matlab.ui.control.Label
        DisplayfieldDropDown            matlab.ui.control.DropDown
        ShowgridSwitchLabel             matlab.ui.control.Label
        ShowgridSwitch                  matlab.ui.control.Switch
        ShowreactionlineSwitchLabel     matlab.ui.control.Label
        ShowreactionlineSwitch          matlab.ui.control.Switch
        WidthLabel_3                    matlab.ui.control.Label
        SelectPt_MarkerWidth            matlab.ui.control.NumericEditField
        SizeLabel                       matlab.ui.control.Label
        SelectPt_MarkerSize             matlab.ui.control.NumericEditField
        IsocontourTab_3                 matlab.ui.container.Tab
        CHOOSEFIELDTOCONTOURLabel       matlab.ui.control.Label
        DataListBox                     matlab.ui.control.ListBox
        emnamesListBox                  matlab.ui.control.ListBox
        phaseEditField                  matlab.ui.control.EditField
        phaseEditFieldLabel             matlab.ui.control.Label
        displayisocontourButton         matlab.ui.control.Button
        removeallButton                 matlab.ui.control.Button
        removelastsButton               matlab.ui.control.Button
        Iso_label                       matlab.ui.control.Label
        Isopleth_2                      matlab.ui.control.Label
        SELECTRANGEANDSETRESOLUTIONLabel  matlab.ui.control.Label
        CHOOSEPLOTTINGSTYLELabel        matlab.ui.control.Label
        CONTROLISOPLETHSLabel           matlab.ui.control.Label
        minEditFieldLabel               matlab.ui.control.Label
        minEditField                    matlab.ui.control.EditField
        stepEditField                   matlab.ui.control.EditField
        stepEditFieldLabel              matlab.ui.control.Label
        maxEditField                    matlab.ui.control.EditField
        maxEditFieldLabel               matlab.ui.control.Label
        resEditField                    matlab.ui.control.EditField
        resEditFieldLabel               matlab.ui.control.Label
        TCLines_LineColor_4             matlab.ui.control.EditField
        ColorLabel                      matlab.ui.control.Label
        TCLines_LineColor_5             matlab.ui.control.EditField
        FontsizeLabel                   matlab.ui.control.Label
        TCLines_LineWidth_1             matlab.ui.control.NumericEditField
        WidthLabel_2                    matlab.ui.control.Label
        TCLines_LineColor_2             matlab.ui.control.EditField
        StyleLabel_2                    matlab.ui.control.Label
        ExportfigureButton              matlab.ui.control.Button
        FileEditFieldLabel              matlab.ui.control.Label
        FileEditField                   matlab.ui.control.EditField
        NumPointsLabel                  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        PTXpathTab                      matlab.ui.container.Tab
        StableAssemblagePTX             matlab.ui.container.Panel
        UITableStableAssemblagePTX      matlab.ui.control.Table
        TextAreaInfoPTX                 matlab.ui.control.TextArea
        GridSwitch_2Label               matlab.ui.control.Label
        GridSwitchPTX                   matlab.ui.control.Switch
        ColormapDropDown_3Label         matlab.ui.control.Label
        ColormapDropDownPTX             matlab.ui.control.DropDown
        FieldLabel                      matlab.ui.control.Label
        DisplayfieldDropDownPTX         matlab.ui.control.DropDown
        AddpointButton                  matlab.ui.control.Button
        RemovelastButton                matlab.ui.control.Button
        RemoveallButton                 matlab.ui.control.Button
        RemovepointButton               matlab.ui.control.Button
        PressureTemperatureCompositionlistofpointsPanel  matlab.ui.container.Panel
        UITablePTX                      matlab.ui.control.Table
        PointidLabel                    matlab.ui.control.Label
        PointidEditField                matlab.ui.control.NumericEditField
        TabGroup7                       matlab.ui.container.TabGroup
        ModeTab                         matlab.ui.container.Tab
        UIAxesArea                      matlab.ui.control.UIAxes
        ph1Tab                          matlab.ui.container.Tab
        PhComp1                         matlab.ui.control.UIAxes
        ph2Tab                          matlab.ui.container.Tab
        PhComp2                         matlab.ui.control.UIAxes
        ph3Tab                          matlab.ui.container.Tab
        PhComp3                         matlab.ui.control.UIAxes
        ph4Tab                          matlab.ui.container.Tab
        PhComp4                         matlab.ui.control.UIAxes
        ph5Tab                          matlab.ui.container.Tab
        PhComp5                         matlab.ui.control.UIAxes
        ph6Tab                          matlab.ui.container.Tab
        PhComp6                         matlab.ui.control.UIAxes
        ph7Tab                          matlab.ui.container.Tab
        PhComp7                         matlab.ui.control.UIAxes
        ph8Tab                          matlab.ui.container.Tab
        PhComp8                         matlab.ui.control.UIAxes
        ph9Tab                          matlab.ui.container.Tab
        PhComp9                         matlab.ui.control.UIAxes
        ph10Tab                         matlab.ui.container.Tab
        PhComp10                        matlab.ui.control.UIAxes
        ph11Tab                         matlab.ui.container.Tab
        PhComp11                        matlab.ui.control.UIAxes
        ph12Tab                         matlab.ui.container.Tab
        PhComp12                        matlab.ui.control.UIAxes
        ph13Tab                         matlab.ui.container.Tab
        PhComp13                        matlab.ui.control.UIAxes
        ph14Tab                         matlab.ui.container.Tab
        PhComp14                        matlab.ui.control.UIAxes
        ph15Tab                         matlab.ui.container.Tab
        PhComp15                        matlab.ui.control.UIAxes
        ph16Tab                         matlab.ui.container.Tab
        PhComp16                        matlab.ui.control.UIAxes
        ph17Tab                         matlab.ui.container.Tab
        PhComp17                        matlab.ui.control.UIAxes
        ph18Tab                         matlab.ui.container.Tab
        PhComp18                        matlab.ui.control.UIAxes
        ph19Tab                         matlab.ui.container.Tab
        PhComp19                        matlab.ui.control.UIAxes
        ph20Tab                         matlab.ui.container.Tab
        PhComp20                        matlab.ui.control.UIAxes
        ComputecompositionCheckBox      matlab.ui.control.CheckBox
        UIAxesPTX                       matlab.ui.control.UIAxes
        TraceElementsTab                matlab.ui.container.Tab
        ExportfigureButton_2            matlab.ui.control.Button
        FileEditField_2Label            matlab.ui.control.Label
        FileEditField_2                 matlab.ui.control.EditField
        StableAssemblagePanel_2         matlab.ui.container.Panel
        UITableStableAssemblageTE       matlab.ui.control.Table
        TextAreaInfoTE                  matlab.ui.control.TextArea
        SaveSpectraButton               matlab.ui.control.Button
        phaseeqCheckBox                 matlab.ui.control.CheckBox
        ReferenceTextArea               matlab.ui.control.TextArea
        ComputetraceelementpartitioningButton  matlab.ui.control.Button
        UITableTraceElement             matlab.ui.control.Table
        Switch_2                        matlab.ui.control.Switch
        LoadfromfileButton_2            matlab.ui.control.Button
        PLOTFUNCTIONegelementratioLabel  matlab.ui.control.Label
        CHOOSETRACECOMPOSITIONLabel     matlab.ui.control.Label
        Validsufficesare_s_m_hb_cpx_opx_pl_g_olLabel  matlab.ui.control.Label
        PrefixLabel                     matlab.ui.control.Label
        DisplayButton                   matlab.ui.control.Button
        TitleEditFieldTE                matlab.ui.control.EditField
        ExampleDropDownLabel            matlab.ui.control.Label
        ExampleDropDown                 matlab.ui.control.DropDown
        CommandwindowLabel              matlab.ui.control.Label
        CommandwindowTextArea           matlab.ui.control.TextArea
        GridSwitchLabel                 matlab.ui.control.Label
        GridSwitch                      matlab.ui.control.Switch
        ColormapDropDown_2Label         matlab.ui.control.Label
        ColormapDropDown_2              matlab.ui.control.DropDown
        Image3                          matlab.ui.control.Image
        UIAxesTE                        matlab.ui.control.UIAxes
        UIAxesTESpectrum                matlab.ui.control.UIAxes
        ComputeTCsolutionforeverypointCheckBox  matlab.ui.control.CheckBox
    end

    % load data
    properties (Access = public)
        PseudoSectionData
        newPoints
        PlotData
        PlotDataTE
        PlotDataPTX
        PlotPhaseBounds
        PlotBrushData
        PTnum
        hField1
        hField2
        hCellCenter
        hPoints
        FileName
        FileDir
        PlotPoint
        PlotPointTE
        PlotPointPTX
        PlotPointPTXline
        hText
        hContour
        hlinePTX
        hContourHandle
        hContourLegendText
        hCaptionHandle
        hReactionLineHandle
    end
    
    properties (Access = private)
        oldpath   =   addpath([pwd,filesep,'pics'])
        oldpath1  =   addpath([pwd,filesep,'matlab'])
        oldpath2  =   addpath([pwd,filesep,'matlab/TEPM'])
    end
    
    
    methods (Access = private)
        
        % Plot initial phase diagram
        function [PseudoSectionData] = PlotInitialPseudoSection(app,PseudoSectionData)
            % Create color
            PhaseData   =   PseudoSectionData.PhaseData;
            
            % liquid fraction at every point
            liq         =   zeros(length(PhaseData),1);
            numPhase    =   zeros(length(PhaseData),1);
            Gibbs_vec   =   zeros(length(PhaseData),1);
            RhoTot_vec  =   zeros(length(PhaseData),1);
            RhoLiq_vec  =   zeros(length(PhaseData),1);
            RhoSol_vec  =   zeros(length(PhaseData),1);
            Vp_vec      =   zeros(length(PhaseData),1);
            Vs_vec      =   zeros(length(PhaseData),1);
            entropy_vec =   zeros(length(PhaseData),1);
            Status_vec  =   zeros(length(PhaseData),1);
            for i=1:length(PhaseData)
                liq(i)          =   PhaseData{i}.liq;
                numPhase(i)     =   PhaseData{i}.numStablePhases;
                Gibbs_vec(i)    =   PhaseData{i}.Gibbs;
                Gamma_vec(:,i)  =   PhaseData{i}.Gamma;
                RhoTot_vec(i)   =   PhaseData{i}.Density_total;
                RhoLiq_vec(i)   =   PhaseData{i}.Density_liq;
                RhoSol_vec(i)   =   PhaseData{i}.Density_sol;
                Vp_vec(i)       =   PhaseData{i}.Vp;
                Vs_vec(i)       =   PhaseData{i}.Vs;
                entropy_vec(i)  =   PhaseData{i}.entropy;
                Status_vec(i)   =   PhaseData{i}.Status;
                array(i).StableSolutions = PhaseData{i}.StableSolutions;
            end
            ReadPseudoSectionData_MAGEMin
            % 1) Give every solid solution and pure phase a number
            % 1a) Create a list with all stable phases/solutions in the diagram
            StableSol = array(1).StableSolutions;
            for i=1:length(array)
                sol = array(i).StableSolutions;
                for j=1:length(sol)
                    if ~(any(strcmp(StableSol,sol(j))))
                        StableSol(end+1)  = sol(j);
                    end
                end
                
            end
            StableSol = unique(StableSol);
            
            % 1b) Give them all a number
            % StablePhaseNumber = [1:length(StableSol)];  % NOTE: we may need to assign random numbers
            
            rng(0,'twister');   % reproducible random numbers
            StablePhaseNumber = randi([0 1e4],1,length(StableSol));
            
            
            % 1c)
            for i=1:length(array)
                NumAssemblage = 0;
                sol = unique(array(i).StableSolutions);
                for j=1:length(sol)
                    id = find(strcmp(StableSol,sol{j}));
                    
                    NumAssemblage = NumAssemblage + StablePhaseNumber(id);
                    
                end
                NumAssemblage_vec(i)       = NumAssemblage;
                PhaseData{i}.NumAssemblage = NumAssemblage;
            end
            
            
            % Display the # of points we currently have in the diagram
            app.NumPointsLabel.Text  = [num2str(length(PhaseData)),' points'];
            value = app.DiagramtypeListBox.Value;
            if value == 'PT'
                    title(app.UIAxes,app.TitleEditField.Value)
                    title(app.UIAxesTE,app.TitleEditField.Value)
                    title(app.UIAxesPTX,app.TitleEditField.Value)
            elseif value == 'PX'
                    upTitle                      = strcat(app.TitleEditField.Value,' (T = ',num2str(app.PseudoSectionData.FixedTemperature),' celcius)');
                    title(app.UIAxes,upTitle)
                    title(app.UIAxesTE,upTitle)
                    title(app.UIAxesPTX,upTitle)
            elseif value == 'TX'
                    upTitle                      = strcat(app.TitleEditField.Value,' (P = ',num2str(app.PseudoSectionData.FixedPressure),' kbar)');
                    title(app.UIAxes,upTitle)
                    title(app.UIAxesTE,upTitle)
                    title(app.UIAxesPTX,upTitle)
            end
%             set(app.UIAxes,'FontSize',app.Axes_Fontsize.Value);
%             set(app.UIAxes,'TitleFontSizeMultiplier',app.Title_Fontsize.Value/app.Axes_Fontsize.Value);
            set(app.UIAxesTE,'FontSize',app.Axes_Fontsize.Value);
            set(app.UIAxesTE,'TitleFontSizeMultiplier',app.Title_Fontsize.Value/app.Axes_Fontsize.Value);
            
            % Store data so that they are available in other routines
            PseudoSectionData.liq                =   liq;
            PseudoSectionData.numPhase           =   numPhase;
            PseudoSectionData.Status             =   Status_vec;
            PseudoSectionData.Gibbs              =   Gibbs_vec;
            PseudoSectionData.Rho_tot            =   RhoTot_vec;
            PseudoSectionData.Rho_sol            =   RhoSol_vec;
            PseudoSectionData.Rho_liq            =   RhoLiq_vec;
            PseudoSectionData.Vp                 =   Vp_vec;
            PseudoSectionData.Vs                 =   Vs_vec;
            PseudoSectionData.entropy            =   entropy_vec;
            PseudoSectionData.Gamma              =   Gamma_vec;
            PseudoSectionData.PhaseData          =   PhaseData;
            PseudoSectionData.NumAssemblage      =   NumAssemblage_vec;
            PseudoSectionData.StablePhaseNumber  =   StablePhaseNumber;
            PseudoSectionData.StableSol          =   StableSol;
            assignin('base','PseudoSectionData',PseudoSectionData);    % send to matlab command-line
            
        end
        % get pre-defined trace element spectrum
        function [ElementProportionsTable, Name] = GetPredefinedTraceElements(app,Tst);
            ElNames = {'Cs';'Rb';'K';'Ba';'Sr';'La';'Ce';'Pr';'Nd';'Sm';'Eu';'Gd';'Tb';'Dy';'Y';'Ho';'Er';'Tm';'Yb';'Lu';'Sc';'Ti';'Hf';'Zr';'U';'Th';'Ta';'Nb'};
            switch Tst
                case 0
                    ElProps = [4.961 78.36 380 760.4 0.427 27.59 53.02 7.342 26.14 5.993 1.291 6.044 1.193 9.622 71.75 2.755 11.18 0 20.8 4.603 7.905 25.31 4.228 124.9 4.304 9.93 0.892 9.984]';
                    Name     = 'Average Peridotite (USGS)';
                case 1
                    ElProps = [39.7 42.3 0 637 338 39.4 69.3 38.1 40.4 37.1 38.7 34.5 0 35.4 17 0 16.5 0 17.8 16.9 23.5 11400 18.5 75.2 24.2 17.3 25 75.6]';
                    Name     = 'MIXB (Qian et al., 2013)';
                otherwise
                    error('please add the composition to the matlab GUI')
            end
            ElementProportionsTable = table(ElNames,ElProps,'VariableNames',{'El','C'});
        end
        
        function [OxideOxProportionsTable, Name] = GetPredefinedChemistry(app,Test,database);
            
            if database == "ig"
                OxideNames = {'SiO2';'Al2O3';'CaO'; 'MgO'; 'FeOt'; 'K2O'; 'Na2O'; 'TiO2'; 'O'; 'Cr2O3'; 'H2O';};
                switch Test
                    case 0
                        OxProps = [  38.494  1.776  2.824 50.566 5.886  0.01  0.250  0.10  0.096  0.109 0]';
                        Name     = 'KLB-1';
                    case 1
                        OxProps = [ 50.72,	9.16,15.21, 16.25,	7.06	0.01 1.47 0.39 0.35  0.01  0.0]';
                        Name     = 'RE46';
                    case 2
                        OxProps = [ 53.21,	9.41,	12.21,	12.21,	8.65,	0.09,	2.90,1.21,0.69,0.02, 0.0]';
                        Name     = 'N-MORB';
                    case 3
                        OxProps = [ 45.25;	8.89;	12.22;	24.68;6.45;	0.03;	1.39;0.67;0.11;0.02;0.0;];
                        Name     = 'MIX1G';
                    case 4
                        OxProps = [ 54.40,	12.96, 11.31, 7.68, 8.63,	0.54, 3.93, 0.79, 0.41, 0.01, 0.0]';
                        Name     = 'High Al Basalt';
                    case 5
                        OxProps = [66.01,11.98,7.06,4.16,5.30,1.57,4.12,0.66,0.97,0.01, 50]';
                        Name     = 'Tonalite 101';
                    case 6
                        OxProps = [  50.0810  8.6901  11.6698 12.1438 7.7832  0.2150  2.4978  1.0059  0.4670  0.0100 5.4364]';
                        Name     = 'WetBasalt';
                    case 7
                        OxProps = [  40.399  0.923  0.412 54.091 3.929 0.01 0.024 0.003 0.095 0.122 0.0]';
                        Name     = 'BP002 harzburgite';
                    otherwise
                        error('please add the composition to the matlab GUI')
                end
            elseif database == "igd"
                OxideNames = {'SiO2';'Al2O3';'CaO'; 'MgO'; 'FeOt'; 'K2O'; 'Na2O'; 'TiO2'; 'O'; 'Cr2O3'; 'H2O';};
                switch Test
                    case 0
                        OxProps = [  38.494  1.776  2.824 50.566 5.886  0.01  0.250  0.10  0.096  0.109 0]';
                        Name     = 'KLB-1';
                    case 1
                        OxProps = [ 50.72,	9.16,15.21, 16.25,	7.06	0.01 1.47 0.39 0.35  0.01  0.0]';
                        Name     = 'RE46';
                    case 2
                        OxProps = [ 53.21,	9.41,	12.21,	12.21,	8.65,	0.09,	2.90,1.21,0.69,0.02, 0.0]';
                        Name     = 'N-MORB';
                    case 3
                        OxProps = [ 45.25;	8.89;	12.22;	24.68;6.45;	0.03;	1.39;0.67;0.11;0.02;0.0;];
                        Name     = 'MIX1G';
                    case 4
                        OxProps = [ 54.40,	12.96, 11.31, 7.68, 8.63,	0.54, 3.93, 0.79, 0.41, 0.01, 0.0]';
                        Name     = 'High Al Basalt';
                    case 5
                        OxProps = [66.01,11.98,7.06,4.16,5.30,1.57,4.12,0.66,0.97,0.01, 50]';
                        Name     = 'Tonalite 101';
                    case 6
                        OxProps = [  50.0810  8.6901  11.6698 12.1438 7.7832  0.2150  2.4978  1.0059  0.4670  0.0100 5.4364]';
                        Name     = 'WetBasalt';
                    case 7
                        OxProps = [  40.399  0.923  0.412 54.091 3.929 0.01 0.024 0.003 0.095 0.122 0.0]';
                        Name     = 'BP002 harzburgite';
                    otherwise
                        error('please add the composition to the matlab GUI')
                end
            elseif database == "alk"
                OxideNames = {'SiO2';'Al2O3';'CaO'; 'MgO'; 'FeOt'; 'K2O'; 'Na2O'; 'TiO2'; 'O'; 'Cr2O3'; 'H2O';};
                switch Test
                    case 0
                        OxProps = [  63.84 13.72 3.09 1.55 5.07 4.04 9.38 0.78 1.47 0.01 0.0]';
                        Name     = 'Ne-Syenite';
                    case 1
                        OxProps = [ 70.06 11.63 2.76 1.5 4.3 3.72 6.41 0.51 0.89 0.01 0.0]';
                        Name     = 'Syenite';
                    case 2
                        OxProps = [ 48.97 12.76 12.87 5.21 7.97 1.66 10.66 1.36 1.66 0.01 0.0]';
                        Name     = 'Ijolite';
                    case 3
                        OxProps = [ 53.221 11.671 10.009 6.597 7.053 5.582 2.956 0.825 1.94 0.146 0.0]';
                        Name     = '9418';
                    case 4
                        OxProps = [  38.494  1.776  2.824 50.566 5.886  0.01  0.250  0.10  0.096  0.109 0]';
                        Name     = 'KLB-1';
                    otherwise
                        error('please add the composition to the matlab GUI')
                end
            elseif database == "mp"
                OxideNames = {'SiO2';'Al2O3';'CaO'; 'MgO'; 'FeOt'; 'K2O'; 'Na2O'; 'TiO2'; 'O'; 'MnO'; 'H2O';};
                switch Test
                    case 0
                        % 1  World-Mean pelite
                        OxProps = [  71.00 12.81 0.77 3.98 6.34 2.79 1.48 0.76 0.1 0.075 40.0]';
                        Name     = 'WM-pelite-H2O-oversat';
                    case 1
                        OxProps = [ 71.00 12.81 0.77 3.98 6.34 2.79 1.48 0.76 0.1 0.075 5.0]';
                        Name     = 'WM-pelite-H2O-undersat';
                    case 2
                        OxProps = [ 64.578 13.651 1.586 5.529 8.025 2.943 2.000 0.907 0.65 0.175 40.0]';
                        Name     = 'Pelite-oversat';
                    case 3
                        OxProps = [ 64.578 13.651 1.586 5.529 8.025 2.943 2.000 0.907 0.65 0.175 6.244]';
                        Name     = 'Pelite-undersat';
                    case 4
                        OxProps = [ 73.9880 8.6143 2.0146 2.7401 3.8451 1.7686 2.4820 0.6393 0.11 0.0630  10.0]';
                        Name     = 'El-Oro-Metapelite';
                    otherwise
                        error('please add the composition to the matlab GUI')
                end
            elseif database == "mb"
                OxideNames = {'SiO2';'Al2O3';'CaO'; 'MgO'; 'FeOt'; 'K2O'; 'Na2O'; 'TiO2'; 'O'; 'H2O';};
                switch Test
                    case 0
                        % 1  SM89
                        OxProps = [  52.47 9.10 12.21 12.71 8.15 0.23 2.61 1.05 1.47 20.0]';
                        Name     = 'SM89-MORB';
                    case 1
                        OxProps = [ 51.08 9.68 13.26 11.21 11.66 0.16 0.79 1.37 0.80 20.0]';
                        Name     = 'AG9-Amphibolite';
                    case 2
                        OxProps = [ 60.05 6.62 8.31 9.93 6.57 0.44 1.83 1.27 0.33 4.64]';
                        Name     = 'SQA-Amphibolite';
                    case 3
                        OxProps = [ 53.96 9.26 10.15 8.11 10.14 0.11 2.54 1.35 0.98 3.42]';
                        Name     = 'BL478';
                    otherwise
                        error('please add the composition to the matlab GUI')
                end
            elseif database == "um"
                OxideNames = {'SiO2';'Al2O3'; 'MgO'; 'FeOt'; 'O'; 'H2O'; 'S'};
                switch Test
                    case 0
                        % Peridotite with water
                        OxProps = [  20.044  0.6256 29.24 3.149 0.7324 46.755 0.3]';
                        Name     = 'Serpentinite (Oxidised)';
                    case 1
                        % Peridotite with water
                        OxProps = [  20.044  0.6256 29.24 3.149 0.1324 46.755 0.3]';
                        Name     = 'Serpentinite (Reduced)';
                    otherwise
                        error('please add the composition to the matlab GUI')
                end
            elseif database == "umj"
                OxideNames = {'SiO2';'Al2O3'; 'MgO'; 'FeOt'; 'O'; 'H2O'; 'CO2';'S'};
                switch Test
                    case 0
                        % Peridotite with water
                        OxProps = [  20.044  0.6256 29.24 3.149 0.7324 46.755 10.0 0.3]';
                        Name     = 'Serpentinite (Oxidised)';
                    case 1
                        % Peridotite with water
                        OxProps = [  20.044  0.6256 29.24 3.149 0.1324 46.755 10.0 0.3]';
                        Name     = 'Serpentinite (Reduced)';
                    otherwise
                        error('please add the composition to the matlab GUI')
                end
        end
        OxideOxProportionsTable = table(OxideNames,OxProps,OxProps,'VariableNames',{'Oxide','mol %','mol2 %'});
            
        end
        
        function app = FillInSimulationTab(app, PseudoSectionData)
            % This fills in the various fields with the "Simulation" Tab
            % with values of the PseudoSection structure
            
            % As this routine is called at least twice (upon starting and
            % upon loading a new field), it is moved into a function
            
            % Set default parameters
            app.FilenameEditField.Value         =  PseudoSectionData.FileName;
            app.TitleEditField.Value            =  PseudoSectionData.Name;
            
            app.UITable.Data(1,:)               = PseudoSectionData.CoarseGrid.P; % Pressure [kbar]: min, max, step
            app.UITable.Data(2,:)               = PseudoSectionData.CoarseGrid.T; % Temperature [C]: min, max, step
         
            app.RefinementsDropDown.Value                  =   num2str(PseudoSectionData.Computation.RefinementLevels);
            app.VerboselevelDropDown.Value                      =   num2str(PseudoSectionData.Computation.Verbose);
            app.SolverDropDown.Value                            =   PseudoSectionData.Computation.solver;
            app.LimitCaopxCheckBox.Value                        =   PseudoSectionData.Computation.limitCaOpx;
            app.CaOpxLim.Value                                  =   PseudoSectionData.Computation.CaOpxLim;
            app.buffer.Value                                    =   PseudoSectionData.Computation.buffer;
            app.buffer_n.Value                              =   PseudoSectionData.Computation.buffer_n;
            app.LabelstyleDropDown.Value                        =   PseudoSectionData.Computation.labelstyle;
%             app.DatabaseDropDown.Value                        =   PseudoSectionData.Computation.db;
            app.DatabaseListBox.Value                           =   PseudoSectionData.Computation.db;
            app.ParallelcomputationsCheckBox.Value              =   PseudoSectionData.Computation.AllPoints;
            app.GammaestimationCheckBox.Value                   =   PseudoSectionData.Computation.UseGammaEstimation;
            app.MPIRanksEditFieldLabel_2.Value                  =   num2str(PseudoSectionData.Computation.NumRanks);
            app.MPIRanksEditFieldLabel_4.Value                  =   PseudoSectionData.Computation.MPI_dir;
            app.UsexEOSuponrefinementCheckBox.Value             =   PseudoSectionData.Computation.Use_xEOS;
            app.PerformcomputationsonremoteserverCheckBox.Value =   PseudoSectionData.Computation.RemoteServer;
            app.xEOSDropDown.Value                              =   PseudoSectionData.Computation.EOS_Method;
            
            if isfield(PseudoSectionData.Computation,'Gamma_Method')
                % for backwards compatibility - can be removed @ some stage
                app.Gamma_Dropdown.Value                              =   PseudoSectionData.Computation.Gamma_Method;
            end
            
            app.num_Neighbours.Value                            =   PseudoSectionData.Computation.num_Neighbours;
            
            
            % Grey out options
            value = app.ParallelcomputationsCheckBox.Value;
            if value 
                Enable = "on";
            else
                Enable = "off";
            end
            app.MPIRanksEditFieldLabel_1.Enable = Enable;
            app.MPIRanksEditFieldLabel_2.Enable = Enable;
            switch app.Binary_Switch.Value
                case 'Local'
                    app.MPIRanksEditFieldLabel_3.Enable = Enable;
                    app.MPIRanksEditFieldLabel_4.Enable = Enable;
                    app.SelectMPIexec.Enable            = Enable;
            end
            
            % TRACE ELEMENT
            if ~isnan(PseudoSectionData.TraceElement.Predefined)
                % we use a build-in test with predefined chemistry
                Tst                                 =   PseudoSectionData.TraceElement.Predefined;
                app.ExampleDropDown.Value           =   num2str(Tst);
                app.Switch_2.Value                  =   'Preset';
                app.UITableTraceElement.Enable      =   'off';
                
                % obtain chemistry for the predefined tests
                [ElementProportionsTable, Name]     =   GetPredefinedTraceElements(app,Tst);
            else
                % we do not use a predefined chemistry
                app.Switch_2.Value                  =   'Custom';
                ElementProportionsTable             =   PseudoSectionData.TraceElement.OxProp;
                app.UITableTraceElement.Enable      =   'on';
            end   
            
            % BULK ROCK COMPOSITION
            if ~isnan(PseudoSectionData.Chemistry.Predefined)
                % we use a build-in test with predefined chemistry
                Test                                =   PseudoSectionData.Chemistry.Predefined;
                database                            =   app.DatabaseListBox.Value;
                app.PredefinedtestDropDown.Value    =   num2str(Test);
                app.Switch.Value                    =   'Preset bulk';
                app.UITableChemistry.Enable         =   'off';
                
                % obtain chemistry for the predefined tests
                [OxideOxProportionsTable, Name]    =   GetPredefinedChemistry(app,Test,database);
                app.TitleEditField.Value            =   Name;
                
            else
                % we do not use a predefined chemistry
                app.Switch.Value                    =   'Custom bulk';
                OxideOxProportionsTable            =   PseudoSectionData.Chemistry.OxProp;
                app.UITableChemistry.Enable         =   'on';
            end
            
            
            app.UITableChemistry.Data = OxideOxProportionsTable; % set table
            app.UITableTraceElement.Data = ElementProportionsTable; % set table
            value = app.DiagramtypeListBox.Value;
            if isfield(app,'UIAxes')
                if value == 'PT'
                        title(app.UIAxes,app.TitleEditField.Value)
                        title(app.UIAxesTE,app.TitleEditField.Value)
                        title(app.UIAxesPTX,app.TitleEditField.Value)
                    elseif value == 'PX'
                        upTitle                      = strcat(app.TitleEditField.Value,' (T = ',num2str(app.PseudoSectionData.FixedTemperature),' celcius)');
                        title(app.UIAxes,upTitle)
                        title(app.UIAxesTE,upTitle)
                        title(app.UIAxesPTX,upTitle)
                    elseif value == 'TX'
                        upTitle                      = strcat(app.TitleEditField.Value,' (P = ',num2str(app.PseudoSectionData.FixedPressure),' kbar)');
                        title(app.UIAxes,upTitle)
                        title(app.UIAxesTE,upTitle)
                        title(app.UIAxesPTX,upTitle)
                end
            end
            
        end
        
        
        function PseudoSectionData = GetDataFromSimulationTab(app, PseudoSectionData)
            % This reads in the various fields of the "Simulation" Tab
            % and puts the values in the PseudoSectionData structure (e.g.
            % before starting a new simulation)
            
            % As this routine is called at least twice (upon starting and
            % upon loading a new field), it is moved into a function
            
            % Set default parameters
            PseudoSectionData                   =   app.PseudoSectionData;
            
            PseudoSectionData.FileName          =   app.FilenameEditField.Value;
            PseudoSectionData.Name              =   app.TitleEditField.Value;
            
            if app.DiagramtypeListBox.Value         == 'PT'
                PseudoSectionData.CoarseGrid.P      =   app.UITable.Data(1,:); % Pressure [kbar]
                PseudoSectionData.CoarseGrid.T      =   app.UITable.Data(2,:); % Temperature [C]: min, max, step
            elseif app.DiagramtypeListBox.Value     == 'PX'
                PseudoSectionData.CoarseGrid.P      =   app.UITable.Data(1,:); % Pressure [kbar]
                PseudoSectionData.CoarseGrid.X      =   app.UITable.Data(2,:); % Temperature [C]: min, max, step
                PseudoSectionData.FixedTemperature  =  app.TemperatureEditField.Value;
            elseif app.DiagramtypeListBox.Value     == 'TX'
                PseudoSectionData.CoarseGrid.T      =   app.UITable.Data(1,:); % Pressure [kbar]
                PseudoSectionData.CoarseGrid.X      =   app.UITable.Data(2,:); % Temperature [C]: min, max, step
                PseudoSectionData.FixedPressure     =  app.PressureEditField.Value;
            end
            
            PseudoSectionData.Computation.RefinementLevels      =   str2num(app.RefinementsDropDown.Value);
            PseudoSectionData.Computation.Verbose               =   str2num(app.VerboselevelDropDown.Value);
            PseudoSectionData.Computation.solver                =   app.SolverDropDown.Value;
            PseudoSectionData.Computation.limitCaOpx            =   app.LimitCaopxCheckBox.Value;
            PseudoSectionData.Computation.CaOpxLim              =   app.CaOpxLim.Value;
            PseudoSectionData.Computation.mbCpx                 =   app.SwitchCpx.Value;
            PseudoSectionData.Computation.buffer                =   app.buffer.Value;
            PseudoSectionData.Computation.buffer_n              =   app.buffer_n.Value;
            PseudoSectionData.Computation.labelstyle            =   app.LabelstyleDropDown.Value;
%             PseudoSectionData.Computation.db                    =   app.DatabaseDropDown.Value;
            PseudoSectionData.Computation.db                    =   app.DatabaseListBox.Value;
            PseudoSectionData.Computation.DiagramType           =   app.DiagramtypeListBox.Value;
            PseudoSectionData.Computation.AllPoints             =   app.ParallelcomputationsCheckBox.Value;
            PseudoSectionData.Computation.UseGammaEstimation    =   app.GammaestimationCheckBox.Value;
            
            PseudoSectionData.Computation.Use_xEOS              =   app.UsexEOSuponrefinementCheckBox.Value;
            PseudoSectionData.Computation.EOS_Method            =   app.xEOSDropDown.Value;
            PseudoSectionData.Computation.num_Neighbours        =   app.num_Neighbours.Value;
            
            PseudoSectionData.Computation.Use_EMFrac            =   app.UseEMFractionuponrefinementCheckBox.Value;
            PseudoSectionData.Computation.EMFrac_Method         =   app.EMFracDropDown.Value;
            PseudoSectionData.Computation.num_Neighbours_EMFrac =   app.num_Neighbours_EMFrac.Value;
            
            PseudoSectionData.Computation.Gamma_Method          =   app.Gamma_Dropdown.Value;
            PseudoSectionData.Computation.NumRanks              =   str2num(app.MPIRanksEditFieldLabel_2.Value);
            PseudoSectionData.Computation.MPI_dir               =   app.MPIRanksEditFieldLabel_4.Value;
            PseudoSectionData.Computation.RemoteServer          =   app.PerformcomputationsonremoteserverCheckBox.Value;
            PseudoSectionData.Computation.MinPhaseFraction      =   0;  % can be removed later (was a GUI option; removed because of lack of useage)
            
            % retrieve prefered Binary method
            value = app.Binary_Switch.Value;
            PseudoSectionData.Computation.BinaryMethod          =   value;
            
            
            % Predefine trace elements or not?
            value = app.Switch_2.Value;
            switch value
                case 'Preset'
                    PseudoSectionData.TraceElement.Predefined            =   str2num(app.ExampleDropDown.Value);
                case 'Custom'
                    PseudoSectionData.TraceElement.Predefined            =   NaN;    % do not use a predefined test
                otherwise
                    error('oops, something iffy')
            end
            
            % Predefine chemistry or not?
            value = app.Switch.Value;
            switch value
                case 'Preset bulk'
                    PseudoSectionData.Chemistry.Predefined            =   str2num(app.PredefinedtestDropDown.Value);
                case 'Custom bulk'
                    PseudoSectionData.Chemistry.Predefined            =   NaN;    % do not use a predefined test
                otherwise
                    error('oops, something iffy')
            end
            
            % Obtain the chemical composition
            PseudoSectionData.Chemistry.sys_in                        =     'mol';
            PseudoSectionData.Chemistry.OxProp                        =     app.UITableChemistry.Data;
            PseudoSectionData.TraceElement.OxProp                     =     app.UITableTraceElement.Data;
            
            app.PseudoSectionData = PseudoSectionData;
            assignin('base','PseudoSectionData',PseudoSectionData);    % send to matlab command-line
            
        end
        
        function PseudoSectionData = RedoSimulationFailedPoints(app, PseudoSectionData)
            
            PhaseData       =   PseudoSectionData.PhaseData;
            Status          =   zeros(length(PhaseData),1);
            for i=1:length(PhaseData)
                Status(i)   =   PhaseData{i}.Status;
            end
            
            newPoints           =   find(Status==3);
            
            if length(newPoints)>0
                
                % Perform a MAGEMin simulation for all new points
                PhaseData           =   PseudoSectionData.PhaseData;
                XY_vec              =   PseudoSectionData.XY_vec;
                %                 ComputeAllPoints    =   PseudoSectionData.Computation.AllPoints;
                ComputeAllPoints    =   false;
                VerboseLevel        =   PseudoSectionData.Computation.Verbose;
                Chemistry           =   PseudoSectionData.Chemistry;
                UseGammaEstimation  =   PseudoSectionData.Computation.UseGammaEstimation;
                
                Computation         =   PseudoSectionData.Computation;
                
                
                dlg   = uiprogressdlg(app.MAGEMinPseudoSectionViewerUIFigure,'Title',['Redo computations for selected points'],'Cancelable',"on");
                
                pause(1)
                [PhaseData, XY_vec, FailedSimulations, CancelComputation] = PerformMAGEMin_Simulation(PhaseData, newPoints, XY_vec, VerboseLevel, Chemistry, dlg, ComputeAllPoints, UseGammaEstimation, Computation);
                
                PseudoSectionData.PhaseData = PhaseData;
                
                
            end
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app)
            app_GUI = app;
            % center the GUI
            movegui(app.MAGEMinPseudoSectionViewerUIFigure,"center")
            
            % Default parameters
            PseudoSectionData.Name         = 'Pseudosection';               % Name/description/title of simulation
            PseudoSectionData.FileName     = 'test';                        % FileName of simulation
            
            PseudoSectionData.CoarseGrid.P = [0.01   24.01   4. ];          % Pressure [kbar]: min, max, step
            PseudoSectionData.CoarseGrid.T = [800. 1400. 100. ];            % Temperature [C]: min, max, step
            PseudoSectionData.CoarseGrid.X = [0. 1.0 0.2];                  % Composition [frac]: min, max, step
            PseudoSectionData.FixedTemperature  = 800.0;                    % default fixed temperature for PX diagrams
            PseudoSectionData.FixedPressure     = 10.0;                     % default fixed pressure for TX diagrams        
            PseudoSectionData.CoarseGrid.contour = [0.   0.1   1.0 ];       % Pressure [kbar]: min, max, step
           
            % Computational parameters
            PseudoSectionData.activePoint                       = -1;
            PseudoSectionData.activePointTE                     = -1;
            PseudoSectionData.activePointPTX                    = -1;
            PseudoSectionData.Computation.MatlabOut             = 0;        % activate output for matlab post-processing (by Tobias Keller)
            PseudoSectionData.Computation.solver                = 2;        % default solver (PGE), if 0 is used, the legacy solver will be used
            PseudoSectionData.Computation.limitCaOpx            = logical(0);
            PseudoSectionData.Computation.CaOpxLim              = 0.5;
            PseudoSectionData.Computation.buffer                = "none";
            PseudoSectionData.Computation.buffer_n              = 0.0;      % default buffer multiplier value
            PseudoSectionData.Computation.labelstyle            = 1;        % default labeling method (THERMOCALC)
            PseudoSectionData.Computation.db                    = "ig";     % default Igneous (Holland et al., 2018)
            PseudoSectionData.Computation.DiagramType           = "PT";     % default, PT; can also be PX TX.
            PseudoSectionData.Computation.RefinementLevels      = 2;        % # of refinement levels
            PseudoSectionData.Computation.ManualRefLevel        = 0;        % # of refinement levels
            PseudoSectionData.Computation.Verbose               = 0;        % How much info will be shown?
            PseudoSectionData.Computation.AllPoints             = 1;        % Do we compute with all points or not?
            PseudoSectionData.Computation.UseGammaEstimation    = 0;        % Use previous gamma or not?
            PseudoSectionData.Computation.NumRanks              = 6;        % # of processors if we compute in parallel
            PseudoSectionData.Computation.MPI_dir               = '/usr/bin/';  % directory that has the MPI executable
            PseudoSectionData.Computation.RemoteServer          = logical(0);  % perform all computations on a remote server?
            PseudoSectionData.Computation.MinPhaseFraction      = 3e-3;     % Minimum require phase fraction, before we take a phase into account
            PseudoSectionData.Computation.num_Neighbours        = 9;
            PseudoSectionData.Computation.Use_xEOS              = logical(0);
            PseudoSectionData.Computation.EOS_Method            = 'Average surrounding points';
            PseudoSectionData.Chemistry.Predefined              = 0;        % Predefined chemical composition
            PseudoSectionData.TraceElement.Predefined           = 0;        % Predefined trace element composition
            PseudoSectionData.DataPTX.AreaData  = [];   

            % Determine if we use the BinaryBuilder/Julia binary or a
            % locally compiled version of MAGEMin.
            path_dylib=[];
            path_bin=[];
            path_julia=[];
            if exist('environmental_variables','file')==2
                % yes, we did run the julia installation script @ some stage
                PseudoSectionData.Computation.Julia_MAGEMin_binary  =   true;
                
                environmental_variables;                                    % run script
                
                PseudoSectionData.Computation.path_dylib    = path_dylib;   % dynamic libraries
                PseudoSectionData.Computation.path_bin      = path_bin;     % MAGEMin & mpiexec binaries
                
            else
                PseudoSectionData.Computation.Julia_MAGEMin_binary = false;
            end
            
            % Check if a local MAGEMin version is available
            if exist('MAGEMin')==2 || exist('MAGEMin.exe')==2
                PseudoSectionData.Computation.Local_MAGEMin_binary = true;
            else
                PseudoSectionData.Computation.Local_MAGEMin_binary = false;
            end
            
            % set binary toggle
            if      PseudoSectionData.Computation.Julia_MAGEMin_binary==true && PseudoSectionData.Computation.Local_MAGEMin_binary==true
                app.Binary_Switch.Enable = true;
                app.Binary_Switch.Value = 'Local';
                PseudoSectionData.Computation.BinaryMethod = 'Local';
            elseif  PseudoSectionData.Computation.Julia_MAGEMin_binary==true && PseudoSectionData.Computation.Local_MAGEMin_binary==false
                app.Binary_Switch.Enable = false;
                app.Binary_Switch.Value = 'Default';
                PseudoSectionData.Computation.BinaryMethod = 'Default';
            elseif  PseudoSectionData.Computation.Julia_MAGEMin_binary==false && PseudoSectionData.Computation.Local_MAGEMin_binary==true
                app.Binary_Switch.Enable = false;
                app.Binary_Switch.Value = 'Local';
                PseudoSectionData.Computation.BinaryMethod = 'Local';
            else
                % No binary is available at all; switch color of button to
                % red
                app.Binary_Switch.FontColor=[1,0,0];
                PseudoSectionData.Computation.BinaryMethod = 'none';
                
                % Call installation GUI
                app_install = Install_MAGEMin;
                while isvalid(app_install); pause(0.1); end
                
            end
            
            
            % retrieve version number of executable
            if PseudoSectionData.Computation.Julia_MAGEMin_binary==true || PseudoSectionData.Computation.Local_MAGEMin_binary==true
                exe = MAGEMin_exe(PseudoSectionData.Computation);
                
                command = [exe,' --version'];
                if PseudoSectionData.Computation.Local_MAGEMin_binary==false
                    command = add_dynamic_libs(command, PseudoSectionData.Computation);
                end
                [stat, cmdout] = system(command);
                disp(cmdout)
                A=[];A=split(cmdout);
                PseudoSectionData.MAGEMin_Version = A(2);
                
            end
            
            % Set default parameters in simulation tab
            app = FillInSimulationTab(app, PseudoSectionData);
            app.PlotBrushData = 0;
            
            % Generate initial mesh, to initialize the plot
            
            % Generate initial (coarse) mesh & initial plot (to start
            % figure)
            
            if PseudoSectionData.Computation.DiagramType == 'PT'
                P_dat   = PseudoSectionData.CoarseGrid.P;
                T_dat   = PseudoSectionData.CoarseGrid.T;
                Y_vec1D =   P_dat(1):P_dat(3):P_dat(2);
                X_vec1D =   T_dat(1):T_dat(3):T_dat(2);  
            elseif PseudoSectionData.Computation.DiagramType == 'PX'
                P_dat   = PseudoSectionData.CoarseGrid.P;
                X_dat   = PseudoSectionData.CoarseGrid.X;
                Y_vec1D =   P_dat(1):P_dat(3):P_dat(2);
                X_vec1D =   X_dat(1):X_dat(3):X_dat(2);
            elseif PseudoSectionData.Computation.DiagramType == 'TX'
                T_dat   = PseudoSectionData.CoarseGrid.T;
                X_dat   = PseudoSectionData.CoarseGrid.X;
                Y_vec1D =   T_dat(1):T_dat(3):T_dat(2);  
                X_vec1D =   X_dat(1):X_dat(3):X_dat(2);
            end

            [XY_vec, elements, irregular]   =   GenerateRegularMesh_AMR(X_vec1D,Y_vec1D);
            PseudoSectionData.XY_vec        =   XY_vec;
            PseudoSectionData.elements      =   elements;
            
            
            %% This will be moved to a different file
            PTCH        =   patch(app.UIAxes,'Faces', PseudoSectionData.elements, ...
                'Vertices', PseudoSectionData.XY_vec,'FaceVertexCData',...
                PseudoSectionData.XY_vec(:,1)*0,'FaceColor','interp',...
                'ButtonDownFcn',@mouse_click, 'Linestyle','-');
            
            axis(app.UIAxes,'tight');
            value = app.DiagramtypeListBox.Value;
            if value == 'PT'
                    title(app.UIAxes,app.TitleEditField.Value)
                    title(app.UIAxesTE,app.TitleEditField.Value)
            elseif value == 'PX'
                    upTitle                      = strcat(app.TitleEditField.Value,' (T = ',num2str(app.PseudoSectionData.FixedTemperature),' celcius)');
                    title(app.UIAxes,upTitle)
                    title(app.UIAxesTE,upTitle)
            elseif value == 'TX'
                    upTitle                      = strcat(app.TitleEditField.Value,' (P = ',num2str(app.PseudoSectionData.FixedPressure),' kbar)');
                    title(app.UIAxes,upTitle)
                    title(app.UIAxesTE,upTitle)
            end
            h       = colorbar(app.UIAxes);
            
            app.PlotData                =   PTCH;
            app.PseudoSectionData       =   PseudoSectionData;
            assignin('base','PseudoSectionData',PseudoSectionData);    % send to matlab command-line
            
            % In case we click on the figure window
            function mouse_click(~,eventData)
                
                % get coordinates of click
                coords              =   eventData.IntersectionPoint;
                PseudoSectionData   = app.PseudoSectionData;
                
                % display stable assemblage
                TP_norm         =   PseudoSectionData.XY_vec;
                NormVal(1)      =   (max(TP_norm(:,1))-min(TP_norm(:,1)));
                NormVal(2)      =   (max(TP_norm(:,2))-min(TP_norm(:,2)));
                TP_norm(:,1)    =   TP_norm(:,1)/NormVal(1);
                TP_norm(:,2)    =   TP_norm(:,2)/NormVal(2);
                [k,dist]        =   dsearchn(TP_norm,[coords(1:2)]./NormVal);   % find closest point; use normalized values, because of the vast difference in values

                if isfield(PseudoSectionData,'PhaseData')

                    db = app.DatabaseListBox.Value;
                    
                    if strcmp(db,'ig') == 1
                        db              =   'Igneous';
                    elseif strcmp(db,'igd') == 1
                        db              =   'Igneous dry';
                    elseif strcmp(db,'mp') == 1
                        db              =   'Metapelite';
                    elseif strcmp(db,'um') == 1
                        db              =   'Ultramafic';
                    elseif strcmp(db,'umj') == 1
                        db              =   'Ultramafic Jun';
                    end 


                    Data            =   PseudoSectionData.PhaseData{k};
                   
                    
                    if PseudoSectionData.Computation.DiagramType == 'PT'
                        P               =   PseudoSectionData.PhaseData{k}.P;
                        T               =   PseudoSectionData.PhaseData{k}.T;
                    elseif PseudoSectionData.Computation.DiagramType == 'PX'
                        P               =   PseudoSectionData.PhaseData{k}.P;
                        T               =   PseudoSectionData.FixedTemperature;
                        X               =   PseudoSectionData.XY_vec(k,1);
                    elseif PseudoSectionData.Computation.DiagramType == 'TX'
                        P               =   PseudoSectionData.FixedPressure;
                        T               =   PseudoSectionData.PhaseData{k}.T;
                        X               =   PseudoSectionData.XY_vec(k,1);
                    end
                    
                    Gibbs           =   PseudoSectionData.PhaseData{k}.Gibbs;
                    Gamma           =   PseudoSectionData.PhaseData{k}.Gamma;
                    NumAssemblage   =   PseudoSectionData.NumAssemblage(k);
                    Density         =   PseudoSectionData.PhaseData{k}.Density_total;
                    MeltFraction    =   PseudoSectionData.PhaseData{k}.liq;
                    Vp              =   PseudoSectionData.PhaseData{k}.Vp;
                    Vs              =   PseudoSectionData.PhaseData{k}.Vs;
                    entropy         =   PseudoSectionData.PhaseData{k}.entropy;
                    Status          =   PseudoSectionData.PhaseData{k}.Status;
                    numStablePhases =   PseudoSectionData.PhaseData{k}.numStablePhases;
                    
                    % display info in info box
                    app.TextAreaInfo.Value{1} = ['database:   ',db];
                    app.TextAreaInfo.Value{2} = ['P             :   ',num2str(P,'%1.3f'),' kbar'];
                    app.TextAreaInfo.Value{3} = ['T             :   ',num2str(T,'%1.3f'),' Celcius'];
                    app.TextAreaInfo.Value{4} = ['Status     :   ',num2str(Status,'%i')];
                    app.TextAreaInfo.Value{5} = ['Num        :   ',num2str(NumAssemblage,'%i')];
                    app.TextAreaInfo.Value{6} = ['StPhase  :   ',num2str(numStablePhases,'%i')];
                    app.TextAreaInfo.Value{7} = ['Gibbs      :   ',num2str(Gibbs,'%1.6f')];
                    app.TextAreaInfo.Value{8} = ['rho          :   ',num2str(Density,'%1.0f'),' kg m-3'];
                    
                    %activate option to save full data of selected point
                    PseudoSectionData.activePoint                     = k;
                    app.SaveequilibriumdataButton.Enable              = true;
                    
                    % plot point
                    delete(app.PlotPoint)
                    hold(app.UIAxes,'on')
                    MarkerStyle = app.SelectPt_MarkerStyle.Value;
                    MarkerSize  = app.SelectPt_MarkerSize.Value;
                    MarkerWidth = app.SelectPt_MarkerWidth.Value;

                    if PseudoSectionData.Computation.DiagramType == 'PT'
                        app.PlotPoint = plot(app.UIAxes,T,P,MarkerStyle,'MarkerSize',MarkerSize,'LineWidth',MarkerWidth);
                    elseif PseudoSectionData.Computation.DiagramType == 'PX'
                        app.PlotPoint = plot(app.UIAxes,X,P,MarkerStyle,'MarkerSize',MarkerSize,'LineWidth',MarkerWidth);
                    elseif PseudoSectionData.Computation.DiagramType == 'TX'
                        app.PlotPoint = plot(app.UIAxes,X,T,MarkerStyle,'MarkerSize',MarkerSize,'LineWidth',MarkerWidth);
                    end

                    % display stable assemblages and fractions
                    StablePhases    =   PseudoSectionData.PhaseData{k}.StableSolutions;
                    Fractions       =   PseudoSectionData.PhaseData{k}.StableFractions(:);
                    
                    t   =  table(StablePhases, Fractions);  % create a matlab table from it
                    app.UITableStableAssemblage.Data = t;   % add table to UI
                    
                    app.PTnum                   = [P T NumAssemblage];
                    
                    
                    % store info about the point we just clicked (useful for
                    % further computations at that point)
                    Data.Chemistry              = PseudoSectionData.Chemistry;  % store chemistry on data point as well
                    PointInfo                   = PseudoSectionData.PhaseData{k};
                    PseudoSectionData.PointInfo = PointInfo;
                    PseudoSectionData.Data      = Data;
                    app.PseudoSectionData       = PseudoSectionData;
                    
                    assignin('base','PseudoSectionData',PseudoSectionData);     % send to matlab command-line
                    assignin('base','Data', Data);                              % send info of current point to matlab command-line
                    
                    if app.ComputeTCsolutionforeverypointCheckBox.Value
                        % If requested, compute the TC solution as well and
                        % print an overview to the command-line
                        
                        [Data_TC, Data]=MAGEMin_to_TC('./ThermoCalc','tc-testMAGEMin.txt',Data); % compute TC
                        
                        Print_TC_vs_MAGEMin(Data,Data_TC); % print comparison
                        
                        assignin('base','Data_TC', Data_TC);                 % send info of current point to matlab command-line
                        
                    end
                    
                    if app.AddlabelsCheckBox.Value
                        % if requested, add label with stable solution on
                        % plot
                        n=length(StablePhases);
                        if mod(n,2)==0
                            StablePhases = reshape(StablePhases,n/2,2);
                        elseif mod(n,3)==0
                            StablePhases = reshape(StablePhases,n/3,3);
                        elseif mod(n,4)==0
                            StablePhases = reshape(StablePhases,n/4,4);
                        else
                            StablePhases = StablePhases;
                        end
                        
                        % make string array of it
                        StablePhases_text= [];
                        for i=1:size(StablePhases,1)
                            str = [];
                            for j=1:size(StablePhases,2)
                                str = [str, char(StablePhases(i,j)),' '];
                            end
                            StablePhases_text = [StablePhases_text; string(str)];
                        end

                        Nstep = 30;
                        dX = diff(app.UIAxes.XLim)/Nstep;
                        dY = diff(app.UIAxes.YLim)/Nstep;
                        Sx=1; Sy=1;
                        
                        if PseudoSectionData.Computation.DiagramType == 'PT'
                            XX = T;
                            YY = P;
                        elseif PseudoSectionData.Computation.DiagramType == 'PX'
                            XX = X;
                            YY = P;
                        elseif PseudoSectionData.Computation.DiagramType == 'TX'
                            XX = X;
                            YY = T;
                        end
                        
                        % Add line (for small fields)
                        if app.AddlabellineCheckBox.Value
                            h  = plot(app.UIAxes,[XX XX+Sx*dX],[YY YY+Sy*dY],'linewidth',1,'color','k');
                            app.hText = [app.hText; h];   % store handle to text
                        else
                            Sx=0; Sy=0;
                        end
                        
                        % textbox
                        h  = text(app.UIAxes,XX+1.01*Sx*dX,YY+Sy*dY,StablePhases_text, 'Edgecolor','k','BackgroundColor',[.9 .9 .9],'Color','k');
                        app.hText = [app.hText; h];   % store handle to text

                    end
                    
                end
                
            end

            
            %% SECTION FOR TE
            PTCHTE        =   patch(app.UIAxesTE,'Faces', PseudoSectionData.elements, ...
                'Vertices', PseudoSectionData.XY_vec,'FaceVertexCData',...
                PseudoSectionData.XY_vec(:,1)*0,'FaceColor','interp',...
                'ButtonDownFcn',@mouse_clickTE, 'Linestyle','-');
            
            axis(app.UIAxesTE,'tight');
            value = app.DiagramtypeListBox.Value;
            if value == 'PT'
                    title(app.UIAxesTE,app.TitleEditField.Value)
            elseif value == 'PX'
                    upTitle                      = strcat(app.TitleEditField.Value,' (T = ',num2str(app.PseudoSectionData.FixedTemperature),' celcius)');
                    title(app.UIAxesTE,upTitle)
            elseif value == 'TX'
                    upTitle                      = strcat(app.TitleEditField.Value,' (P = ',num2str(app.PseudoSectionData.FixedPressure),' kbar)');
                    title(app.UIAxesTE,upTitle)
            end
            h       = colorbar(app.UIAxesTE);
            
            app.PlotDataTE                =   PTCHTE;
            app.PseudoSectionData       =   PseudoSectionData;
            assignin('base','PseudoSectionData',PseudoSectionData);    % send to matlab command-line
            
            % In case we click on the figure window
            function mouse_clickTE(~,eventData)
                
                % get coordinates of click
                coords              =   eventData.IntersectionPoint;
                PseudoSectionData   =   app.PseudoSectionData;
                
                % display stable assemblage
                TP_norm         =   PseudoSectionData.XY_vec;
                NormVal(1)      =   (max(TP_norm(:,1))-min(TP_norm(:,1)));
                NormVal(2)      =   (max(TP_norm(:,2))-min(TP_norm(:,2)));
                TP_norm(:,1)    =   TP_norm(:,1)/NormVal(1);
                TP_norm(:,2)    =   TP_norm(:,2)/NormVal(2);
                [k,dist]        =   dsearchn(TP_norm,[coords(1:2)]./NormVal);   % find closest point; use normalized values, because of the vast difference in values

                if isfield(PseudoSectionData,'PhaseData')

                    db = app.DatabaseListBox.Value;
                    
                    if strcmp(db,'ig') == 1
                        db              =   'Igneous';
                    elseif strcmp(db,'igd') == 1
                        db              =   'Igneous dry';
                    elseif strcmp(db,'mp') == 1
                        db              =   'Metapelite';
                    elseif strcmp(db,'um') == 1
                        db              =   'Ultramafic';
                    elseif strcmp(db,'umj') == 1
                        db              =   'Ultramafic Jun';
                    end 
                    Data            =   PseudoSectionData.PhaseData{k};
                   
                    if PseudoSectionData.Computation.DiagramType == 'PT'
                        P               =   PseudoSectionData.PhaseData{k}.P;
                        T               =   PseudoSectionData.PhaseData{k}.T;
                    elseif PseudoSectionData.Computation.DiagramType == 'PX'
                        P               =   PseudoSectionData.PhaseData{k}.P;
                        T               =   PseudoSectionData.FixedTemperature;
                        X               =   PseudoSectionData.XY_vec(k,1);
                    elseif PseudoSectionData.Computation.DiagramType == 'TX'
                        P               =   PseudoSectionData.FixedPressure;
                        T               =   PseudoSectionData.PhaseData{k}.T;
                        X               =   PseudoSectionData.XY_vec(k,1);
                    end
                    
                    Gibbs           =   PseudoSectionData.PhaseData{k}.Gibbs;
                    Gamma           =   PseudoSectionData.PhaseData{k}.Gamma;
                    NumAssemblage   =   PseudoSectionData.NumAssemblage(k);
                    Density         =   PseudoSectionData.PhaseData{k}.Density_total;
                    MeltFraction    =   PseudoSectionData.PhaseData{k}.liq;
                    Vp              =   PseudoSectionData.PhaseData{k}.Vp;
                    Vs              =   PseudoSectionData.PhaseData{k}.Vs;
                    entropy         =   PseudoSectionData.PhaseData{k}.entropy;
                    Status          =   PseudoSectionData.PhaseData{k}.Status;
                    numStablePhases =   PseudoSectionData.PhaseData{k}.numStablePhases;
                    
                    TEname      = table2cell(PseudoSectionData.TraceElement.OxProp(:,1));
                    if isfield(PseudoSectionData.TraceElement,'m') == 1
                        for j=1:length(TEname)
                            Data.TE.m.(TEname{j})   = PseudoSectionData.TraceElement.m.(TEname{j})(k);
                            Data.TE.s.(TEname{j})   = PseudoSectionData.TraceElement.s.(TEname{j})(k);
                            Data.TE.hb.(TEname{j})  = PseudoSectionData.TraceElement.hb.(TEname{j})(k);
                            Data.TE.g.(TEname{j})   = PseudoSectionData.TraceElement.g.(TEname{j})(k);
                            Data.TE.pl.(TEname{j})  = PseudoSectionData.TraceElement.pl.(TEname{j})(k);
                            Data.TE.ol.(TEname{j})  = PseudoSectionData.TraceElement.ol.(TEname{j})(k);
                            Data.TE.opx.(TEname{j}) = PseudoSectionData.TraceElement.opx.(TEname{j})(k);
                            Data.TE.cpx.(TEname{j}) = PseudoSectionData.TraceElement.cpx.(TEname{j})(k);
                        end
                        
                        phName = fieldnames(Data.TE);
                        jx = 1;
                        for ix=1:length(phName)
                            if Data.TE.(phName{ix}).(TEname{1}) > 0
                                
                                x1 = zeros(length(TEname),1);
                                for j=1:length(TEname)
                                	x1(j) = Data.TE.(phName{ix}).(TEname{j});
                                end
                                
%                             if Data.TE.(phName{ix}).(TEname{1}) > 0
                                lgd(jx) = string(phName(ix));
                                jx = jx + 1;
%                             end
                                x1( x1 < 1e-10) = NaN;
                                
                                semilogy(app.UIAxesTESpectrum,x1,'o-')  
                                hold(app.UIAxesTESpectrum,'on')
                            end
                        end
                        legend(app.UIAxesTESpectrum,lgd)
                        hold(app.UIAxesTESpectrum,'off')
                    else
                        disp('Trace Element not computed')
                    end
                    

                    % display info in info box
                    app.TextAreaInfoTE.Value{1} = ['database:   ',db];
                    app.TextAreaInfoTE.Value{2} = ['P             :   ',num2str(P,'%1.3f'),' kbar'];
                    app.TextAreaInfoTE.Value{3} = ['T             :   ',num2str(T,'%1.3f'),' Celcius'];

                    %activate option to save full data of selected point
                    PseudoSectionData.activePointTE           = k;
                    app.SaveSpectraButton.Enable              = true;
                    app.phaseeqCheckBox.Enable                = true;
                    
                    % plot point
                    delete(app.PlotPointTE)
                    hold(app.UIAxesTE,'on')
                    MarkerStyle = app.SelectPt_MarkerStyle.Value;
                    MarkerSize  = app.SelectPt_MarkerSize.Value;
                    MarkerWidth = app.SelectPt_MarkerWidth.Value;

                    if PseudoSectionData.Computation.DiagramType == 'PT'
                        app.PlotPointTE = plot(app.UIAxesTE,T,P,MarkerStyle,'MarkerSize',MarkerSize,'LineWidth',MarkerWidth);
                    elseif PseudoSectionData.Computation.DiagramType == 'PX'
                        app.PlotPointTE = plot(app.UIAxesTE,X,P,MarkerStyle,'MarkerSize',MarkerSize,'LineWidth',MarkerWidth);
                    elseif PseudoSectionData.Computation.DiagramType == 'TX'
                        app.PlotPointTE = plot(app.UIAxesTE,X,T,MarkerStyle,'MarkerSize',MarkerSize,'LineWidth',MarkerWidth);
                    end
                    
                    % display stable assemblages and fractions
                    StablePhases    =   PseudoSectionData.PhaseData{k}.StableSolutions;
                    Fractions       =   PseudoSectionData.PhaseData{k}.StableFractions(:);
                    
                    t   =  table(StablePhases, Fractions);  % create a matlab table from it
                    app.UITableStableAssemblageTE.Data = t;   % add table to UI
                    
                    
                    % store info about the point we just clicked (useful for
                    % further computations at that point)
                    Data.Chemistry              = PseudoSectionData.Chemistry;  % store chemistry on data point as well
                    PointInfo                   = PseudoSectionData.PhaseData{k};
                    PseudoSectionData.PointInfo = PointInfo;
                    PseudoSectionData.Data      = Data;
                    app.PseudoSectionData       = PseudoSectionData;
                    
                    assignin('base','PseudoSectionData',PseudoSectionData);     % send to matlab command-line
                    assignin('base','Data', Data);                              % send info of current point to matlab command-line
                end
            end

            %% SECTION FOR PTX paths
            PTCHPTX        =   patch(app.UIAxesPTX,'Faces', PseudoSectionData.elements, ...
                'Vertices', PseudoSectionData.XY_vec,'FaceVertexCData',...
                PseudoSectionData.XY_vec(:,1)*0,'FaceColor','interp',...
                'ButtonDownFcn',@mouse_clickPTX, 'Linestyle','-');
            
            axis(app.UIAxesPTX,'tight');
            value = app.DiagramtypeListBox.Value;
            if value == 'PT'
                    title(app.UIAxesPTX,app.TitleEditField.Value)
            elseif value == 'PX'
                    upTitle                      = strcat(app.TitleEditField.Value,' (T = ',num2str(app.PseudoSectionData.FixedTemperature),' celcius)');
                    title(app.UIAxesPTX,upTitle)
            elseif value == 'TX'
                    upTitle                      = strcat(app.TitleEditField.Value,' (P = ',num2str(app.PseudoSectionData.FixedPressure),' kbar)');
                    title(app.UIAxesPTX,upTitle)
            end
            h       = colorbar(app.UIAxesPTX);
            
            app.PlotDataPTX                 =   PTCHPTX;
            app.PseudoSectionData           =   PseudoSectionData;
            assignin('base','PseudoSectionData',PseudoSectionData);    % send to matlab command-line
            
            % In case we click on the figure window
            function mouse_clickPTX(~,eventData)
                
                % get coordinates of click
                coords              =   eventData.IntersectionPoint;
                PseudoSectionData   =   app.PseudoSectionData;
                
                % display stable assemblage
                TP_norm         =   PseudoSectionData.XY_vec;
                NormVal(1)      =   (max(TP_norm(:,1))-min(TP_norm(:,1)));
                NormVal(2)      =   (max(TP_norm(:,2))-min(TP_norm(:,2)));
                TP_norm(:,1)    =   TP_norm(:,1)/NormVal(1);
                TP_norm(:,2)    =   TP_norm(:,2)/NormVal(2);
                [k,dist]        =   dsearchn(TP_norm,[coords(1:2)]./NormVal);   % find closest point; use normalized values, because of the vast difference in values

                if isfield(PseudoSectionData,'PhaseData')
                    db = app.DatabaseListBox.Value;
                    
                    if strcmp(db,'ig') == 1
                        db              =   'Igneous';
                    elseif strcmp(db,'igd') == 1
                        db              =   'Igneous dry';
                    elseif strcmp(db,'mp') == 1
                        db              =   'Metapelite';
                    elseif strcmp(db,'um') == 1
                        db              =   'Ultramafic';
                    elseif strcmp(db,'umj') == 1
                        db              =   'Ultramafic Jun';
                    end 
                    Data                =   PseudoSectionData.PhaseData{k};
                   
                    if PseudoSectionData.Computation.DiagramType == 'PT'
                        P               =   PseudoSectionData.PhaseData{k}.P;
                        T               =   PseudoSectionData.PhaseData{k}.T;
                    elseif PseudoSectionData.Computation.DiagramType == 'PX'
                        P               =   PseudoSectionData.PhaseData{k}.P;
                        T               =   PseudoSectionData.FixedTemperature;
                        X               =   PseudoSectionData.XY_vec(k,1);
                    elseif PseudoSectionData.Computation.DiagramType == 'TX'
                        P               =   PseudoSectionData.FixedPressure;
                        T               =   PseudoSectionData.PhaseData{k}.T;
                        X               =   PseudoSectionData.XY_vec(k,1);
                    end
                    
                    Gibbs           =   PseudoSectionData.PhaseData{k}.Gibbs;
                    Gamma           =   PseudoSectionData.PhaseData{k}.Gamma;
                    NumAssemblage   =   PseudoSectionData.NumAssemblage(k);
                    Density         =   PseudoSectionData.PhaseData{k}.Density_total;
                    MeltFraction    =   PseudoSectionData.PhaseData{k}.liq;
                    Vp              =   PseudoSectionData.PhaseData{k}.Vp;
                    Vs              =   PseudoSectionData.PhaseData{k}.Vs;
                    entropy         =   PseudoSectionData.PhaseData{k}.entropy;
                    Status          =   PseudoSectionData.PhaseData{k}.Status;
                    numStablePhases =   PseudoSectionData.PhaseData{k}.numStablePhases;

                    % display info in info box
                    app.TextAreaInfoPTX.Value{1} = ['database:   ',db];
                    app.TextAreaInfoPTX.Value{2} = ['P             :   ',num2str(P,'%1.3f'),' kbar'];
                    app.TextAreaInfoPTX.Value{3} = ['T             :   ',num2str(T,'%1.3f'),' Celcius'];
% 
                    %activate option to save full data of selected point
                    PseudoSectionData.activePointPTX           = k;
%                     app.SaveSpectraButton.Enable              = true;
%                     app.phaseeqCheckBox.Enable                = true;
%                     
                    % plot point
                    delete(app.PlotPointPTX)
                    hold(app.UIAxesPTX,'on')
                    MarkerStyle = app.SelectPt_MarkerStyle.Value;
                    MarkerSize  = app.SelectPt_MarkerSize.Value;
                    MarkerWidth = app.SelectPt_MarkerWidth.Value;

                    if PseudoSectionData.Computation.DiagramType == 'PT'
                        app.PlotPointPTX = plot(app.UIAxesPTX,T,P,MarkerStyle,'MarkerSize',MarkerSize,'LineWidth',MarkerWidth);
                    elseif PseudoSectionData.Computation.DiagramType == 'PX'
                        app.PlotPointPTX = plot(app.UIAxesPTX,X,P,MarkerStyle,'MarkerSize',MarkerSize,'LineWidth',MarkerWidth);
                    elseif PseudoSectionData.Computation.DiagramType == 'TX'
                        app.PlotPointPTX = plot(app.UIAxesPTX,X,T,MarkerStyle,'MarkerSize',MarkerSize,'LineWidth',MarkerWidth);
                    end
%                     
                    % display stable assemblages and fractions
                    StablePhases    =   PseudoSectionData.PhaseData{k}.StableSolutions;
                    Fractions       =   PseudoSectionData.PhaseData{k}.StableFractions(:);
                    
                    t   =  table(StablePhases, Fractions);  % create a matlab table from it
                    app.UITableStableAssemblagePTX.Data = t;   % add table to UI
                    
%                     
%                     % store info about the point we just clicked (useful for
%                     % further computations at that point)
%                     Data.Chemistry              = PseudoSectionData.Chemistry;  % store chemistry on data point as well
%                     PointInfo                   = PseudoSectionData.PhaseData{k};
%                     PseudoSectionData.PointInfo = PointInfo;
%                     PseudoSectionData.Data      = Data;
%                     app.PseudoSectionData       = PseudoSectionData;

                    app.PseudoSectionData       = PseudoSectionData;
                    assignin('base','PseudoSectionData',PseudoSectionData);     % send to matlab command-line
                    assignin('base','Data', Data);                              % send info of current point to matlab command-line 
                end
                
            end


        end

        % Button down function: MAGEMinPseudoSectionViewerUIFigure
        function Retrieve_PT(app, event)
            
        end

        % Value changed function: DisplayfieldDropDown
        function PlotColor(app, event)
            dataPl  = app.PlotData;
            data    = app.PseudoSectionData;
            value   = app.DisplayfieldDropDown.Value;
            switch value
                case 'liquid fraction'
                    % change colors
                    set(dataPl,'FaceVertexCData',data.liq(:));
                    h       = colorbar(app.UIAxes);
                    ylabel(h,'liquid fraction')
                    
                case '# of stable phases'
                    % change colors
                    set(dataPl,'FaceVertexCData',data.numPhase(:));
                    h       = colorbar(app.UIAxes);
                    ylabel(h,'# stable phases')
                    %                     hold(app.UIAxes,'on')
                    %                     scatter(app.UIAxes,data.XY_vec(:,1),data.XY_vec(:,2),20,data.numPhase(:),'filled')
                case 'Variance'
                    numOxides = length(find(data.Chemistry.OxProp.("mol %")>0));
                    Variance = numOxides + 2 - data.numPhase;
                    
                    % change colors
                    set(dataPl,'FaceVertexCData',Variance(:));
                    
                    caxis(app.UIAxes,[1 numOxides+1])
                    
                    h       = colorbar(app.UIAxes);
                    ylabel(h,'Variance')
                    colormap(app.UIAxes, flipud(pink))
                    
                case 'Gibbs'
                    
                    % filter out extreme values
                    Gibbs = data.Gibbs;
                    ind = find(abs(Gibbs)>1e4);
                    Gibbs(ind) = NaN;
                    
                    
                    % change colors
                    set(dataPl,'FaceVertexCData',Gibbs(:));
                    
                    h       = colorbar(app.UIAxes);
                    ylabel(h,'Gibbs Free Energy')
                    
                    
                case 'Gamma'
                    Gamma = data.Gamma(1,:);
                    
                    % filter out extreme values
                    ind = find(abs(Gamma)>1e4);
                    Gamma(ind) = NaN;
                    
                    % change colors
                    set(dataPl,'FaceVertexCData',Gamma(:));
                    
                    h       = colorbar(app.UIAxes);
                    ylabel(h,'Gamma[SiO2]')
                    
                case 'Density'
                    set(dataPl,'FaceVertexCData',data.Rho_tot(:));
                    h       = colorbar(app.UIAxes);
                    ylabel(h,'Average density [kg/m3]')
                    
                case 'Density_liquid'
                    rho = data.Rho_liq(:);
                    rho(rho==0) = NaN;  % places that have no density are set to NaN
                    
                    set(dataPl,'FaceVertexCData',rho);
                    h       = colorbar(app.UIAxes);
                    ylabel(h,'Melt density [kg/m3]')
                    
                case 'Density_solid'
                    rho = data.Rho_sol(:);
                    rho(rho==0) = NaN;  % places that have no density are set to NaN
                    
                    set(dataPl,'FaceVertexCData',rho);
                    h       = colorbar(app.UIAxes);
                    ylabel(h,'Solid density [kg/m3]')
                    
                case 'Vp'
                    set(dataPl,'FaceVertexCData',data.Vp(:));
                    h       = colorbar(app.UIAxes);
                    ylabel(h,'Vp [km/s]')
                    
                case 'Vs'
                    set(dataPl,'FaceVertexCData',data.Vs(:));
                    h       = colorbar(app.UIAxes);
                    ylabel(h,'Vs [km/s]')
                    
                case 'Vp/Vs'
                    VpVs = data.Vp(:)./data.Vs(:);
                    ind = find(VpVs>3);
                    VpVs(ind) = NaN;
                    
                    set(dataPl,'FaceVertexCData',VpVs);
                    h       = colorbar(app.UIAxes);
                    ylabel(h,'Vp/Vs []')
                    
                case 'entropy'
                    set(dataPl,'FaceVertexCData',data.entropy(:));
                    h       = colorbar(app.UIAxes);
                    ylabel(h,'entropy [K/J]')
                    
                    
                case 'Status'
                    set(dataPl,'FaceVertexCData',data.Status(:));
                    
                    h       = colorbar(app.UIAxes);
                    ylabel(h,'Calculation status')
                    
                case 'NumAssemblage'
                    [~,iA,NumAssemblage_discrete] = unique(data.NumAssemblage(:));
                    
                    set(dataPl,'FaceVertexCData',NumAssemblage_discrete);
                    h       = colorbar(app.UIAxes);
                    ylabel(h,'discrete # of assemblage')
                    
                    
                case 'Field'
                    try
                        Field = evalin('base','Field');
                    catch
                        error('A variable "Field" should be present in the workspace of size(length(XY_vec),1)')
                        
                    end


                    % This is an arbitrary field that we can create in the
                    % command window. It should have the correct size
                    set(dataPl,'FaceVertexCData',Field);
                    h       = colorbar(app.UIAxes);
                    ylabel(h,'Field created in the command-window')


            end
            
            Caxis_min =  min(get(dataPl,'FaceVertexCData'));
            Caxis_max =  max(get(dataPl,'FaceVertexCData'));
            switch value
                case 'Variance'
                    Caxis_min = 1;
                    Caxis_max = 11;
            end
            if isnan(Caxis_min)
                Caxis_min = 0;
            end
            if isnan(Caxis_max) | Caxis_max<=Caxis_min
                Caxis_max = Caxis_min+1;
            end
            
            
            app.Caxis_min.Value = Caxis_min;
            app.Caxis_max.Value = Caxis_max;
            
            set(app.UIAxes,'CLim',[Caxis_min Caxis_max]);
        end

        % Callback function
        function PickField1(app, event)
            
            delete(app.hField1)
            
            % Retrieve the data of the last point
            PTnum   =   app.PTnum;
            if ~isempty(PTnum)
                P       =   PTnum(1);
                T       =   PTnum(2);
                num     =   PTnum(3);
                
                PseudoSectionData = app.PseudoSectionData;
                XY_vec  =   PseudoSectionData.XY_vec;
                ind     =   find( PseudoSectionData.NumAssemblage==num);
                
                hold(app.UIAxes, 'on')
                
                app.hField1 = plot(app.UIAxes,XY_vec(ind,1),XY_vec(ind,2),'wo','MarkerFaceColor','w','Tag',num2str(num));
                
            end
            
        end

        % Callback function
        function PickField2(app, event)
            
            delete(app.hField2)
            
            % Retrieve the data of the last point
            PTnum   =   app.PTnum;
            if ~isempty(PTnum)
                P       =   PTnum(1);
                T       =   PTnum(2);
                num     =   PTnum(3);
                
                PseudoSectionData = app.PseudoSectionData;
                XY_vec  =   PseudoSectionData.XY_vec;
                ind     =   find( PseudoSectionData.NumAssemblage==num);
                
                hold(app.UIAxes, 'on')
                
                app.hField2 = plot(app.UIAxes,XY_vec(ind,1),XY_vec(ind,2),'mo','MarkerFaceColor','m','Tag',num2str(num));
                
            end
            
        end

        % Callback function
        function ExtractLine(app, event)
            if ~isempty(app.hField1) &  ~isempty(app.hField2)
                % only do this if both fields have been selected
                Field1_num = str2num(get(app.hField1,'Tag'));
                Field2_num = str2num(get(app.hField2,'Tag'));
                
                if Field1_num~=Field2_num % we can only do something if the two numbers differ
                    PseudoSectionData       =   app.PseudoSectionData;
                    NumAssemblage           =   PseudoSectionData.NumAssemblage;
                    PhaseData               =   PseudoSectionData.PhaseData;
                    elements                =   PseudoSectionData.elements;
                    
                    ind                     =   find(NumAssemblage==Field1_num);
                    Field1_StablePhases     =   PhaseData{ind(1)}.StableSolutions;
                    
                    ind                     =   find(NumAssemblage==Field2_num);
                    Field2_StablePhases     =   PhaseData{ind(2)}.StableSolutions;
                    
                    % Determine if the 2 fields are adjacent (aka, if there
                    % is indeed a reaction happening between them)
                    isReaction              =   false;
                    NumAssemblage_el        =   NumAssemblage(elements);
                    
                    % find elements that have both assemblages present,
                    % such that it is likely that there is a reaction
                    % between them
                    ind_El                  =   find(any(NumAssemblage_el==Field1_num,2) & ...
                        any(NumAssemblage_el==Field2_num,2));
                    if ~isempty(ind_El)
                        isReaction = true;
                    end
                    
                    % Display info about the reaction
                    Reaction                =   setxor(Field1_StablePhases, Field2_StablePhases)
                    if isReaction
                        XY_vec = PseudoSectionData.XY_vec;
                        % Display info about the reaction
                        for i=1:length(Reaction)
                            str = ['[',Reaction{i},']'];
                            app.ReactionlineTextArea.Value{i} = str;
                        end
                        
                        % Plot the approximate reaction points on either
                        % side of the reaction
                        el_React    =  elements(ind_El,:);
                        el_NumAssem =  NumAssemblage_el(ind_El,:);
                        
                        id                      =  find(el_NumAssem==Field1_num);
                        ind_Bound_Field1        =  el_React(id);
                        delete(app.hField1);
                        app.hField1 = plot(app.UIAxes,XY_vec(ind_Bound_Field1,1),XY_vec(ind_Bound_Field1,2),'wo','MarkerFaceColor','w','Tag',num2str(Field1_num));
                        
                        id                      =  find(el_NumAssem==Field2_num);
                        ind_Bound_Field2        =  el_React(id);
                        delete(app.hField2);
                        app.hField2 = plot(app.UIAxes,XY_vec(ind_Bound_Field2,1),XY_vec(ind_Bound_Field2,2),'mo','MarkerFaceColor','m','Tag',num2str(Field2_num));
                        
                        
                        % Store info in PseudoSectionData structure
                        PseudoSectionData.PhaseBound.Field1_num         = Field1_num;
                        PseudoSectionData.PhaseBound.Field2_num         = Field2_num;
                        PseudoSectionData.PhaseBound.ind_Bound_Field1   = ind_Bound_Field1;
                        PseudoSectionData.PhaseBound.ind_Bound_Field2   = ind_Bound_Field2;
                        PseudoSectionData.PhaseBound.Field1_TP          = XY_vec(ind_Bound_Field1,:);
                        PseudoSectionData.PhaseBound.Field2_TP          = XY_vec(ind_Bound_Field2,:);
                        
                        PseudoSectionData.PhaseBound.Field1_StablePhases    = Field1_StablePhases;
                        PseudoSectionData.PhaseBound.Field2_StablePhases    = Field2_StablePhases;
                        PseudoSectionData.PhaseBound.DifferingPhases        = Reaction;
                        
                        % Create a "LineData" structure that can be used to
                        % do TC line calculations
                        LineData.T_val(1) = min([PseudoSectionData.PhaseBound.Field1_TP(:,1); PseudoSectionData.PhaseBound.Field2_TP(:,1) ]);
                        LineData.P_val(1) = min([PseudoSectionData.PhaseBound.Field1_TP(:,2); PseudoSectionData.PhaseBound.Field2_TP(:,2) ]);
                        LineData.T_val(2) = max([PseudoSectionData.PhaseBound.Field1_TP(:,1); PseudoSectionData.PhaseBound.Field2_TP(:,1) ]);
                        LineData.P_val(2) = max([PseudoSectionData.PhaseBound.Field1_TP(:,2); PseudoSectionData.PhaseBound.Field2_TP(:,2) ]);
                        LineData.PhaseDisappear = Reaction;
                        PseudoSectionData.PhaseBound.LineData = LineData;
                        
                        
                        % Update min/max P & T fields in GUI:
                        app.PEditField.Value     = LineData.P_val(1);
                        app.PEditField_2.Value   = LineData.P_val(2);
                        app.TEditField.Value     = LineData.T_val(1);
                        app.PEditField_3.Value   = LineData.T_val(2);
                        
                        
                    else
                        % no reaction; delete the plots
                        delete(app.hField1);
                        delete(app.hField2);
                        app.ReactionlineTextArea.Value = '';
                        PseudoSectionData.PhaseBound.Field1_num = [];
                        PseudoSectionData.PhaseBound.Field2_num = [];
                        PseudoSectionData.PhaseBound.Field1_TP  = [];
                        PseudoSectionData.PhaseBound.Field2_TP  = [];
                        PseudoSectionData.PhaseBound.Field1_StablePhases    = [];
                        PseudoSectionData.PhaseBound.Field2_StablePhases    = [];
                        PseudoSectionData.PhaseBound.DifferingPhases        = [];
                        
                    end
                    
                    % update command-line info
                    app.PseudoSectionData =   PseudoSectionData;
                    assignin('base','PseudoSectionData',PseudoSectionData);    % send to matlab command-line
                    
                    
                end
                
                
            end
            
        end

        % Button pushed function: ShowcellcentersselectpointsButton
        function ShowCellCenters(app, event)
            PseudoSectionData       =   app.PseudoSectionData;
            elements                =   PseudoSectionData.elements;
            XY_vec                  =   PseudoSectionData.XY_vec;
            T_vec = XY_vec(:,1);
            P_vec = XY_vec(:,2);
            
            T_cen = mean(T_vec(elements),2);
            P_cen = mean(P_vec(elements),2);
            
            if isempty(app.hCellCenter)
                hold(app.UIAxes, 'on')
                app.hCellCenter = plot(app.UIAxes,T_cen,P_cen,'w.');
                brush(app.UIAxes,'on')
            else
                delete(app.hCellCenter)
                app.hCellCenter=[];
                % also
                if isfield(PseudoSectionData,'Refine')
                    % Remove refined grid
                    delete(PseudoSectionData.Refine.PlotPatch);
                end
                
            end
            
            
            % now you can use the brush mode to select cells which you
            % want to refine
            
            
        end

        % Button pushed function: SaveButton
        function SaveAs(app, event)
            
            
            FileName                    =   get(app.FilenameEditField,'Value');
            PseudoSectionData           =   app.PseudoSectionData;                     % data of phase diagram
            PseudoSectionData.FileName  =   FileName;
            PseudoSectionData.Name      =   app.TitleEditField.Value;
            filename                    =   fullfile(app.FileDir,FileName);
            save(filename,'PseudoSectionData');                                          % save the data to a MAT file
            
            assignin('base','PseudoSectionData',PseudoSectionData);    % send to matlab command-line
            app.FilenameEditField.FontColor  = 'r';  %change color to show that we saved it to a file
            
            
        end

        % Button pushed function: LoadButton
        function LoadFile(app, event)
            
            % Save the simulation under a specific name
            FileName                    =   get(app.FilenameEditField,'Value');         % initial simulation name
            filename                    =   fullfile(app.FileDir,[FileName,'.mat']);
            
            load(filename,'PseudoSectionData');                                         % load the data from a MAT file
            app.PseudoSectionData       =   PseudoSectionData;
            
            if isfield(PseudoSectionData,'Refine')
                delete(PseudoSectionData.Refine.PlotPatch);
                PseudoSectionData = rmfield(PseudoSectionData,'Refine');
            end
            
            % Update all values on display window accordingly
            app = FillInSimulationTab(app, PseudoSectionData);
            
            % Change color of filename, to indicate that we have loaded a
            % file
            app.FilenameEditField.FontColor = 'b';
            
            
            % Plot the phase diagram
            % update data for figure
            [PseudoSectionData]         =   PlotInitialPseudoSection(app, PseudoSectionData);
            % update plot w/out deleting it (!). Otherwise, the button
            % click no longer works (matlab should fix that)
            set(app.PlotData,'Faces', PseudoSectionData.elements, 'Vertices', PseudoSectionData.XY_vec,'FaceVertexCData',PseudoSectionData.numPhase(:),'Edgecolor','k')
            h       = colorbar(app.UIAxes);
            ylabel(h,'# stable phases')
            
            
            app.PseudoSectionData       =   PseudoSectionData;
            
            % add TC lines if they are available in the struct you just
            % loaded
            plotLines = app.PlotCheckBox.Value;
            if isfield(app.PseudoSectionData,'LinesPlot') & plotLines;
                hold(app.UIAxes,'on')
                linecolor = app.TCLines_LineColor.Value;
                linewidth = app.TCLines_LineWidth.Value;
                
                app.PlotPhaseBounds = Plot_TC_Lines(app.PseudoSectionData, app.UIAxes, linecolor, linewidth);
            end
            
            
            assignin('base','PseudoSectionData',PseudoSectionData);    % send to matlab command-line
            
            % switch to the Tab that shows the plot
            app.TabGroup3.SelectedTab = app.PseudosectionTab;
            
            
        end

        % Button pushed function: SaveFileName
        function SaveFilename(app, event)
            
            % Save the simulation under a specific name
            FileName                    =   get(app.FilenameEditField,'Value');         % initial simulation name
            
            f=figure(); drawnow; f.Visible = 'off';
            [file,path]                 =   uigetfile('*.mat');                         % pick file UI
            delete(f);
            app.MAGEMinPseudoSectionViewerUIFigure.Visible = 'off';
            app.MAGEMinPseudoSectionViewerUIFigure.Visible = 'on';


            if file~=0
                app.FilenameEditField.Value =   file(1:end-4);                              % update filename if needed
                app.FileName                =   file;
                app.FileDir                 =   path;
            end
            
            
        end

        % Button pushed function: RefineselectedareaButton
        function RefineMesh(app, event)
            % local mesh refinement of selected region
            PseudoSectionData   =   app.PseudoSectionData;
            
            refine_Elements     =   find(get(app.hCellCenter,'BrushData')); % get indices of cell centers
            elements            =   PseudoSectionData.elements;
            
            if isfield(PseudoSectionData,'Refine')
                Refine      =   PseudoSectionData.Refine;
                % we refined already before
                XY_vec      =   Refine.XY_vec;
                elements    =   Refine.elements;
                newPoints   =   Refine.newPoints;
                PrPts0      =   Refine.PrPts;
            else
                % Generate a new refined mesh
                XY_vec      =   PseudoSectionData.XY_vec;
                elements    =   PseudoSectionData.elements;
                newPoints   =   [];
                PrPts0      =   [];
            end
            
            % Refine
            irregular   =   zeros(0,3);
            numPoints   =   length(XY_vec);
            [XY_vec,elements,irregular, PrPts] =   QrefineR(XY_vec,elements,irregular,refine_Elements);
            newPoints                          =   [newPoints, numPoints+1:size(XY_vec,1)];                 % newly added points
            PrPts       = [PrPts0; PrPts];
            
            % Plot the refined mesh on top of the existing mesh
            delete(app.hCellCenter)  % delete cell center
            app.hCellCenter             =   [];
            PlotPatch                   =   patch(app.UIAxes,'Faces', elements, 'Vertices', XY_vec, 'Facecolor','none','Edgecolor','r');
%             patch(app.UIAxesPTX,'Faces', elements, 'Vertices', XY_vec, 'Facecolor','none','Edgecolor','r');
            
            % Info about # of new points
            app.newpointsTextArea.Value{1} = num2str(length(newPoints));
            
            % store data
            Refine.PlotPatch            =   PlotPatch;
            Refine.elements             =   elements;
            Refine.XY_vec               =   XY_vec;
            Refine.newPoints            =   newPoints;
            Refine.PrPts                =   PrPts;
            PseudoSectionData.Refine    =   Refine;
            
            app.PseudoSectionData       =   PseudoSectionData;
            app.newPoints               =   newPoints;
            assignin('base','PseudoSectionData',PseudoSectionData);    % send to matlab command-line
            
        end

        % Button pushed function: StartrefinementButton
        function StartRefinement(app, event)
            PseudoSectionData = app.PseudoSectionData;
            PseudoSectionData = GetDataFromSimulationTab(app,PseudoSectionData);    % retrieve data from simulation
            if isfield(PseudoSectionData,'Refine')
                % Perform a new computation for the refined grid
                Refine      =       PseudoSectionData.Refine;
                PseudoSectionData.Computation.ManualRefLevel = PseudoSectionData.Computation.ManualRefLevel + 1;
                newPoints   =       Refine.newPoints;
                XY_vec      =       Refine.XY_vec;
                PrPts       =       Refine.PrPts;
                elements    =       Refine.elements;
                
                % get smallest refinement to extract rough reaction lines 
                DiagramType                     =   PseudoSectionData.Computation.DiagramType; 
                if DiagramType == "PT"
                    X_dat                           =   PseudoSectionData.CoarseGrid.T;
                elseif DiagramType == "PX"
                    X_dat                           =   PseudoSectionData.CoarseGrid.X;
                elseif DiagramType == "TX"
                    X_dat                           =   PseudoSectionData.CoarseGrid.X;
                end

                d = X_dat(3)/(2^(PseudoSectionData.Computation.RefinementLevels + PseudoSectionData.Computation.ManualRefLevel -1));
                r_el = [];
                for i=1:length(elements)
                    d1 = elements(i,1);
                    d2 = elements(i,3);
                    diag =  abs(XY_vec(d1,1)-XY_vec(d2,1));
                    if abs(diag - d) < 1e-4
                        r_el = [r_el;elements(i,:)];
                    end
                end
                PseudoSectionData.r_elements = r_el;
                
                PhaseData   =       PseudoSectionData.PhaseData;
                Computation =       PseudoSectionData.Computation;
                
                % remove NumAssemblage from PhaseData (will be updated)
                for i=1:length(PhaseData)
                    PhaseData{i} = rmfield(PhaseData{i},'NumAssemblage');
                end
                
                % Update Gamma based on previous points
                PhaseData = Update_Gamma_onPoints(PhaseData,XY_vec, elements, newPoints,PrPts,Computation);
                
                % Add x-EOS interpolation (if possible):
                if Computation.Use_xEOS
                    PhaseData = Update_xEOS_onPoints(PhaseData,XY_vec, newPoints, PrPts,Computation);
                end
                
                % Add EM Fraction interpolation (if possible):
                if Computation.Use_EMFrac
                    PhaseData = Update_EMFrac_onPoints(PhaseData,XY_vec, newPoints,PrPts,Computation);
                end
                
                
                % Perform a MAGEMin simulation for all new points
                ComputeAllPoints    =  PseudoSectionData.Computation.AllPoints;
                UseGammaEstimation  =  PseudoSectionData.Computation.UseGammaEstimation;
                VerboseLevel        =  PseudoSectionData.Computation.Verbose;
                Chemistry           =  PseudoSectionData.Chemistry;
                
                dlg   = uiprogressdlg(app.MAGEMinPseudoSectionViewerUIFigure,'Title',['Performing computations for refined mesh'],'Cancelable',"on");
                
                [PhaseData, XY_vec, FailedSimulations, CancelComputation] = PerformMAGEMin_Simulation(PseudoSectionData,PhaseData, newPoints, XY_vec, VerboseLevel, Chemistry, dlg, ComputeAllPoints, UseGammaEstimation, Computation);
                
                if ~CancelComputation
                    
                    % Update info to new grid
                    PseudoSectionData.PhaseData =   PhaseData;
                    PseudoSectionData.XY_vec    =   XY_vec;
                    PseudoSectionData.elements  =   elements;
                    PseudoSectionData           =   rmfield(PseudoSectionData,'Refine');
                    
                    %                     PseudoSectionData           =   RedoSimulationFailedPoints(app, PseudoSectionData); % redo calculation for failed points
                    
                    % Remove refined grid
                    delete(Refine.PlotPatch);
                    
                    % Plot the new grid
                    [PseudoSectionData]         =   PlotInitialPseudoSection(app, PseudoSectionData);
                    
                    % update plot w/out deleting it (!). Otherwise, the button
                    % click no longer works (matlab should fix that)
                    set(app.PlotData,'Faces', PseudoSectionData.elements, 'Vertices', PseudoSectionData.XY_vec,'FaceVertexCData',PseudoSectionData.numPhase(:))
                    set(app.PlotDataPTX,'Faces', PseudoSectionData.elements, 'Vertices', PseudoSectionData.XY_vec,'FaceVertexCData',PseudoSectionData.numPhase(:))
                    
                    app.PseudoSectionData       =   PseudoSectionData;
                    app.PseudoSectionData       =   PseudoSectionData;
                    app.newPoints               =   newPoints;
                    assignin('base','PseudoSectionData',PseudoSectionData);    % send to matlab command-line
                    
                    app.FilenameEditField.FontColor  = 'k';  %change color of filename to show that it is a new computation
                    
                end
                
                
            end


        end

        % Button pushed function: RefineallphaseboundariesButton
        function RefinePhaseBounds(app, event)
            
            % refine all phase boundaries
            PseudoSectionData   =   app.PseudoSectionData;
            NumAssemblage       =   PseudoSectionData.NumAssemblage;
            
            if ~isfield(PseudoSectionData,'Refine')
                % if we already created a refined mesh we first have to
                % compute solutions @ every point to determine their phase
                % assemblage before we can refinee the phase boundaries
                
                % Generate a new refined mesh
                XY_vec      =   PseudoSectionData.XY_vec;
                elements    =   PseudoSectionData.elements;
                newPoints   =   [];
                PrPts0      =   [];
                
                % Reactions are when 2 points have a different assemblage number
                el_ext          =   elements(:,[1:4, 1]);         % circular extended elements
                Reactions       =   NumAssemblage(el_ext(:,2:end)) - NumAssemblage(el_ext(:,1:end-1));
                
                
                % Grid refinement where the reactions happen
                numReactions    = sum(abs(Reactions)>0,2);
                %refine_Elements = find(numReactions>1);     % reaction boundaries
                refine_Elements = find(any(abs(Reactions)>0,2));
                
                % Refine
                irregular   =   zeros(0,3);
                numPoints   =   length(XY_vec);
                [XY_vec,elements,irregular, PrPts] =   QrefineR(XY_vec,elements,irregular,refine_Elements);
                
                
                newPoints                          =   [newPoints, numPoints+1:size(XY_vec,1)];                 % newly added points
                PrPts       = [PrPts0; PrPts];
                
                % Plot the refined mesh on top of the existing mesh
                delete(app.hCellCenter)  % delete cell center
                app.hCellCenter             =   [];
                PlotPatch                   =   patch(app.UIAxes,'Faces', elements, 'Vertices', XY_vec, 'Facecolor','none','Edgecolor','r');
%                 patch(app.UIAxesPTX,'Faces', elements, 'Vertices', XY_vec, 'Facecolor','none','Edgecolor','r');
                % Info about # of new points
                app.newpointsTextArea.Value{1} = num2str(length(newPoints));
                
                % store data
                Refine.RefinePhaseBounds    =   true;   % we can only refine this once; after this we need to start a computation
                Refine.PlotPatch            =   PlotPatch;
                Refine.elements             =   elements;
                Refine.XY_vec               =   XY_vec;
                Refine.newPoints            =   newPoints;
                Refine.PrPts                =   PrPts;
                PseudoSectionData.Refine    =   Refine;
                
                app.PseudoSectionData       =   PseudoSectionData;
                app.newPoints               =   newPoints;
                assignin('base','PseudoSectionData',PseudoSectionData);    % send to matlab command-line            end
            else
                warning('First compute a new grid, before we can refine phase boundaries');
            end
        end

        % Value changed function: TitleEditField
        function ChangeTitle(app, event)
            value = app.DiagramtypeListBox.Value;
            if value == 'PT'
                title(app.UIAxes,app.TitleEditField.Value)
                title(app.UIAxesTE,app.TitleEditField.Value)
                title(app.UIAxesPTX,app.TitleEditField.Value)
            elseif value == 'PX'
                upTitle                      = strcat(app.TitleEditField.Value,' (T = ',num2str(app.PseudoSectionData.FixedTemperature),' celcius)');
                title(app.UIAxes,upTitle)
                title(app.UIAxesTE,upTitle)
                title(app.UIAxesPTX,upTitle)
            elseif value == 'TX'
                upTitle                      = strcat(app.TitleEditField.Value,' (P = ',num2str(app.PseudoSectionData.FixedPressure),' kbar)');
                title(app.UIAxes,upTitle)
                title(app.UIAxesTE,upTitle)
                title(app.UIAxesPTX,upTitle)
            end
            
        end

        % Button pushed function: StartnewcomputationButton
        function StartNewComputation(app, event)

            delete(app.PlotPoint)
            delete(app.PlotPointTE)
            
            PseudoSectionData       =   []; % new computation, hence this
            PseudoSectionData       =   GetDataFromSimulationTab(app,PseudoSectionData);    % retrieve data from simulation
            PseudoSectionData.Computation.ManualRefLevel = 0;
            % Perform simulation
            PseudoSectionData.fig   =   app.MAGEMinPseudoSectionViewerUIFigure;
            DisplayPlots            =   true;
            [PseudoSectionData, CancelComputation]       =   ComputePhaseDiagrams_AMR(PseudoSectionData, DisplayPlots);

            if ~CancelComputation
                %                 PseudoSectionData       =   RedoSimulationFailedPoints(app, PseudoSectionData); % redo calculation for failed points
                app.PseudoSectionData   =   PseudoSectionData;
                PseudoSectionData       =   rmfield( PseudoSectionData, 'fig');     % remove figure data as this causes problems for saving otherwise
                
                % update data for figure
                [PseudoSectionData]      =   PlotInitialPseudoSection(app, PseudoSectionData);
                
                % update plot w/out deleting it (!). Otherwise, the button
                % click no longer works (matlab should fix that)
                set(app.PlotData,'Faces', PseudoSectionData.elements, 'Vertices', PseudoSectionData.XY_vec,'FaceVertexCData',PseudoSectionData.numPhase(:),'Edgecolor','k')
                colormap(app.UIAxes, flipud(app.ColormapDropDown.Value))
                
                
                set(app.PlotDataPTX,'Faces', PseudoSectionData.elements, 'Vertices', PseudoSectionData.XY_vec,'FaceVertexCData',PseudoSectionData.numPhase(:),'Edgecolor','k')
                colormap(app.UIAxesPTX, flipud(app.ColormapDropDown.Value))
                
                app.PseudoSectionData     =   PseudoSectionData;
                
                assignin('base','PseudoSectionData',PseudoSectionData);    % send to matlab command-line
                
                % switch to the Tab that shows the plot
                app.TabGroup3.SelectedTab = app.PseudosectionTab;
            end
  
            
        end

        % Callback function
        function SelectRedoPoints(app, event)
            % redo the computation for selected points - here: select the
            % points
            
            PseudoSectionData  =   app.PseudoSectionData;
            XY_vec             =   PseudoSectionData.XY_vec;
            T_vec              =   XY_vec(:,1);
            P_vec              =   XY_vec(:,2);
            
            if isempty(app.hPoints)
                hold(app.UIAxes, 'on')
                app.hPoints = plot(app.UIAxes,T_vec,P_vec,'w.');
                brush(app.UIAxes,'on')
            else
                delete(app.hPoints)
                app.hPoints=[];
                
            end
            
        end

        % Callback function
        function StartRedoComputation(app, event)
            % Redo the computation for selected points
            % This is mostly for debugging the code, as it will show us
            % how differences will affect the phase diagram
            
            PseudoSectionData = app.PseudoSectionData;
            PseudoSectionData = GetDataFromSimulationTab(app,PseudoSectionData);    % retrieve data from simulation
            
            % Get a list of selected points
            selectedPoints           = find(get(app.hPoints,'BrushData'));               % get indices of all points to recompute
            
            if length(selectedPoints)>0
                P_sel   =   app.hPoints.YData(selectedPoints);
                T_sel   =   app.hPoints.XData(selectedPoints);
                
                % Discover to which points they correspond
                XY_vec          =   PseudoSectionData.XY_vec;
                TP_norm         =   PseudoSectionData.XY_vec;
                NormVal(1)      =   (max(TP_norm(:,1))-min(TP_norm(:,1)));
                NormVal(2)      =   (max(TP_norm(:,2))-min(TP_norm(:,2)));
                TP_norm(:,1)    =   TP_norm(:,1)/NormVal(1);
                TP_norm(:,2)    =   TP_norm(:,2)/NormVal(2);
                newPoints   =       dsearchn(TP_norm,[T_sel(:)/NormVal(1), P_sel(:)/NormVal(2)]);   % normalize, as it'll otherwise confuse delaunay
                
                elements    =       PseudoSectionData.elements;
                PhaseData   =       PseudoSectionData.PhaseData;
                Gamma       =       PseudoSectionData.Gamma;
                
                % remove NumAssemblage from PhaseData (will be updated)
                for i=1:length(PhaseData)
                    PhaseData{i} = rmfield(PhaseData{i},'NumAssemblage');
                end
                
                
                % Perform a MAGEMin simulation for all new points
                ComputeAllPoints    =   PseudoSectionData.Computation.AllPoints;
                ComputeAllPoints    =   false;
                VerboseLevel        =   PseudoSectionData.Computation.Verbose;
                Chemistry           =   PseudoSectionData.Chemistry;
                UseGammaEstimation  =   PseudoSectionData.Computation.UseGammaEstimation;
                Computation         =   PseudoSectionData.Computation;
                
                
                dlg   = uiprogressdlg(app.MAGEMinPseudoSectionViewerUIFigure,'Title',['Redo computations for selected points'],'Cancelable',"on");
                
                for iPoint=1:length(newPoints)
                    PhaseData = Update_Gamma_onPoints(PhaseData,XY_vec, elements, newPoints(iPoint),[], PseudoSectionData.Computation);
                    
                    % Add x-EOS interpolation (if possible):
                    PhaseData = Update_xEOS_onPoints(PhaseData,XY_vec, newPoints(iPoint),[], PseudoSectionData.Computation);
                    
                    [PhaseData, XY_vec, FailedSimulations, CancelComputation] = PerformMAGEMin_Simulation(PhaseData, newPoints(iPoint), XY_vec, VerboseLevel, Chemistry, dlg, ComputeAllPoints, UseGammaEstimation, Computation);
                    
                    
                    disp(['Percentage finished of REDO ',num2str(iPoint/length(newPoints)*100),'% '])
                    disp(' ')
                    if CancelComputation
                        break
                    end
                end
                if ~CancelComputation
                    % Update info to new grid
                    PseudoSectionData.PhaseData = PhaseData;
                    PseudoSectionData.XY_vec    = XY_vec;
                    PseudoSectionData.elements  = elements;
                    
                    % Remove selected points
                    delete(app.hPoints)
                    app.hPoints=[];
                    
                    % Plot the new grid
                    [PseudoSectionData]         =   PlotInitialPseudoSection(app, PseudoSectionData);
                    
                    % update plot w/out deleting it (!). Otherwise, the button
                    % click no longer works (matlab should fix that)
                    set(app.PlotData,'Faces', PseudoSectionData.elements, 'Vertices', PseudoSectionData.XY_vec,'FaceVertexCData',PseudoSectionData.numPhase(:))
                    set(app.PlotDataPTX,'Faces', PseudoSectionData.elements, 'Vertices', PseudoSectionData.XY_vec,'FaceVertexCData',PseudoSectionData.numPhase(:))
                    
                    app.PseudoSectionData       =   PseudoSectionData;
                    app.newPoints               =   newPoints;
                    assignin('base','PseudoSectionData',PseudoSectionData);    % send to matlab command-line
                    
                    app.FilenameEditField.FontColor  = 'k';     %change color of filename to show that something new was computed
                end
                
            end
            
        end

        % Value changed function: Switch
        function ChemistrySelectionSwitch(app, event)
            value = app.Switch.Value;
            
            switch value
                case 'Preset bulk'
                    app.BulkCaption.Enable              = false;
                    app.SystemunitDropDown.Enable       = false;
                    app.LoadfromfileButton.Enable       = false;
                    app.PredefinedtestDropDown.Enable   = true;
                    app.UITableChemistry.Enable         = 'off';
                case 'Custom bulk'
                    app.BulkCaption.Enable              = true;
                    app.SystemunitDropDown.Enable       = true;
                    app.LoadfromfileButton.Enable       = true;
                    app.PredefinedtestDropDown.Enable   = false;
                    app.UITableChemistry.Enable         = 'on';
            end
            
            
        end

        % Callback function
        function RemovePoints(app, event)
            delete(app.hField1);
            delete(app.hField2);
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            
            % open menu
            [file,path]                 =   uigetfile('*.mat');                         % pick file UI
            
            app.FilenameEditField.Value =   file(1:end-4);                              % update filename if needed
            app.FileName                =   file;
            app.FileDir                 =   path;
            
            LoadFile(app, event);


        end

        % Menu selected function: SaveasMenu
        function SaveasMenuSelected(app, event)
            
            % save as menu
            f=figure(); drawnow; f.Visible = 'off';
            [file,path]                 =   uiputfile('*.mat');                         % pick file UI
            delete(f);
            
            figure(app.UIFigure)
            app.FilenameEditField.Value =   file(1:end-4);                              % update filename if needed
            app.FileName                =   file;
            app.FileDir                 =   path;
            
            SaveAs(app, event);
            
        end

        % Menu selected function: ExporttoLaMEMGeoParamsMenu
        function ExporttoLaMEMGeoParamsMenuSelected(app, event)
            
            % Exports the data to a LaMEM file
            
            % save as menu
            [FileName,FileDir]      =   uiputfile('*.in');                         % pick file UI
            drawnow;
         %   figure(app.UIFigure)
            
            % Get grid resolution from UI
            GridResolution          =   [200 200];
            
            ExportData_LaMEM(app.PseudoSectionData, FileName, FileDir, GridResolution);


        end

        % Menu selected function: QuitMenu
        function QuitMenuSelected(app, event)
            % Stops the UI
            closereq();
            
        end

        % Value changed function: ShowgridSwitch
        function ShowgridSwitchValueChanged(app, event)
            value = app.ShowgridSwitch.Value;
            
            switch value
                case 'On'
                    set(app.PlotData,'LineStyle','-');
                case 'Off'
                    set(app.PlotData,'LineStyle','none');
            end


        end

        % Value changed function: ParallelcomputationsCheckBox
        function ParallelcomputationsCheckBoxValueChanged(app, event)
            value = app.ParallelcomputationsCheckBox.Value;
            
            if value
                Enable = "on";
            else
                Enable = "off";
            end
            value_switch = app.Binary_Switch.Value;
            
            
            app.MPIRanksEditFieldLabel_1.Enable = Enable;
            app.MPIRanksEditFieldLabel_2.Enable = Enable;
            if strcmp(value_switch,'Local')
                app.MPIRanksEditFieldLabel_3.Enable = Enable;
                app.MPIRanksEditFieldLabel_4.Enable = Enable;
                app.SelectMPIexec.Enable            = Enable;
            end
            app.PerformcomputationsonremoteserverCheckBox.Enable = Enable;
            
            
        end

        % Button pushed function: SelectMPIexec
        function SelectMPIexecButtonPushed(app, event)
            % Select an mpi executable
            [~,path]                                    =   uigetfile('*.*','Select your mpiexec file');       % pick file UI
            drawnow;
            figure(app.UIFigure)
            if path~=0
                app.MPIRanksEditFieldLabel_4.Value      =   path;                       % update path
            end
            
        end

        % Callback function
        function StartRedoStatusPointsButtonPushed(app, event)
            PseudoSectionData  =   app.PseudoSectionData;
            XY_vec             =   PseudoSectionData.XY_vec;
            T_vec              =   XY_vec(:,1);
            P_vec              =   XY_vec(:,2);
            Status_vec         =   PseudoSectionData.Status;
            
            ind = find(Status_vec==3);
            
            if app.PlotBrushData == 1
                % delete points from plot if they are plotted already
                delete(app.hPoints)
                app.PlotBrushData = 0;
                app.hPoints=[];
                
            elseif ~isempty(ind) & app.PlotBrushData==0
                % plot data
                hold(app.UIAxes, 'on')
                app.hPoints = plot(app.UIAxes,T_vec(ind),P_vec(ind),'wo');
                brush(app.UIAxes,'on')
                app.PlotBrushData = 1;
            end


        end

        % Callback function
        function MinrequiredphasefractionCheckBoxValueChanged(app, event)
            
            
        end

        % Callback function
        function ReprocessPointsMinFractionButtonPushed(app, event)
            % This reprocesses the points based on the minimum fraction
            PseudoSectionData   = GetDataFromSimulationTab(app,app.PseudoSectionData);    % retrieve data from simulation
            
            % go through all points & reprocess them
            PseudoSectionData   = ReprocessPointsMinPhaseFraction(PseudoSectionData,PseudoSectionData.Computation.MinPhaseFraction);
            
            % Update arrays with density, # of phases etc.
            [PseudoSectionData] = PlotInitialPseudoSection(app,PseudoSectionData);
            
            % update plot w/out deleting it (!). Otherwise, the button
            % click no longer works (matlab should fix that)
            [~,iA,NumAssemblage_discrete] = unique(data.NumAssemblage(:));
            set(app.PlotData,'Faces', PseudoSectionData.elements, 'Vertices', PseudoSectionData.XY_vec,'FaceVertexCData',NumAssemblage_discrete,'Edgecolor','k')
            h       = colorbar(app.UIAxes);
            g       = ylabel(h,'discrete # of assemblage');
            g.Position(1) = -1;
            
            app.PseudoSectionData       =   PseudoSectionData;
            
            assignin('base','PseudoSectionData',PseudoSectionData);    % send to matlab command-line
            
            % switch to the Tab that shows the plot
            app.TabGroup3.SelectedTab = app.PseudosectionTab;


        end

        % Callback function
        function ComputeTCLineButtonPushed(app, event)
            PseudoSectionData =     app.PseudoSectionData;
            if isfield(PseudoSectionData,'LinesPlot')
                LinesPlot  =     PseudoSectionData.LinesPlot;
            else
                LinesPlot  =   [];
            end
            LineData          =     PseudoSectionData.PhaseBound.LineData;
            PhaseBound        =     PseudoSectionData.PhaseBound;
            
            
            % Read in data from the GUI:
            LineData.numPoints=     app.pointsEditField.Value;
            LineData.CalcTatP =     app.scanPCheckBox.Value;
            LineData.P_val(1) =     app.PEditField.Value;
            LineData.P_val(2) =     app.PEditField_2.Value;
            LineData.T_val(1) =     app.TEditField.Value;
            LineData.T_val(2) =     app.PEditField_3.Value;
            
            ComputeLine       =     logical(1);
            Data              =     PseudoSectionData.Data;
            
            assignin('base','LineData',LineData);    % send to matlab command-line
            
            % Compute line using TC
            Line              =     MAGEMin_to_TC('ThermoCalc','tc-testMAGEMin.txt',Data,logical(1),ComputeLine,LineData, PhaseBound);
            
            
            % Add line to structure
            LinesPlot{end+1} = Line;
            
            % Plot all lines if requested
            PseudoSectionData.LinesPlot = LinesPlot;
            
            plotLines = app.PlotCheckBox.Value;
            if ~isempty(Line.P)
                linecolor = app.TCLines_LineColor.Value;
                linewidth = app.TCLines_LineWidth.Value;
                
                
                app.PlotPhaseBounds = Plot_TC_Lines(PseudoSectionData, app.UIAxes, linecolor, linewidth);
                
                delete(app.hField1);
                delete(app.hField2);
            end
            
            app.PseudoSectionData = PseudoSectionData;
            assignin('base','PseudoSectionData',PseudoSectionData);    % send to matlab command-line
            
            
        end

        % Callback function
        function DeletelastlineButtonPushed(app, event)
            PseudoSectionData =     app.PseudoSectionData;
            nLines = length(PseudoSectionData.LinesPlot);
            if nLines>1
                PseudoSectionData.LinesPlot(end) = [];
            else
                PseudoSectionData=rmfield(PseudoSectionData,'LinesPlot');
            end
            
            assignin('base','PseudoSectionData',PseudoSectionData);    % send to matlab command-line
            
            % call line plotting routine
            if isfield(app,'PlotPhaseBounds')
                h=findobj(app.UIAxes,'type','line');
                delete(h);    % remove all lines on plot
                app.PlotPhaseBounds=[];
            end
            plotLines = app.PlotCheckBox.Value;
            if plotLines
                delete(app.PlotPhaseBounds);
                linecolor = app.TCLines_LineColor.Value;
                linewidth = app.TCLines_LineWidth.Value;
                app.PlotPhaseBounds = Plot_TC_Lines(PseudoSectionData, app.UIAxes, linecolor, linewidth);
            end
            app.PseudoSectionData = PseudoSectionData;
            
        end

        % Value changed function: DatabaseListBox, 
        % PredefinedtestDropDown
        function PredefinedtestDropDownValueChanged(app, event)
            value     = app.PredefinedtestDropDown.Value;
%             database  = app.DatabaseDropDown.Value;
            database  = app.DatabaseListBox.Value;
            [OxideOxProportionsTable, Name] = GetPredefinedChemistry(app,str2num(value),database);

            app.UITableChemistry.Data = OxideOxProportionsTable;
            app.TitleEditField.Value  = Name;
            
            if database == "alk" ||database == "ig" ||  database == "igd" 
                app.LimitCaopxCheckBox.Enable = 'on';
                app.CaOpxLim.Enable = 'on';
            else
                app.LimitCaopxCheckBox.Enable = 'off';
                app.CaOpxLim.Enable = 'off';
            end
            
            if database == "mb"
                app.SwitchCpx.Enable = 'on';
            else
                app.SwitchCpx.Enable = 'off';
            end     
            
            
        end

        % Value changed function: UsexEOSuponrefinementCheckBox
        function UsexEOSuponrefinementCheckBoxValueChanged(app, event)
            value = app.UsexEOSuponrefinementCheckBox.Value;
            if value
                app.xEOSDropDown.Enable     =   "on";
            else
                app.xEOSDropDown.Enable     =   "off";
            end
            
        end

        % Button pushed function: UniformrefinementButton
        function UniformrefinementButtonPushed(app, event)
            % local mesh refinement of selected region
            PseudoSectionData   =   app.PseudoSectionData;
            
            elements            =   PseudoSectionData.elements;
            refine_Elements     =   [1:length(elements)]';   % refine all elements
            
            if isfield(PseudoSectionData,'Refine')
                Refine      =   PseudoSectionData.Refine;
                % we refined already before
                XY_vec      =   Refine.XY_vec;
                elements    =   Refine.elements;
                newPoints   =   Refine.newPoints;
                PrPts0      =   Refine.PrPts;
            else
                % Generate a new refined mesh
                XY_vec      =   PseudoSectionData.XY_vec;
                elements    =   PseudoSectionData.elements;
                newPoints   =   [];
                PrPts0      =   [];
            end
            
            % Refine
            irregular   =   zeros(0,3);
            numPoints   =   length(XY_vec);
            [XY_vec,elements,irregular, PrPts] =   QrefineR(XY_vec,elements,irregular,refine_Elements);
            newPoints                          =   [newPoints, numPoints+1:size(XY_vec,1)];                 % newly added points
            PrPts       = [PrPts0; PrPts];
            
            % Plot the refined mesh on top of the existing mesh
            delete(app.hCellCenter)  % delete cell center
            app.hCellCenter             =   [];
            PlotPatch                   =   patch(app.UIAxes,'Faces', elements, 'Vertices', XY_vec, 'Facecolor','none','Edgecolor','r');
            
            % Info about # of new points
            app.newpointsTextArea.Value{1} = num2str(length(newPoints));
            
            % store data
            Refine.PlotPatch            =   PlotPatch;
            Refine.elements             =   elements;
            Refine.XY_vec               =   XY_vec;
            Refine.newPoints            =   newPoints;
            Refine.PrPts                =   PrPts;
            PseudoSectionData.Refine    =   Refine;
            app.PseudoSectionData       =   PseudoSectionData;
            assignin('base','PseudoSectionData',PseudoSectionData);    % send to matlab command-line
            
        end

        % Value changed function: xEOSDropDown
        function xEOSDropDownValueChanged(app, event)
            value = app.xEOSDropDown.Value;
            switch value
                case 'Average surrounding points'
                    app.num_Neighbours.Enable = "on";
                otherwise
                    app.num_Neighbours.Enable = "off";
            end
            
        end

        % Callback function
        function StartRedoComputationTCButtonPushed(app, event)
            % Redo the computation for selected points
            % This is mostly for debugging the code, as it will show us
            % how differences will affect the phase diagram
            
            PseudoSectionData = app.PseudoSectionData;
            PseudoSectionData = GetDataFromSimulationTab(app,PseudoSectionData);    % retrieve data from simulation
            
            % Get a list of selected points
            selectedPoints    = find(get(app.hPoints,'BrushData'));               % get indices of all points to recompute
            
            if length(selectedPoints)>0
                P_sel   =   app.hPoints.YData(selectedPoints);
                T_sel   =   app.hPoints.XData(selectedPoints);
                
                % Discover to which points they correspond
                TP_norm         =   PseudoSectionData.XY_vec;
                NormVal(1)      =   (max(TP_norm(:,1))-min(TP_norm(:,1)));
                NormVal(2)      =   (max(TP_norm(:,2))-min(TP_norm(:,2)));
                TP_norm(:,1)    =   TP_norm(:,1)/NormVal(1);
                TP_norm(:,2)    =   TP_norm(:,2)/NormVal(2);
                newPoints       =   dsearchn(TP_norm,[T_sel(:)/NormVal(1), P_sel(:)/NormVal(2)]);   % normalize, as it'll otherwise confuse delaunay
                
                % Progress dialog
                dlg   = uiprogressdlg(app.MAGEMinPseudoSectionViewerUIFigure,'Title',['Redoing pointwise calculations using ThermoCalc for ',num2str(length(newPoints)),' points'],'Cancelable',"on");
                
                % Perform calculations
                [PseudoSectionData, CancelCalculation] = RecomputePoints_TC(dlg,newPoints,PseudoSectionData);
                
                if ~CancelCalculation
                    
                    % Remove selected points
                    delete(app.hPoints)
                    app.hPoints=[];
                    
                    % Plot the new grid
                    [PseudoSectionData]         =   PlotInitialPseudoSection(app, PseudoSectionData);
                    
                    % update plot w/out deleting it (!). Otherwise, the button
                    % click no longer works (matlab should fix that)
                    set(app.PlotData,'Faces', PseudoSectionData.elements, 'Vertices', PseudoSectionData.XY_vec,'FaceVertexCData',PseudoSectionData.numPhase(:))
                    set(app.PlotDataPTX,'Faces', PseudoSectionData.elements, 'Vertices', PseudoSectionData.XY_vec,'FaceVertexCData',PseudoSectionData.numPhase(:))
                    
                    app.PseudoSectionData       =   PseudoSectionData;
                    assignin('base','PseudoSectionData',PseudoSectionData);    % send to matlab command-line
                    
                    app.FilenameEditField.FontColor  = 'k';     %change color of filename to show that something new was computed
                end
                
            end


        end

        % Callback function
        function SelectAllPointsButtonPushed(app, event)
            PseudoSectionData  =   app.PseudoSectionData;
            XY_vec             =   PseudoSectionData.XY_vec;
            T_vec              =   XY_vec(:,1);
            P_vec              =   XY_vec(:,2);
            
            ind                 =   1:length(T_vec);
            if app.PlotBrushData == 0
                hold(app.UIAxes, 'on')
                app.hPoints     = plot(app.UIAxes,T_vec(ind),P_vec(ind),'wo');
                brush(app.UIAxes,'on')
                
                set(app.hPoints,'BrushData',ind);
                app.PlotBrushData = 1;
                
            else
                delete(app.hPoints)
                app.hPoints=[];
                app.PlotBrushData = 0;
            end
            
            
        end

        % Callback function
        function SelectAllNewPointsButtonPushed(app, event)
            PseudoSectionData  =   app.PseudoSectionData;
            newPoints          =   app.newPoints;
            XY_vec             =   PseudoSectionData.XY_vec;
            T_vec              =   XY_vec(:,1);
            P_vec              =   XY_vec(:,2);
            
            ind                 =   newPoints;
            if app.PlotBrushData == 0
                hold(app.UIAxes, 'on')
                app.hPoints     = plot(app.UIAxes,T_vec(ind),P_vec(ind),'wo');
                brush(app.UIAxes,'on')
                
                set(app.hPoints,'BrushData',ind);
                app.PlotBrushData = 1;
            else
                delete(app.hPoints)
                app.hPoints=[];
                app.PlotBrushData = 0;
            end
            
            
        end

        % Callback function
        function SelectAllNewPoints_noTCButtonPushed(app, event)
            PseudoSectionData  =   app.PseudoSectionData;
            XY_vec             =   PseudoSectionData.XY_vec;
            T_vec              =   XY_vec(:,1);
            P_vec              =   XY_vec(:,2);
            PhaseData          =   PseudoSectionData.PhaseData;
            
            indTC = zeros(size(T_vec));
            for i=1:length(PhaseData)
                if isfield(PhaseData{i},'TC_recompute')
                    if PhaseData{i}.TC_recompute.success
                        indTC(i) = 1;
                    end
                end
                
            end
            
            ind_noTC = find(indTC==0);  % we did not reprocess this point yet with TC
            
            if app.PlotBrushData == 0
                hold(app.UIAxes, 'on')
                app.hPoints     = plot(app.UIAxes,T_vec(ind_noTC),P_vec(ind_noTC),'wo');
                brush(app.UIAxes,'on')
                
                app.PlotBrushData = 1;
            else
                delete(app.hPoints)
                app.hPoints=[];
                app.PlotBrushData = 0;
            end
        end

        % Value changed function: GammaestimationCheckBox
        function GammaestimationCheckBoxValueChanged(app, event)
            value = app.GammaestimationCheckBox.Value;
            
            if value
                app.Gamma_Dropdown.Enable     =   "on";
            else
                app.Gamma_Dropdown.Enable     =   "off";
            end


        end

        % Callback function
        function DoLocalMinimizationsButtonPushed(app, event)
            % This will do a local optimization for a given data point
            % (taken from the command-window)
            
            % Read in data point from command windown
            try
                Data = evalin('base','Data');
            catch
                error('A variable "Data" should be present in the workspace; click on a data point first!')
                
            end
            
            % Read in solution model
            SS = app.solutionmodelEditField.Value;
            
            % Read in n_pc
            n_pc = app.pointsdimensionEditField.Value;
            
            
            % Do the computations & perform clustering of the data
            MPI_dir   = app.MPIRanksEditFieldLabel_4.Value;
            MPI_cores = str2num(app.MPIRanksEditFieldLabel_2.Value);
            
            if MPI_cores==1
                MPI_dir=[];
            end
            [Local_best, Local_cluster, Local] = ScanParameterSpace_SolutionModel(Data.P,Data.T,Data.Gamma,SS, n_pc, MPI_dir, MPI_cores, false);
            
            
            % Send all data to command window
            assignin('base','Local',            Local);
            assignin('base','Local_best',       Local_best);
            assignin('base','Local_cluster',    Local_cluster);
            
            % Set options in dropdown menu based on # of Compositional
            % Variables
            nCompVar = size(Local.xEOS_end,2);
            for i=1:nCompVar
                strCompVar{i} = num2str(i);
            end
            if size(Local_cluster.xEOS_end,1)>1
                strCompVar{i+1} = 'clus. 1-2';
                i=i+1;
            end
            strCompVar{i+1} = 'random';
            app.DropDown_Local_Param1.Items                 = strCompVar;
            app.DropDown_Local_Param2.Items                 = strCompVar;
            
            % Set options in dropdown menu based on # of Compositional
            % Variables
            nCluster = size(Local_cluster.xEOS_end,1);
            for i=1:nCluster
                strClusterVar{i} = num2str(i);
            end
            
            
            app.clusterDropDown.Items                    = strClusterVar;


            app.DropDown_Local_Param2.Value                 = '2';
            app.DropDown_Local_Param1.Enable                = true;
            app.DropDown_Local_Param2.Enable                = true;
            app.PlotoutputLocalOptimizationButton.Enable    = true;
            app.CheckBox_holdon_local.Enable                = true;
            app.CheckBox_AddNN_local.Enable                 = true;
            app.CheckBox_FullSurface.Enable                 = true;
            app.Label_xaxis_Local.Enable                    = true;
            app.Label_yaxis_Local.Enable                    = true;
            app.keepnormalCheckBox.Enable   = true;
            
            app.Local_Xmin.Enable = true;
            app.Local_Xmax.Enable = true;
            app.Local_Xpts.Enable = true;
            app.Label_Xtext.Enable = true;
            
            app.Local_Ymin.Enable = true;
            app.Local_Ymax.Enable = true;
            app.Local_Ypts.Enable = true;
            app.Label_Ytext.Enable = true;
            
            app.PlotGsurface_Button.Enable = true;
            app.clusterDropDown.Enable = true;
            
            % Compute
            
        end

        % Callback function
        function PlotoutputLocalOptimizationButtonPushed(app, event)
            % This creates a plot of all Gamma values and a second plot of
            % xeos(1) vs xeos(2)  (selectable through dropdowns)
            
            % Read in data point from command window
            try
                Data = evalin('base','Data');
            catch
                error('A variable "Data" should be present in the workspace; click on a data point first!')
                
            end
            
            % Read in Local point from command window
            try
                Local = evalin('base','Local');
            catch
                error('A variable "Local" should be present in the workspace; click on a data point first!')
                
            end
            
            % Determine if we have the same SS model as part of the MAGEMin
            % output data as well
            SS              =   Local.SolutionModel;
            ind             =   find(strcmp(Data.StableSolutions,SS));
            if ~isempty(ind)
                HaveLocalxEOS   =   true;
                MAGEMin_xEOS    =   Data.CompositionalVar{ind};
            else
                HaveLocalxEOS   =   false;
            end
            numPoints = length(Local.Gamma(:));
            
            HoldOnPlot = app.CheckBox_holdon_local.Value;


            Add_NN     = app.CheckBox_AddNN_local.Value;
            
            switch app.DropDown_Local_Param1.Value
                case {'random','clus. 1-2'}
                    ind(1) = 1;
                otherwise
                    ind(1)  = str2num(app.DropDown_Local_Param1.Value);
            end
            
            switch app.DropDown_Local_Param2.Value
                case {'random','clus. 1-2'}
                    ind(2) = 1;
                otherwise
                    ind(2)  = str2num(app.DropDown_Local_Param2.Value);
            end
            
            
            figure(1), clf
            plot(Local.df), xlabel('# of points'), ylabel('Driving force')
            title(['Solution for ',SS],'FontSize',app.Title_Fontsize.Value)
            
            
            figure(2),
            
            
            if HoldOnPlot
                hold on;
            else
                clf;
            end
            
            plot3(Local.xEOS_end(:,ind(1)), Local.xEOS_end(:,ind(2)),Local.df,'ro','MarkerFaceColor','r','MarkerSize',10)
            
            view(3)
            axis tight
            xlabel(['x-eos # ',num2str(ind(1)) ],'Fontsize',app.Axes_Fontsize.Value);
            ylabel(['x-eos # ',num2str(ind(2)) ],'Fontsize',app.Axes_Fontsize.Value);
            zlabel('Driving force','Fontsize',app.Axes_Fontsize.Value);
            
            grid on;
            title_str = ['solution space scan (red markers) for ',SS,' @ T=',num2str(Data.T),'^oC; P=',num2str(Data.P),' kbar  (',num2str(numPoints),' points)'];
            if HaveLocalxEOS
                hold on,
                plot3(MAGEMin_xEOS(ind(1)),MAGEMin_xEOS(ind(2)),0,'ks','Markersize',20)
                title_str = ['MAGEMin result (black square) vs. ', title_str];
            end
            
            if Add_NN
                % add predictions of the NN if requested
                xEOS_NN = xEOS_From_NN(SS,[Data.T Data.P]);
                if ~isempty(xEOS_NN)
                    hold on,
                    plot3(xEOS_NN(ind(1)),xEOS_NN(ind(2)),0,'bv','Markersize',20)
                    title_str = ['NN prediction (blue triangle) vs. ', title_str];
                    
                    
                    assignin('base','xEOS_NN',xEOS_NN); % send to command-line for inspection
                end
            end
            
            title(title_str,'FontSize',app.Title_Fontsize.Value)
            
            % adapt axis
            xl = xlim; xl(1) = xl(1)-0.1; xl(2) = xl(2)+0.1; xlim(xl);
            yl = ylim; yl(1) = yl(1)-0.1; yl(2) = yl(2)+0.1; ylim(yl);
            zl = zlim; zl(1) = zl(1)-0.1; zl(2) = zl(2)+0.1; zlim(zl);
            

            % Create a plot versus endmember proportions
            figure(22),
            
            
            if HoldOnPlot
                hold on;
            else
                clf;
            end
            %             if SampleG
            %                 if SmoothSurf
            %                     surface(Gsurface.xP_S,Gsurface.yP_S,Gsurface.zP_S,'FaceAlpha',.7);
            %                     shading interp, hold on,
            %                     contour3(Gsurface.xP_S,Gsurface.yP_S,Gsurface.zP_S,20,'k')
            %                 else
            %                     surface(Gsurface.xP, Gsurface.yP, Gsurface.zP,'FaceAlpha',.7)
            %                     shading interp, hold on,
            %                     contour3(Gsurface.xP,Gsurface.yP,Gsurface.zP,20,'k')
            %                 end
            %                 colorbar
            %
            %             else
            plot3(Local.Prop_end(:,ind(1)), Local.Prop_end(:,ind(2)),Local.df,'ro','MarkerFaceColor','r','MarkerSize',10)
            %             end
            view(3)
            axis tight
            xlabel(['EM proportion # ',num2str(ind(1)) ],'Fontsize',app.Axes_Fontsize.Value);
            ylabel(['EM proportion # ',num2str(ind(2)) ],'Fontsize',app.Axes_Fontsize.Value);
            zlabel('Driving force','Fontsize',app.Axes_Fontsize.Value);
            
            grid on;
            title_str = ['solution space scan (red markers) for ',SS,' @ T=',num2str(Data.T),'^oC; P=',num2str(Data.P),' kbar  (',num2str(numPoints),' points)'];
            
            
            title(title_str,'FontSize',12)
            
            % adapt axis
            xl = xlim; xl(1) = xl(1)-0.1; xl(2) = xl(2)+0.1; xlim(xl);
            yl = ylim; yl(1) = yl(1)-0.1; yl(2) = yl(2)+0.1; ylim(yl);
            zl = zlim; zl(1) = zl(1)-0.1; zl(2) = zl(2)+0.1; zlim(zl);
            
            
        end

        % Callback function
        function PlotGsurface_ButtonPushed(app, event)
            % Computes a G-surface around the current point
            
            
            % Read in data point from command window
            try
                Data = evalin('base','Data');
            catch
                error('A variable "Data" should be present in the workspace; click on a data point first!')
                
            end
            
            % Read in Local point from command window
            try
                Local_cluster = evalin('base','Local_cluster');
            catch
                error('A variable "Local" should be present in the workspace; click on a data point first!')
                
            end
            
            
            % Get info from GUI
            lb(1)       = app.Local_Xmin.Value;
            lb(2)       = app.Local_Ymin.Value;
            ub(1)       = app.Local_Xmax.Value;
            ub(2)       = app.Local_Ymax.Value;
            nPts(1)     = app.Local_Xpts.Value;
            nPts(2)     = app.Local_Ypts.Value;
            SS          = app.solutionmodelEditField.Value;
            HoldOnPlot  = app.CheckBox_holdon_local.Value;
            Cluster     = str2double(app.clusterDropDown.Value);
            KeepNormal  = app.keepnormalCheckBox.Value;
            
            if KeepNormal
                % Read the normal vector from the command-window
                try
                    NormalVector = evalin('base','NormalVector');
                catch
                    error('A variable "NormalVector" should be present in the workspace; Generate that first')
                end
                
            else
                % We generate a new normal vector
                x0              =   Local_cluster.xEOS_end(Cluster,:);
                Normalized(1)   =   true;
                switch app.DropDown_Local_Param1.Value
                    case 'clus. 1-2'
                        % between cluster 1 and 2
                        normal(1,:) = Local_cluster.xEOS_end(2,:)-Local_cluster.xEOS_end(1,:);
                        
                    case 'random'
                        normal(1,:) = rand(size(x0));
                        
                    otherwise
                        % use one of the x-eos as coordinate axis
                        ind             =   str2num(app.DropDown_Local_Param1.Value);
                        normal(1,:)     =   zeros(size(x0));
                        normal(1,ind)   =   1;
                        
                        Normalized(1)   =   false;
                end
                normal(1,:) = normal(1,:)/max(abs(normal(1,:)));
                
                Normalized(2)   =   true;
                switch app.DropDown_Local_Param2.Value
                    case 'clus. 1-2'
                        % between cluster 1 and 2
                        normal(2,:) = Local_cluster.xEOS_end(2,:)-Local_cluster.xEOS_end(1,:);
                        
                    case 'random'
                        normal(2,:) = rand(size(x0));
                        
                    otherwise
                        % use one of the x-eos as coordinate axis
                        ind             =   str2num(app.DropDown_Local_Param2.Value);
                        normal(2,:)     =   zeros(size(x0));
                        normal(2,ind)   =   1;
                        
                        Normalized(2)   =   false;
                end
                normal(2,:) = normal(2,:)/max(abs(normal(2,:)));
                
                
                NormalVector.x0         = x0;
                NormalVector.normal     = normal;
                NormalVector.Normalized = Normalized;
                
                
                % Save to command-window
                assignin('base','NormalVector',NormalVector);
                
            end


            % Sample solution space
            [Gsurface]  = SampleSolutionSpace(SS,Data.T,Data.P,Data.Gamma,NormalVector.x0,NormalVector.normal,lb, ub, nPts);
            assignin('base','Gsurface',Gsurface); % send to command-line for inspection
            
            if any(NormalVector.Normalized)
                figure(222)
                % we have a coordinate system that is normalized, in which
                % case we center it around (0,0), which is where our
                % cluster is situated. Note that doing a new calculation
                % centers this again
            else
                
                figure(2)
                
                % transfer the normalized x,y coordinates
                if ~NormalVector.Normalized(1)
                    id           = find(NormalVector.normal(1,:)>0);
                    Gsurface.x   = Gsurface.x + NormalVector.x0(id);
                end
                if ~NormalVector.Normalized(2)
                    id           = find(NormalVector.normal(2,:)>0);
                    Gsurface.y   = Gsurface.y + NormalVector.x0(id);
                end
                
                
            end
            if HoldOnPlot
                hold on;
            else
                clf;
            end
            
            if app.CheckBox_FullSurface.Value
                Gsurface.x_full = Gsurface.x;
                Gsurface.y_full = Gsurface.y;
                ind=find(isnan(Gsurface.df_full));
                Gsurface.x_full(ind)=    NaN;
                Gsurface.y_full(ind)=   NaN;
                mesh(Gsurface.x_full, Gsurface.y_full, Gsurface.df_full,'FaceAlpha',.7)
                hold on
            end
            ind=find(isnan(Gsurface.z));
            Gsurface.x(ind)     =   NaN;
            Gsurface.y(ind)     =   NaN;
            
            surface(Gsurface.x, Gsurface.y, Gsurface.z,'FaceAlpha',.7)
            shading interp, hold on,
            contour3(Gsurface.x,Gsurface.y,Gsurface.z,20,'k')
            %             mesh(Gsurface.x, Gsurface.y, Gsurface.df_full,'FaceAlpha',.7)
            
            
            h = colorbar, view(3), grid on
            ylabel(h,'Driving force','Fontsize',app.Axes_Fontsize.Value)
            
            if any(NormalVector.Normalized)
                % plot the clusters as well, in normalized coordinates
                for iClust=1:size(Local_cluster.xEOS_end,1)
                    X = NormalVector.normal'\[Local_cluster.xEOS_end(iClust,:)-NormalVector.x0]';% coordinates in normalized space
                    hold on
                    plot3(X(1),X(2),Local_cluster.df(iClust),'ro','MarkerFaceColor','r','MarkerSize',10)
                end
                
                
            end
            
            
            xl= [min(Gsurface.x(:)) max(Gsurface.x(:))]; xlim(xl);
            yl= [min(Gsurface.y(:)) max(Gsurface.y(:))]; ylim(yl);
            
            zl = zlim;
            zl(1) = min([ min(Gsurface.z(:)) zl(1)]);
            zl(2) = max([ max(Gsurface.z(:)) zl(2)]);
            zlim(zl);
            
            
            xlabel(['x:  ',app.DropDown_Local_Param1.Value,' direction'],'Fontsize',app.Axes_Fontsize.Value);
            ylabel(['y:  ',app.DropDown_Local_Param2.Value,' direction'],'Fontsize',app.Axes_Fontsize.Value);
            zlabel('Driving force');
            
            grid on;
            title_str = ['solution space scan for ',SS,' @ T=',num2str(Data.T),'^oC; P=',num2str(Data.P),' kbar'];
            title(title_str,'FontSize',app.Title_Fontsize.Value)
            axis tight
            view(0,90)


        end

        % Callback function
        function PlotCheckBoxValueChanged(app, event)
            plotLines = app.PlotCheckBox.Value;
            
            if plotLines
                hold(app.UIAxes,'on')
                linecolor = app.TCLines_LineColor.Value;
                linewidth = app.TCLines_LineWidth.Value;
                
                app.PlotPhaseBounds = Plot_TC_Lines(app.PseudoSectionData, app.UIAxes, linecolor, linewidth);
            else
                h=findobj(app.UIAxes,'type','line');
                delete(h);    % remove all lines on plot
                app.PlotPhaseBounds=[];
                
            end


        end

        % Callback function
        function DeletealllinesButtonPushed(app, event)
            PseudoSectionData = app.PseudoSectionData;
            if isfield(PseudoSectionData,'LinesPlot')
                PseudoSectionData = rmfield(PseudoSectionData,'LinesPlot');
            end
            assignin('base','PseudoSectionData',PseudoSectionData);    % send to matlab command-line
            
            % call line plotting routine
            h   =   findobj(app.UIAxes,'type','line');
            delete(h);                              % remove all lines on plot
            app.PlotPhaseBounds = [];
            
            app.PseudoSectionData = PseudoSectionData;
        end

        % Value changed function: 
        % UseEMFractionuponrefinementCheckBox
        function UseEMFractionuponrefinementCheckBoxValueChanged(app, event)
            value = app.UseEMFractionuponrefinementCheckBox.Value;
            if value
                app.EMFracDropDown.Enable     =   "on";
            else
                app.EMFracDropDown.Enable     =   "off";
            end
            
            
        end

        % Value changed function: EMFracDropDown
        function EMFracDropDownValueChanged(app, event)
            value = app.EMFracDropDown.Value;
            switch value
                case 'Average surrounding points'
                    app.num_Neighbours_EMFrac.Enable = "on";
                otherwise
                    app.num_Neighbours_EMFrac.Enable = "off";
            end
            
        end

        % Value changed function: Axes_Fontsize
        function Axes_FontsizeValueChanged(app, event)
            set(app.UIAxes,'FontSize',app.Axes_Fontsize.Value);
            set(app.UIAxes,'TitleFontSizeMultiplier',app.Title_Fontsize.Value/app.Axes_Fontsize.Value);
            
        end

        % Value changed function: Title_Fontsize
        function Title_FontsizeValueChanged(app, event)
            set(app.UIAxes,'FontSize',app.Axes_Fontsize.Value);
            set(app.UIAxes,'TitleFontSizeMultiplier',app.Title_Fontsize.Value/app.Axes_Fontsize.Value);
            
        end

        % Callback function
        function ColorbarlabelValueChanged(app, event)
            value = app.Colorbarlabel.Value;
            h       = colorbar(app.UIAxes);
            g       = ylabel(h,value);
            g.Position(1) = -1;
            
            
        end

        % Value changed function: Caxis_max
        function Caxis_maxValueChanged(app, event)
            Caxis_max = app.Caxis_max.Value;
            Caxis_min = app.Caxis_min.Value;
            
            set(app.UIAxes,'CLim',[Caxis_min Caxis_max]);


        end

        % Value changed function: Caxis_min
        function Caxis_minValueChanged(app, event)
            Caxis_max = app.Caxis_max.Value;
            Caxis_min = app.Caxis_min.Value;
            
            set(app.UIAxes,'CLim',[Caxis_min Caxis_max]);
            
        end

        % Button pushed function: RefineunivariantpointsButton
        function RefineunivariantpointsButtonPushed(app, event)
            
            % refine all phase boundaries
            PseudoSectionData   =   app.PseudoSectionData;
            NumAssemblage       =   PseudoSectionData.NumAssemblage;
            
            
            if ~isfield(PseudoSectionData,'Refine')
                % if we already created a refined mesh we first have to
                % compute solutions @ every point to determine their phase
                % assemblage before we can refinee the phase boundaries
                
                % Generate a new refined mesh
                XY_vec      =   PseudoSectionData.XY_vec;
                elements    =   PseudoSectionData.elements;
                newPoints   =   [];
                PrPts0      =   [];
                
                % Reactions are when 2 points have a different assemblage number
                el_ext          =   elements(:,[1:4, 1]);         % circular extended elements
                Reactions       =   NumAssemblage(el_ext(:,2:end)) - NumAssemblage(el_ext(:,1:end-1));
                
                % Grid refinement where the reactions happen
                numReactions    = sum(abs(Reactions)>0,2);
                refine_Elements = find(numReactions>2);     % points where different fields occur
                
                % Refine
                irregular   =   zeros(0,3);
                numPoints   =   length(XY_vec);
                [XY_vec,elements,irregular, PrPts] =   QrefineR(XY_vec,elements,irregular,refine_Elements);
                newPoints                          =   [newPoints, numPoints+1:size(XY_vec,1)];                 % newly added points
                PrPts       = [PrPts0; PrPts];
                
                % Plot the refined mesh on top of the existing mesh
                delete(app.hCellCenter)  % delete cell center
                app.hCellCenter             =   [];
                PlotPatch                   =   patch(app.UIAxes,'Faces', elements, 'Vertices', XY_vec, 'Facecolor','none','Edgecolor','r');
                
                % Info about # of new points
                app.newpointsTextArea.Value{1} = num2str(length(newPoints));
                
                % store data
                Refine.RefinePhaseBounds    =   true;   % we can only refine this once; after this we need to start a computation
                Refine.PlotPatch            =   PlotPatch;
                Refine.elements             =   elements;
                Refine.XY_vec               =   XY_vec;
                Refine.newPoints            =   newPoints;
                Refine.PrPts                =   PrPts;
                PseudoSectionData.Refine    =   Refine;
                
                app.PseudoSectionData       =   PseudoSectionData;
                app.newPoints               =   newPoints;
                assignin('base','PseudoSectionData',PseudoSectionData);    % send to matlab command-line            end
            else
                warning('First compute a new grid, before we can refine phase boundaries');
            end


        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged(app, event)
            value = app.ColormapDropDown.Value;
            switch value
                case {'Gray'}
                    colormap(app.UIAxes, flipud(gray))
                case {'Bone'}
                    colormap(app.UIAxes, flipud(bone))
                case {'Pink'}
                    colormap(app.UIAxes, flipud(pink))
                otherwise
                    colormap(app.UIAxes, value)
            end
            
        end

        % Callback function
        function ComputeLineButtonPushed(app, event)
            PseudoSectionData =     app.PseudoSectionData;
            if isfield(PseudoSectionData,'LinesPlot')
                LinesPlot  =     PseudoSectionData.LinesPlot;
            else
                LinesPlot  =   [];
            end
            
            LineData          =     PseudoSectionData.PhaseBound.LineData;
            PhaseBound        =     PseudoSectionData.PhaseBound;
            
            
            % Read in data from the GUI:
            LineData.numPoints=     app.pointsEditField.Value;
            LineData.CalcTatP =     app.scanPCheckBox.Value;
            LineData.P_val(1) =     app.PEditField.Value;
            LineData.P_val(2) =     app.PEditField_2.Value;
            LineData.T_val(1) =     app.TEditField.Value;
            LineData.T_val(2) =     app.PEditField_3.Value;
            
            ComputeLine       =     logical(1);
            Data              =     PseudoSectionData.Data;
            
            assignin('base','LineData',LineData);       %   send to matlab command-line
            assignin('base','PhaseBound',PhaseBound);   % send to matlab command-line
            
            % Compute line using TC
            %Line              =     MAGEMin_to_TC('ThermoCalc','tc-testMAGEMin.txt',Data,logical(1),ComputeLine,LineData, PhaseBound);
            
            % Compute line using MAGEMin
            Line                = Extract_Reactions_MAGEMin(LineData, PhaseBound);
            
            % Add line to structure
            LinesPlot{end+1} = Line;
            
            % Plot all lines if requested
            PseudoSectionData.LinesPlot = LinesPlot;
            
            plotLines = app.PlotCheckBox.Value;
            if ~isempty(Line.P)
                linecolor = app.TCLines_LineColor.Value;
                linewidth = app.TCLines_LineWidth.Value;
                
                app.PlotPhaseBounds = Plot_TC_Lines(PseudoSectionData, app.UIAxes, linecolor, linewidth);
                
                delete(app.hField1);
                delete(app.hField2);
            end
            
            app.PseudoSectionData = PseudoSectionData;
            assignin('base','PseudoSectionData',PseudoSectionData);    % send to matlab command-line
            
        end

        % Button pushed function: removealllabelsButton
        function removealllabelsButtonPushed(app, event)
            delete(app.hText)
            app.hText = [];
            
        end

        % Callback function
        function UIAxesButtonDown(app, event)
            
        end

        % Button pushed function: removelastlabelButton
        function removelastlabelButtonPushed(app, event)
            delete(app.hText(end))
            app.hText(end) = [];
            if length(findobj(app.hText(end),'Type','Line'))>0
                % delete line as well
                delete(app.hText(end))
                app.hText(end) = [];
            end
        end

        % Menu selected function: InstallMAGEMinMenu
        function InstallMAGEMinMenuSelected(app, event)
            Install_MAGEMin;
        end

        % Menu selected function: OpeninnewFigureMenu
        function OpeninnewFigureMenuSelected(app, event)
            % This opens the current axes in new Figure
            fig             =   figure;
            set(fig,'OuterPosition',[706 369 855 727]);
            
            ax              =   axes;clf;
            new_handle      =   copyobj(app.UIAxes,fig);
            set(fig,'Color','w')
            set(gca,'Units','normalized')
            set(gca,'Position',[0.05 0.05 0.9 0.9])
        end

        % Value changed function: Binary_Switch
        function Binary_SwitchValueChanged(app, event)
            value = app.Binary_Switch.Value;
            switch value
                case 'Default'
                    app.MPIRanksEditFieldLabel_3.Enable=false;
                    app.MPIRanksEditFieldLabel_4.Enable=false;
                    app.SelectMPIexec.Enable=false;
                    app.PseudoSectionData.Computation.Julia_MAGEMin_binary = true;
                    
                case 'Local'
                    app.MPIRanksEditFieldLabel_3.Enable=true;
                    app.MPIRanksEditFieldLabel_4.Enable=true;
                    app.SelectMPIexec.Enable=true;
                    app.PseudoSectionData.Computation.Julia_MAGEMin_binary = false;
                    
                    
            end
        end

        % Button pushed function: LoadfromfileButton
        function LoadfromfileButtonPushed(app, event)
            
            sysunit = app.SystemunitDropDown.Value; %this gets the unit of bulk rock composition to load
            db      = string(app.DatabaseListBox.Value);
            [file,path] = uigetfile('*.dat');
            bulk_in = loadBulkFromFile(sysunit,file,path,db);
            app.UITableChemistry.Data = bulk_in;
            app.TitleEditField.Value = file(1:end-4);
            app.MAGEMinPseudoSectionViewerUIFigure.Visible = 'off';
            app.MAGEMinPseudoSectionViewerUIFigure.Visible = 'on';
        end

        % Button pushed function: displayisocontourButton
        function displayIsocontourFunction(app, event)
            data.phase = app.phaseEditField.Value;
            data.min   = app.minEditField.Value;
            data.stp   = app.stepEditField.Value;
            data.max   = app.maxEditField.Value;
            data.res   = app.resEditField.Value;
            data.style = app.TCLines_LineColor_2.Value;
            data.lblC  = app.TCLines_LineColor_4.Value;
            data.FtSize= app.TCLines_LineColor_5.Value;
            data.mode  = app.DataListBox.Value; 
            data.em    = [];
            
            if string(data.mode) == "em frac"
                data.em = app.emnamesListBox.Value;
            end
            
            hold(app.UIAxes,'on');
            set(app.hCaptionHandle,'visible','on');
            [h,cap] = Plot_IsoContour(app.UIAxes,app.PseudoSectionData,data);  
            
            id = 0;
            for i=1:length(app.hContour)
                if isempty(app.hContour{i})
                    id = i;
                    break;
                end
            end
            if id == 0
                app.hContour{end+1}     = h;
                app.hContourLegendText  = [app.hContourLegendText,cap];            
                app.hContourHandle      = [app.hContourHandle,h];
            else
                app.hContour{id}            = h;
                app.hContourLegendText(id)  = cap;            
                app.hContourHandle(id)      = h;
            end
            app.hCaptionHandle      = legend(app.UIAxes,app.hContourHandle,app.hContourLegendText,'Location','NorthEast');
        end

        % Button pushed function: removeallButton
        function clearIsocontour(app, event)
            for i=1:length(app.hContour)
                delete(app.hContour{i});
                app.hContour{i} = [];
            end
            app.hContour = {};
            app.hContourHandle = [];
            app.hContourLegendText = [];
            set(app.hCaptionHandle,'visible','off')
        end

        % Callback function
        function changeEmList(app, event)
            app.emnamesListBox.Items = {'em1', 'em2'};
        end

        % Value changed function: DataListBox, phaseEditField
        function SelectContourMode(app, event)
            value = app.DataListBox.Value;
            switch value
                case 'mode'
                    app.emnamesListBox.Visible         = false;
                    app.emnamesListBox.Enable          = false;
                case 'em frac'
                    if app.phaseEditField.Value
                        em_names = Get_endmembers(app.UIAxes,app.PseudoSectionData, app.phaseEditField.Value);
                        app.emnamesListBox.Items = em_names;
                        
                        app.emnamesListBox.Visible     = true;
                        app.emnamesListBox.Enable      = true;
                    else
                        disp('! Enter a valid solution phase name before trying to display the end-member list')
                    end
            end
        end

        % Callback function
        function save2eps(app, event)

        
        end

        % Button pushed function: ExportfigureButton
        function ExportFigure(app, event)
            set(0,'DefaultFigureRenderer', 'painters');
            set(0,'defaultfigurecolor',[1 1 1]);
            fileName    = app.FileEditField.Value;
            fig         = figure();
            axes('pos',[.62 .75 .1 .1]);
            imshow('./pics/Logo_export.png');
            dim = [0.62 0.25 .35 .45];
            
            for i=1:size(app.DatabaseListBox.ItemsData,2)
                if strcmp(string(app.DatabaseListBox.Value), app.DatabaseListBox.ItemsData{1,i}) == 1
                    id = i;
                end
            end

            value  = app.DiagramtypeListBox.Value;
            ncores = string(app.MPIRanksEditFieldLabel_2.Value);
                  
            if value == 'PT'
                sdt = 'Pressure-Temperature, fixed composition';
            elseif value == 'PX'
                sdt = 'Pressure-Composition, fixed temperature';
            elseif value == 'TX'
                sdt = 'Temperature-Composition, fixed pressure';
            end
            OxProp = app.UITableChemistry.Data;
            oxName0 = mat2str(string(reshape(OxProp{:,1}',1,[]) )); oxName = erase(oxName0,'"');oxName1 = erase(oxName,'[');oxName2 = erase(oxName1,']');
            oxComp0 = mat2str(string(reshape(OxProp{:,2}',1,[]) )); oxComp = erase(oxComp0,'"');oxComp1 = erase(oxComp,'[');oxComp2 = erase(oxComp1,']');
            oxCompb0 = mat2str(string(reshape(OxProp{:,3}',1,[]) )); oxCompb = erase(oxCompb0,'"');oxCompb1 = erase(oxCompb,'[');oxCompb2 = erase(oxCompb1,']');

            str = strcat('Phase diagram computed with MAGEMin v1.3.7 (',string(date),')\n\n\\bfDatabase\t\t:\\rm\n',string(app.DatabaseListBox.Items{1,id}), ...
                             '\n\\bfDiagram type\t:\\rm\n',sdt, ...
                             '\n\\bfSolver\t:\\rm\n',string(app.SolverDropDown.Items{1,app.SolverDropDown.Value}) );
            
            if value == 'PT'
                             str = strcat(str,'\n\\bfOxide list\t\t:\\rm\n',oxName2,  ...
                             '\n\\bfOxide comp [mol]\t:\\rm\n',oxComp2);
            elseif value == 'PX'
                             str = strcat(str,'\n\\bfFixed Temperature [C]\t:\\rm\n',num2str(app.TemperatureEditField.Value), ...
                             '\n\\bfOxide list\t\t:\\rm\n',oxName2,  ...
                             '\n\\bfOxide comp X0 [mol]\t:\\rm\n',oxComp2, ...
                             '\n\\bfOxide comp X1 [mol]\t:\\rm\n',oxCompb2);
             elseif value == 'TX'
                             str = strcat(str,'\n\\bfFixed Pressure [kbar]\t:\\rm\n',num2str(app.PressureEditField.Value), ...
                             '\n\\bfOxide list\t\t:\\rm\n',oxName2,  ...
                             '\n\\bfOxide comp X0 [mol]\t:\\rm\n',oxComp2, ...
                             '\n\\bfOxide comp X1 [mol]\t:\\rm\n',oxCompb2);
            end

            disp(app.buffer.Value);
            if strcmp(string(app.buffer.Value),"none") == 0
                buffer_fac =  app.buffer_n.Value;
                str = strcat(str,'\n\\bf',string(app.buffer.Value),' buffer multiplier\t:\\rm\n',num2str(buffer_fac));
            end
            
            str = strcat(str, '\n\n\\bfTotal number of computed points\t:\\rm\n',app.NumPointsLabel.Text,  ...
                              '\n\\bfNumber of used cores\t:\\rm\n',ncores);
            
            str2 = sprintf(str);
            annotation('textbox',dim,'String',str2);
  
            axis off;
            cb          = colorbar(app.UIAxes); 
            ylabel(cb, string(app.DisplayfieldDropDown.Value));
            h           = copyUIAxes(app.UIAxes, fig,'colorbar',cb,'copyPosition', true);
            saveas(fig, fileName, 'epsc');
            saveas(fig, fileName, 'jpg');
            close(fig); 
            
            % save labels to separated vector file
            fig2        = figure( 'Position',get(app.UIAxes, 'Position'));
            ax          = axes; 
            g           = copyobj(app.hText, ax);
            set(ax,'XLim',get(app.UIAxes, 'XLim'));
            set(ax,'YLim',get(app.UIAxes, 'YLim'));
            saveas(fig2, strcat(fileName,'_labels'), 'epsc');
            close(fig2); 
            
            set(0,'DefaultFigureColor','factory')
        end

        % Button pushed function: removelastsButton
        function clearlast(app, event)
            len = length(app.hContour);

            if len > 1
                i = len;
                while isempty(app.hContour{i})
                    i = i-1;
                end
                    delete(app.hContour{i});
                    app.hContour{i}     = [];
                    delete(app.hContourHandle(i))
                    app.hContourHandle(i)     = [];
                    app.hContourLegendText(i) = [];
                if i == 1
                    set(app.hCaptionHandle,'visible','off');
                    app.hContour = {};
                    app.hContourHandle = [];
                    app.hContourLegendText = [];
                end
            else
                delete(app.hContour{1});
                app.hContour = {};
                app.hContourHandle = [];
                app.hContourLegendText = [];
                set(app.hCaptionHandle,'visible','off');
            end

        end

        % Button pushed function: SaveequilibriumdataButton
        function savePointData(app, event)
            save_EquilibriumData(app.PseudoSectionData);
            app.MAGEMinPseudoSectionViewerUIFigure.Visible = 'off';
            app.MAGEMinPseudoSectionViewerUIFigure.Visible = 'on';
        end

        % Value changed function: DiagramtypeListBox
        function changeDiagramType(app, event)
            PseudoSectionData = app.PseudoSectionData;
            value = app.DiagramtypeListBox.Value;
            
            if value == 'PT'
                app.UITable.Data(1,:)           = PseudoSectionData.CoarseGrid.P; % Pressure [kbar]: min, max, step
                app.UITable.Data(2,:)           = PseudoSectionData.CoarseGrid.T; % Temperature [C]: min, max, step
                app.UITable.RowName             = {'P [kbar]'; 'T [C]'; ''};
                app.UIAxes.YLabel.String        = 'Pressure [kbar]';
                app.UIAxes.XLabel.String        = 'Temperature [Celcius]';
                app.UIAxesTE.YLabel.String      = 'Pressure [kbar]';
                app.UIAxesTE.XLabel.String      = 'Temperature [Celcius]';
                app.UIAxesPTX.YLabel.String      = 'Pressure [kbar]';
                app.UIAxesPTX.XLabel.String      = 'Temperature [Celcius]';
                   
                title(app.UIAxes,app.TitleEditField.Value)
                
                app.TemperatureEditField.Enable = false;
                app.PressureEditField.Enable    = false;
                app.TemperatureLabel.Enable     = false;
                app.PressureLabel.Enable        = false;
                PseudoSectionData.Computation.DiagramType = 'PT';
            elseif value == 'PX'
                app.UITable.Data(1,:)           = PseudoSectionData.CoarseGrid.P; % Pressure [kbar]: min, max, step
                app.UITable.Data(2,:)           = PseudoSectionData.CoarseGrid.X; % Composition [frac]: min, max, step
                app.UITable.RowName             = {'P [kbar]'; 'X [frac]'; ''};
                app.UIAxes.YLabel.String        = 'Pressure [kbar]';
                app.UIAxes.XLabel.String        = 'X [frac]';
                app.UIAxesTE.YLabel.String      = 'Pressure [kbar]';
                app.UIAxesTE.XLabel.String      = 'X [frac]';
                app.UIAxesPTX.YLabel.String      = 'Pressure [kbar]';
                app.UIAxesPTX.XLabel.String      = 'X [frac]';
                
                upTitle                      = strcat(app.TitleEditField.Value,' (T = ',num2str(app.PseudoSectionData.FixedTemperature),' celcius)');
                title(app.UIAxes,upTitle)
                
%                 app.hText = text(app.UIAxes,0.4,0.4,FixedLabel, 'Edgecolor','k','BackgroundColor',[.9 .9 .9],'Color','k');
                app.TemperatureEditField.Enable = true;
                app.PressureEditField.Enable    = false;
                app.TemperatureLabel.Enable     = true;
                app.PressureLabel.Enable        = false;
                PseudoSectionData.Computation.DiagramType = 'PX';
            elseif value == 'TX'
                app.UITable.Data(1,:)           = PseudoSectionData.CoarseGrid.T; % Temperature [C]: min, max, step
                app.UITable.Data(2,:)           = PseudoSectionData.CoarseGrid.X; % Composition [frac]: min, max, step
                app.UITable.RowName             = {'T [C]'; 'X [frac]'; ''};
                app.UIAxes.YLabel.String        = 'Temperature [Celcius]';
                app.UIAxes.XLabel.String        = 'X [frac]';
                app.UIAxesTE.YLabel.String      = 'Temperature [Celcius]';
                app.UIAxesTE.XLabel.String      = 'X [frac]';
                app.UIAxesPTX.YLabel.String      = 'Temperature [Celcius]';
                app.UIAxesPTX.XLabel.String      = 'X [frac]';
                
                upTitle                      = strcat(app.TitleEditField.Value,' (P = ',num2str(app.PseudoSectionData.FixedPressure),' kbar)');
                title(app.UIAxes,upTitle)
                
                app.TemperatureEditField.Enable = false;
                app.PressureEditField.Enable    = true;
                app.TemperatureLabel.Enable     = false;
                app.PressureLabel.Enable        = true;
                PseudoSectionData.Computation.DiagramType = 'TX';
            end
            
            drawnow;
            app.PseudoSectionData = PseudoSectionData;
            assignin('base','PseudoSectionData',PseudoSectionData);    % send to matlab command-line
            
        end

        % Value changed function: ShowreactionlineSwitch
        function ShowReactionLine(app, event)
            value = app.ShowreactionlineSwitch.Value;
            
            switch value
                case 'On'
                     r_el = app.PseudoSectionData.r_elements;
                    xy   = app.PseudoSectionData.XY_vec;
        
%                     hold(app.UIAxes,'on');
                    
                    h = patch(app.UIAxes,'Faces', r_el, 'Vertices', xy);
                    app.hReactionLineHandle = h; 

                    set(app.hReactionLineHandle,'visible','on');
                case 'Off'
                    set(app.hReactionLineHandle,'visible','off');
                    app.hReactionLineHandle = [];
            end
            
            
        end

        % Value changed function: Switch_2
        function TraceElementSelectionSwitch(app, event)
            value = app.Switch_2.Value;
            
            switch value
                case 'Preset'
                    app.LoadfromfileButton_2.Enable       = false;
                    app.ExampleDropDown.Enable   = true;
                    app.UITableTraceElement.Enable         = 'off';
                case 'Custom'
                    app.LoadfromfileButton_2.Enable       = true;
                    app.ExampleDropDown.Enable   = false;
                    app.UITableTraceElement.Enable         = 'on';
            end
            
        end

        % Value changed function: ExampleDropDown
        function ExampleTE(app, event)
            value = app.ExampleDropDown.Value;

            [ElementProportionsTable, Name]             = GetPredefinedTraceElements(app,str2num(value));
            app.UITableTraceElement.Data                = ElementProportionsTable;
            app.PseudoSectionData.TraceElement.OxProp   = ElementProportionsTable;
            app.TitleEditFieldTE.Value                  = Name;

        end

        % Button pushed function: 
        % ComputetraceelementpartitioningButton
        function ComputeTEpartitioning(app, event)
  
            dlg   = uiprogressdlg(app.MAGEMinPseudoSectionViewerUIFigure,'Title',['Performing trace element prediction...'],'Cancelable',"on");
            PseudoSectionData                       = app.PseudoSectionData;  
            PseudoSectionData.TraceElement.OxProp   = app.UITableTraceElement.Data;
            PseudoSectionData                       = TEpredictingModel(PseudoSectionData);
            
            app.PseudoSectionData                   =   PseudoSectionData;
            assignin('base','PseudoSectionData',PseudoSectionData);    % send to matlab command-line
            close(dlg);
        end

        % Button pushed function: DisplayButton
        function PlotTE(app, event)

%             data    = app.PseudoSectionData.TraceElement.m.Dy(:);
            data   = [];
            cmd    = app.CommandwindowTextArea.Value;
            
            s   = app.PseudoSectionData.TraceElement.s;
            m   = app.PseudoSectionData.TraceElement.m;
            hb  = app.PseudoSectionData.TraceElement.hb;
            g   = app.PseudoSectionData.TraceElement.g;
            pl  = app.PseudoSectionData.TraceElement.pl;
            ol  = app.PseudoSectionData.TraceElement.ol;
            opx = app.PseudoSectionData.TraceElement.opx;
            cpx = app.PseudoSectionData.TraceElement.cpx;
                    
            strcmd = strcat('data = ',string(cmd),'(:);');
            strlbl = strcat(cmd,' [ppm]');
            
            eval(strcmd);
            set(app.PlotDataTE,'Faces', app.PseudoSectionData.elements, 'Vertices', app.PseudoSectionData.XY_vec,'FaceVertexCData',data(:));
            colormap(app.UIAxesTE, flipud(app.ColormapDropDown_2.Value))
            h       = colorbar(app.UIAxesTE);
            g       = ylabel(h,strlbl);
            g.Position(1) = -1;

%             app.TabGroup3.SelectedTab = app.TraceElementsTab;
            
        end

        % Value changed function: GridSwitch
        function ShowGridTE(app, event)
            value = app.GridSwitch.Value;
            
            switch value
                case 'On'
                    set(app.PlotDataTE,'LineStyle','-');
                case 'Off'
                    set(app.PlotDataTE,'LineStyle','none');
            end

        end

        % Value changed function: ColormapDropDown_2
        function ColormapTEchanged(app, event)
            value = app.ColormapDropDown_2.Value;
            switch value
                case {'Gray'}
                    colormap(app.UIAxesTE, flipud(gray))
                case {'Bone'}
                    colormap(app.UIAxesTE, flipud(bone))
                case {'Pink'}
                    colormap(app.UIAxesTE, flipud(pink))
                otherwise
                    colormap(app.UIAxesTE, value)
            end
            
        end

        % Button pushed function: ExportfigureButton_2
        function ExportFigTE(app, event)
            set(0,'DefaultFigureRenderer', 'painters');
            set(0,'defaultfigurecolor',[1 1 1]);
            fileName    = app.FileEditField_2.Value;
            fig         = figure();
            axes('pos',[.62 .75 .1 .1]);
            imshow('./pics/Logo_export.png');
            dim = [0.62 0.25 .35 .45];
            
            for i=1:size(app.DatabaseListBox.ItemsData,2)
                if strcmp(string(app.DatabaseListBox.Value), app.DatabaseListBox.ItemsData{1,i}) == 1
                    id = i;
                end
            end

            value  = app.DiagramtypeListBox.Value;
            ncores = string(app.MPIRanksEditFieldLabel_2.Value);
                  
            if value == 'PT'
                sdt = 'Pressure-Temperature, fixed composition';
            elseif value == 'PX'
                sdt = 'Pressure-Composition, fixed temperature';
            elseif value == 'TX'
                sdt = 'Temperature-Composition, fixed pressure';
            end
            OxProp = app.UITableChemistry.Data;
            oxName0 = mat2str(string(reshape(OxProp{:,1}',1,[]) )); oxName = erase(oxName0,'"');oxName1 = erase(oxName,'[');oxName2 = erase(oxName1,']');
            oxComp0 = mat2str(string(reshape(OxProp{:,2}',1,[]) )); oxComp = erase(oxComp0,'"');oxComp1 = erase(oxComp,'[');oxComp2 = erase(oxComp1,']');
            oxCompb0 = mat2str(string(reshape(OxProp{:,3}',1,[]) )); oxCompb = erase(oxCompb0,'"');oxCompb1 = erase(oxCompb,'[');oxCompb2 = erase(oxCompb1,']');

            str = strcat('Phase diagram computed with MAGEMin v1.3.7 (',string(date),')\n\n\\bfDatabase\t\t:\\rm\n',string(app.DatabaseListBox.Items{1,id}), ...
                             '\n\\bfDiagram type\t:\\rm\n',sdt, ...
                             '\n\\bfSolver\t:\\rm\n',string(app.SolverDropDown.Items{1,app.SolverDropDown.Value}) );
            
            if value == 'PT'
                             str = strcat(str,'\n\\bfOxide list\t\t:\\rm\n',oxName2,  ...
                             '\n\\bfOxide comp [mol]\t:\\rm\n',oxComp2);
            elseif value == 'PX'
                             str = strcat(str,'\n\\bfFixed Temperature [C]\t:\\rm\n',num2str(app.TemperatureEditField.Value), ...
                             '\n\\bfOxide list\t\t:\\rm\n',oxName2,  ...
                             '\n\\bfOxide comp X0 [mol]\t:\\rm\n',oxComp2, ...
                             '\n\\bfOxide comp X1 [mol]\t:\\rm\n',oxCompb2);
             elseif value == 'TX'
                             str = strcat(str,'\n\\bfFixed Pressure [kbar]\t:\\rm\n',num2str(app.PressureEditField.Value), ...
                             '\n\\bfOxide list\t\t:\\rm\n',oxName2,  ...
                             '\n\\bfOxide comp X0 [mol]\t:\\rm\n',oxComp2, ...
                             '\n\\bfOxide comp X1 [mol]\t:\\rm\n',oxCompb2);
            end

            if app.buffer.Value ~= 'none'
                buffer_fac =  app.buffer_n.Value;
                str = strcat(str,'\n\\bfBuffer multiplier\t:\\rm\n',num2str(buffer_fac));
            end
            
            str = strcat(str, '\n\n\\bfTotal number of computed points\t:\\rm\n',app.NumPointsLabel.Text,  ...
                              '\n\\bfNumber of used cores\t:\\rm\n',ncores);
            
            str2 = sprintf(str);
            annotation('textbox',dim,'String',str2);
  
            axis off;
            cb          = colorbar(app.UIAxesTE); 
            ylabel(cb, string(app.CommandwindowTextArea.Value));
            h           = copyUIAxes(app.UIAxesTE, fig,'colorbar',cb,'copyPosition', true);
            saveas(fig, fileName, 'epsc');
            saveas(fig, fileName, 'jpg');
            close(fig); 

            set(0,'DefaultFigureColor','factory')
        end

        % Button pushed function: SaveSpectraButton
        function SaveSpectraToFile(app, event)
            
            if app.phaseeqCheckBox.Value == true
                full = 1;
            else
                full = 0;
            end
            save_TEspectra(app.PseudoSectionData,full);
            app.MAGEMinPseudoSectionViewerUIFigure.Visible = 'off';
            app.MAGEMinPseudoSectionViewerUIFigure.Visible = 'on';
        end

        % Value changed function: GridSwitchPTX
        function ShowGridPTX(app, event)
            value = app.GridSwitchPTX.Value;
            
            switch value
                case 'On'
                    set(app.PlotDataPTX,'LineStyle','-');
                case 'Off'
                    set(app.PlotDataPTX,'LineStyle','none');
            end
        end

        % Value changed function: ColormapDropDownPTX
        function ColormapPTXChanged(app, event)
            value = app.ColormapDropDownPTX.Value;
            switch value
                case {'Gray'}
                    colormap(app.UIAxesPTX, flipud(gray))
                case {'Bone'}
                    colormap(app.UIAxesPTX, flipud(bone))
                case {'Pink'}
                    colormap(app.UIAxesPTX, flipud(pink))
                otherwise
                    colormap(app.UIAxesPTX, value)
            end
            
        end

        % Value changed function: DisplayfieldDropDownPTX
        function PlotColorPTX(app, event)
            dataPl  = app.PlotDataPTX;
            data    = app.PseudoSectionData;
            value   = app.DisplayfieldDropDownPTX.Value;
            switch value
                case 'liquid fraction'
                    % change colors
                    set(dataPl,'FaceVertexCData',data.liq(:));
                    h       = colorbar(app.UIAxesPTX);
                    ylabel(h,'liquid fraction')
                    
                case '# of stable phases'
                    % change colors
                    set(dataPl,'FaceVertexCData',data.numPhase(:));
                    h       = colorbar(app.UIAxesPTX);
                    ylabel(h,'# stable phases')
                case 'Variance'
                    numOxides = length(find(data.Chemistry.OxProp.("mol %")>0));
                    Variance = numOxides + 2 - data.numPhase;
                    
                    % change colors
                    set(dataPl,'FaceVertexCData',Variance(:));
                    
                    caxis(app.UIAxesPTX,[1 numOxides+1])
                    
                    h       = colorbar(app.UIAxesPTX);
                    ylabel(h,'Variance')
                    colormap(app.UIAxesPTX, flipud(pink))
                    
                case 'Gibbs'
                    
                    % filter out extreme values
                    Gibbs = data.Gibbs;
                    ind = find(abs(Gibbs)>1e4);
                    Gibbs(ind) = NaN;
                    
                    
                    % change colors
                    set(dataPl,'FaceVertexCData',Gibbs(:));
                    
                    h       = colorbar(app.UIAxesPTX);
                    ylabel(h,'Gibbs Free Energy')
                    
                    
                case 'Gamma'
                    Gamma = data.Gamma(1,:);
                    
                    % filter out extreme values
                    ind = find(abs(Gamma)>1e4);
                    Gamma(ind) = NaN;
                    
                    % change colors
                    set(dataPl,'FaceVertexCData',Gamma(:));
                    
                    h       = colorbar(app.UIAxesPTX);
                    ylabel(h,'Gamma[SiO2]')
                    
                case 'Density'
                    set(dataPl,'FaceVertexCData',data.Rho_tot(:));
                    h       = colorbar(app.UIAxesPTX);
                    ylabel(h,'Average density [kg/m3]')
                    
                case 'Density_liquid'
                    rho = data.Rho_liq(:);
                    rho(rho==0) = NaN;  % places that have no density are set to NaN
                    
                    set(dataPl,'FaceVertexCData',rho);
                    h       = colorbar(app.UIAxesPTX);
                    ylabel(h,'Melt density [kg/m3]')
                    
                case 'Density_solid'
                    rho = data.Rho_sol(:);
                    rho(rho==0) = NaN;  % places that have no density are set to NaN
                    
                    set(dataPl,'FaceVertexCData',rho);
                    h       = colorbar(app.UIAxesPTX);
                    ylabel(h,'Solid density [kg/m3]')
                    
                case 'Vp'
                    set(dataPl,'FaceVertexCData',data.Vp(:));
                    h       = colorbar(app.UIAxesPTX);
                    ylabel(h,'Vp [km/s]')
                    
                case 'Vs'
                    set(dataPl,'FaceVertexCData',data.Vs(:));
                    h       = colorbar(app.UIAxesPTX);
                    ylabel(h,'Vs [km/s]')
                    
                case 'Vp/Vs'
                    VpVs = data.Vp(:)./data.Vs(:);
                    ind = find(VpVs>3);
                    VpVs(ind) = NaN;
                    
                    set(dataPl,'FaceVertexCData',VpVs);
                    h       = colorbar(app.UIAxesPTX);
                    ylabel(h,'Vp/Vs []')
                    
                case 'entropy'
                    set(dataPl,'FaceVertexCData',data.entropy(:));
                    h       = colorbar(app.UIAxesPTX);
                    ylabel(h,'entropy [K/J]')
                    
                    
                case 'Status'
                    set(dataPl,'FaceVertexCData',data.Status(:));
                    
                    h       = colorbar(app.UIAxesPTX);
                    ylabel(h,'Calculation status')
                    
                case 'NumAssemblage'
                    [~,iA,NumAssemblage_discrete] = unique(data.NumAssemblage(:));
                    
                    set(dataPl,'FaceVertexCData',NumAssemblage_discrete);
                    h       = colorbar(app.UIAxesPTX);
                    ylabel(h,'discrete # of assemblage')
                    
                    
                case 'Field'
                    try
                        Field = evalin('base','Field');
                    catch
                        error('A variable "Field" should be present in the workspace of size(length(XY_vec),1)')
                        
                    end

                    % This is an arbitrary field that we can create in the
                    % command window. It should have the correct size
                    set(dataPl,'FaceVertexCData',Field);
                    h       = colorbar(app.UIAxesPTX);
                    ylabel(h,'Field created in the command-window')
     
            end
            
            Caxis_min =  min(get(dataPl,'FaceVertexCData'));
            Caxis_max =  max(get(dataPl,'FaceVertexCData'));
            switch value
                case 'Variance'
                    Caxis_min = 1;
                    Caxis_max = 11;
            end
            if isnan(Caxis_min)
                Caxis_min = 0;
            end
            if isnan(Caxis_max) | Caxis_max<=Caxis_min
                Caxis_max = Caxis_min+1;
            end
            
            
            app.Caxis_min.Value = Caxis_min;
            app.Caxis_max.Value = Caxis_max;
            
            set(app.UIAxesPTX,'CLim',[Caxis_min Caxis_max]);
        end

        % Button pushed function: AddpointButton
        function AddPTXPoint(app, event)
            PseudoSectionData = app.PseudoSectionData;
            k = PseudoSectionData.activePointPTX;

            if PseudoSectionData.Computation.DiagramType == 'PT'
                P               =   PseudoSectionData.PhaseData{1,k}.P;
                T               =   PseudoSectionData.PhaseData{1,k}.T;
                X               =   -1;
                Bulk            =  table2array(PseudoSectionData.Chemistry.OxProp(:,2));
            elseif app.PseudoSectionData.Computation.DiagramType == 'PX'
                P               =   PseudoSectionData.PhaseData{1,k}.P;
                T               =   PseudoSectionData.FixedTemperature;
                X               =   PseudoSectionData.XY_vec(k,1);
                Bulk            =  table2array(PseudoSectionData.Chemistry.OxProp(:,2))*(1.-PseudoSectionData.XY_vec(k,1)) + table2array(PseudoSectionData.Chemistry.OxProp(:,3))*(PseudoSectionData.XY_vec(k,1));
            elseif app.PseudoSectionData.Computation.DiagramType == 'TX'
                P               =   PseudoSectionData.FixedPressure;
                T               =   PseudoSectionData.PhaseData{1,k}.T;
                X               =   PseudoSectionData.XY_vec(k,1);
                Bulk            =  table2array(PseudoSectionData.Chemistry.OxProp(:,2))*(1.-PseudoSectionData.XY_vec(k,1)) + table2array(PseudoSectionData.Chemistry.OxProp(:,3))*(PseudoSectionData.XY_vec(k,1));
            end
    
            SScomp_wt   = PseudoSectionData.PhaseData{1, k}.SScomp_wt;
            phase       = PseudoSectionData.PhaseData{1, k}.StableSolutions;
            fraction    = cell2mat(PseudoSectionData.PhaseData{1, k}.PHfractions_wt);

            dt = PseudoSectionData.DataPTX.AreaData;
            
            if isempty(PseudoSectionData.DataPTX.AreaData)
                dt.Bulk{1}       = Bulk;
                dt.P{1}          = P;
                dt.T{1}          = T;
                dt.X{1}          = X;
                dt.phase{1}      = phase;
                dt.fraction{1}   = fraction;      
                dt.SScomp_wt{1}  = SScomp_wt;
            else
                pt = length(dt.phase) + 1;
                dt.Bulk{pt}       = Bulk;
                dt.P{pt}          = P;
                dt.T{pt}          = T;
                dt.X{pt}          = X;
                dt.phase{pt}      = phase;
                dt.fraction{pt}   = fraction;      
                dt.SScomp_wt{pt}  = SScomp_wt;
            end
            
            PseudoSectionData.DataPTX.AreaData = dt;
            t   =  table([1:length(dt.phase)]',PseudoSectionData.DataPTX.AreaData.P', PseudoSectionData.DataPTX.AreaData.T');  % create a matlab table from it
            app.UITablePTX.Data = t;   % add table to UI
            
            delete(app.PlotPointPTXline)
            if PseudoSectionData.Computation.DiagramType == 'PT'
                app.PlotPointPTXline = plot(app.UIAxesPTX,cell2mat(dt.T),cell2mat(dt.P),'r+-');
            elseif app.PseudoSectionData.Computation.DiagramType == 'PX'
                app.PlotPPlotPointPTXlineointPTX = plot(app.UIAxesPTX,cell2mat(dt.X),cell2mat(dt.P),'r+-');
            elseif app.PseudoSectionData.Computation.DiagramType == 'TX'
                app.PlotPointPTXline = plot(app.UIAxesPTX,cell2mat(dt.X),cell2mat(dt.T),'r+-');
            end
            

            if length(dt.phase) > 0
                
                % update phase list
                ph_list = {};
                for i=1:length(dt.phase)
                    for j=1:length(dt.phase{1,i})
                        str = dt.phase{1,i}(j);
                
                      	id = find(strcmp(ph_list,str));
                      	if isempty(id)
                            ph_list = [ph_list dt.phase{1,i}(j)];
                        end
                    end
                end
                
                % get phase fractions
                mod_array = zeros(length(dt.phase),length(ph_list));
                for i=1:length(dt.phase)
                    for j=1:length(ph_list)
                      id = find(strcmp(dt.phase{1,i},ph_list(j)));
                      if ~isempty(id)
                          mod_array(i,j) = dt.fraction{1,i}(id);
                      else
                          mod_array(i,j) = 0.0;
                      end

                    end
                end
                area(app.UIAxesArea,mod_array);
                legend(app.UIAxesArea,ph_list);
                axis(app.UIAxesArea, 'tight');
                
                if app.ComputecompositionCheckBox.Value == 1
                    lgd = table2cell(PseudoSectionData.Chemistry.OxProp(:,1));
                    n_ox = length(lgd);
                    for j=1:length(ph_list)             %list of stable phases among the selected points
                        comp = zeros(n_ox,length(dt.phase)) +NaN;
                        for i=1:length(dt.phase)        %number of points
                          
                          id = find(strcmp(dt.phase{1,i},ph_list(j)));
                          if ~isempty(id)
                              comp(:,i) = dt.SScomp_wt{1,i}{:,id};
                          end
                        end
                        
                        cmd = ['area(app.PhComp' num2str(j) ',comp''); legend(app.PhComp' num2str(j) ',lgd); axis(app.PhComp' num2str(j) ', ''tight'');'];
                        eval(cmd);
                    end
                    
                    for j=1:length(ph_list)
                        ph = char(ph_list(j));
                        cmd = ['app.ph' num2str(j) 'Tab.Title =  "' ph '" ;'];
                        eval(cmd);
                    end
                    for j=length(ph_list):11
                        cmd = ['app.ph' num2str(j) 'Tab.Title = " ";'];
                        eval(cmd);
                    end   
                end

            end


            app.PseudoSectionData = PseudoSectionData;
            assignin('base','PseudoSectionData',PseudoSectionData);    % send to matlab command-line
        end

        % Button pushed function: RemovelastButton
        function RemoveLastPTXPoint(app, event)
            PseudoSectionData   = app.PseudoSectionData;

            dt = PseudoSectionData.DataPTX.AreaData;
            
            if ~isempty(PseudoSectionData.DataPTX.AreaData)
                pt = length(dt.phase);
                if pt > 0
                    dt.Bulk(pt)       = [];
                    dt.P(pt)          = [];
                    dt.T(pt)          = [];
                    dt.X(pt)          = [];
                    dt.phase(pt)      = [];
                    dt.fraction(pt)   = [];
                    dt.SScomp_wt(pt)  = [];
                    
                    PseudoSectionData.DataPTX.AreaData = dt;
                    t   =  table([1:length(dt.phase)]',PseudoSectionData.DataPTX.AreaData.P', PseudoSectionData.DataPTX.AreaData.T');  % create a matlab table from it
                    app.UITablePTX.Data = t;   % add table to UI
                    
                    delete(app.PlotPointPTXline)
                    if length(dt.phase) > 0
                        if PseudoSectionData.Computation.DiagramType == 'PT'
                            app.PlotPointPTXline = plot(app.UIAxesPTX,cell2mat(dt.T),cell2mat(dt.P),'r+-');
                        elseif app.PseudoSectionData.Computation.DiagramType == 'PX'
                            app.PlotPointPTXline = plot(app.UIAxesPTX,cell2mat(dt.X),cell2mat(dt.P),'r+-');
                        elseif app.PseudoSectionData.Computation.DiagramType == 'TX'
                            app.PlotPointPTXline = plot(app.UIAxesPTX,cell2mat(dt.X),cell2mat(dt.T),'r+-');
                        end
                    end
                    
                    if length(dt.phase) > 0
                        
                        % update phase list
                        ph_list = {};
                        for i=1:length(dt.phase)
                            for j=1:length(dt.phase{1,i})
                                str = dt.phase{1,i}(j);
                        
                              	id = find(strcmp(ph_list,str));
                              	if isempty(id)
                                    ph_list = [ph_list dt.phase{1,i}(j)];
                                end
                            end
                        end
                        
                        % get phase fractions
                        mod_array = zeros(length(dt.phase),length(ph_list));
                        for i=1:length(dt.phase)
                            for j=1:length(ph_list)
                              id = find(strcmp(dt.phase{1,i},ph_list(j)));
                              if ~isempty(id)
                                  mod_array(i,j) = dt.fraction{1,i}(id);
                              else
                                  mod_array(i,j) = 0.0;
                              end
        
                            end
                        end
                        
                        area(app.UIAxesArea,mod_array);
                        legend(app.UIAxesArea,ph_list);
                        axis(app.UIAxesArea, 'tight');
                        if app.ComputecompositionCheckBox.Value == 1
                            lgd = table2cell(PseudoSectionData.Chemistry.OxProp(:,1));
                            n_ox = length(lgd);
                            for j=1:length(ph_list)             %list of stable phases among the selected points
                                comp = zeros(n_ox,length(dt.phase)) +NaN;
                                for i=1:length(dt.phase)        %number of points
                                  
                                  id = find(strcmp(dt.phase{1,i},ph_list(j)));
                                  if ~isempty(id)
                                      comp(:,i) = dt.SScomp_wt{1,i}{:,id};
                                  end
                                end
                                
                                cmd = ['area(app.PhComp' num2str(j) ',comp''); legend(app.PhComp' num2str(j) ',lgd); axis(app.PhComp' num2str(j) ', ''tight'');'];
                                eval(cmd);
                            end
                            
                            for j=1:length(ph_list)
                                ph = char(ph_list(j));
                                cmd = ['app.ph' num2str(j) 'Tab.Title =  "' ph '" ;'];
                                eval(cmd);
                            end
                            for j=length(ph_list):11
                                cmd = ['app.ph' num2str(j) 'Tab.Title = " ";'];
                                eval(cmd);
                            end  
                        end
                    end
                    
                    
                    app.PseudoSectionData = PseudoSectionData;
                    assignin('base','PseudoSectionData',PseudoSectionData);    % send to matlab command-line    
                end
            end
        end

        % Button pushed function: RemovepointButton
        function RemoveNPTXPoint(app, event)
            PseudoSectionData   = app.PseudoSectionData;
            
            dt      = PseudoSectionData.DataPTX.AreaData;
            point   = app.PointidEditField.Value; 
            
            if ~isempty(PseudoSectionData.DataPTX.AreaData)
                pt = length(dt.phase);
                if pt > 0 && point <= pt
                    dt.Bulk(point)       = [];
                    dt.P(point)          = [];
                    dt.T(point)          = [];
                    dt.X(point)          = [];
                    dt.phase(point)      = [];
                    dt.fraction(point)   = [];
                    dt.SScomp_wt(point)  = [];
                    disp(dt)
                    PseudoSectionData.DataPTX.AreaData = dt;
                    t   =  table([1:length(dt.phase)]',PseudoSectionData.DataPTX.AreaData.P', PseudoSectionData.DataPTX.AreaData.T');  % create a matlab table from it
                    app.UITablePTX.Data = t;   % add table to UI
                    
                    delete(app.PlotPointPTXline)
                    if length(dt.phase) > 0
                        if PseudoSectionData.Computation.DiagramType == 'PT'
                            app.PlotPointPTXline = plot(app.UIAxesPTX,cell2mat(dt.T),cell2mat(dt.P),'r+-');
                        elseif app.PseudoSectionData.Computation.DiagramType == 'PX'
                            app.PlotPointPTXline = plot(app.UIAxesPTX,cell2mat(dt.X),cell2mat(dt.P),'r+-');
                        elseif app.PseudoSectionData.Computation.DiagramType == 'TX'
                            app.PlotPointPTXline = plot(app.UIAxesPTX,cell2mat(dt.X),cell2mat(dt.T),'r+-');
                        end
                    end
                    
                    if length(dt.phase) > 0
                        % update phase list
                        ph_list = {};
                        for i=1:length(dt.phase)
                            for j=1:length(dt.phase{1,i})
                                str = dt.phase{1,i}(j);
                        
                              	id = find(strcmp(ph_list,str));
                              	if isempty(id)
                                    ph_list = [ph_list dt.phase{1,i}(j)];
                                end
                            end
                        end
                        
                        % get phase fractions
                        mod_array = zeros(length(dt.phase),length(ph_list));
                        for i=1:length(dt.phase)
                            for j=1:length(ph_list)
                              id = find(strcmp(dt.phase{1,i},ph_list(j)));
                              if ~isempty(id)
                                  mod_array(i,j) = dt.fraction{1,i}(id);
                              else
                                  mod_array(i,j) = 0.0;
                              end
                            end
                        end
                        
                        area(app.UIAxesArea,mod_array);
                        legend(app.UIAxesArea,ph_list);
                        axis(app.UIAxesArea, 'tight');
                        if app.ComputecompositionCheckBox.Value == 1  
                            lgd = table2cell(PseudoSectionData.Chemistry.OxProp(:,1));
                            n_ox = length(lgd);
                            for j=1:length(ph_list)             %list of stable phases among the selected points
                                comp = zeros(n_ox,length(dt.phase)) +NaN;
                                for i=1:length(dt.phase)        %number of points
                                  
                                  id = find(strcmp(dt.phase{1,i},ph_list(j)));
                                  if ~isempty(id)
                                      comp(:,i) = dt.SScomp_wt{1,i}{:,id};
                                  end
                                end
                                
                                cmd = ['area(app.PhComp' num2str(j) ',comp''); legend(app.PhComp' num2str(j) ',lgd); axis(app.PhComp' num2str(j) ', ''tight'');'];
                                eval(cmd);
                            end
                            
                            for j=1:length(ph_list)
                                ph = char(ph_list(j));
                                cmd = ['app.ph' num2str(j) 'Tab.Title =  "' ph '" ;'];
                                eval(cmd);
                            end
                            for j=length(ph_list):11
                                cmd = ['app.ph' num2str(j) 'Tab.Title = " ";'];
                                eval(cmd);
                            end   
                        end

                    end
                    
                    app.PseudoSectionData = PseudoSectionData;
                    assignin('base','PseudoSectionData',PseudoSectionData);    % send to matlab command-line    
                end
            end

        end

        % Button pushed function: RemoveallButton
        function RemoveAllPTXPoint(app, event)
            PseudoSectionData   = app.PseudoSectionData;

            dt = PseudoSectionData.DataPTX.AreaData;
            
            if ~isempty(PseudoSectionData.DataPTX.AreaData)
                pt = length(dt.phase);
                if pt > 0
                    dt.Bulk(:)       = [];
                    dt.P(:)          = [];
                    dt.T(:)          = [];
                    dt.X(:)          = [];
                    dt.phase(:)      = [];
                    dt.fraction(:)   = [];
                    dt.SScomp_wt(:)  = [];
                    
                    PseudoSectionData.DataPTX.AreaData = dt;
                    t   =  table([1:length(dt.phase)]',PseudoSectionData.DataPTX.AreaData.P', PseudoSectionData.DataPTX.AreaData.T');  % create a matlab table from it
                    app.UITablePTX.Data = t;   % add table to UI
                    
                    delete(app.PlotPointPTXline)

                    app.PseudoSectionData = PseudoSectionData;
                    assignin('base','PseudoSectionData',PseudoSectionData);    % send to matlab command-line    
                end
            end
        end

        % Button pushed function: LoadfromfileButton_2
        function LoadTEBulkFromFile(app, event)

            [file,path] = uigetfile('*.dat');
            bulkTE_in = loadTEBulkFromFile(file,path);
            app.UITableTraceElement.Data = bulkTE_in;
            app.TitleEditFieldTE.Value = file(1:end-4);
%             app.MAGEMinPseudoSectionViewerUIFigure.Visible = 'off';
%             app.MAGEMinPseudoSectionViewerUIFigure.Visible = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MAGEMinPseudoSectionViewerUIFigure = uifigure('Visible', 'off');
            app.MAGEMinPseudoSectionViewerUIFigure.Name = 'MAGEMin PseudoSection Viewer';
            app.MAGEMinPseudoSectionViewerUIFigure.ButtonDownFcn = createCallbackFcn(app, @Retrieve_PT, true);
            app.FileMenu = uimenu(app.MAGEMinPseudoSectionViewerUIFigure);
            app.FileMenu.Text = 'File';
            app.InstallMAGEMinMenu = uimenu(app.FileMenu);
            app.InstallMAGEMinMenu.MenuSelectedFcn = createCallbackFcn(app, @InstallMAGEMinMenuSelected, true);
            app.InstallMAGEMinMenu.Text = 'Install MAGEMin';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load';
            app.SaveasMenu = uimenu(app.FileMenu);
            app.SaveasMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveasMenuSelected, true);
            app.SaveasMenu.Text = 'Save as';
            app.OpeninnewFigureMenu = uimenu(app.FileMenu);
            app.OpeninnewFigureMenu.MenuSelectedFcn = createCallbackFcn(app, @OpeninnewFigureMenuSelected, true);
            app.OpeninnewFigureMenu.Text = 'Open in new Figure';
            app.ExporttoLaMEMGeoParamsMenu = uimenu(app.FileMenu);
            app.ExporttoLaMEMGeoParamsMenu.MenuSelectedFcn = createCallbackFcn(app, @ExporttoLaMEMGeoParamsMenuSelected, true);
            app.ExporttoLaMEMGeoParamsMenu.Text = 'Export to LaMEM/GeoParams';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @QuitMenuSelected, true);
            app.QuitMenu.Text = 'Quit';
            app.TabGroup3 = uitabgroup(app.MAGEMinPseudoSectionViewerUIFigure);
            app.SimulationTab = uitab(app.TabGroup3);
            app.SimulationTab.Title = 'Simulation';
            app.SimulationinfoPanel = uipanel(app.SimulationTab);
            app.SimulationinfoPanel.Title = 'Simulation info';
            app.SaveButton = uibutton(app.SimulationinfoPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAs, true);
            app.SaveButton.Text = 'Save';
            app.LoadButton = uibutton(app.SimulationinfoPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFile, true);
            app.LoadButton.Text = 'Load';
            app.SaveFileName = uibutton(app.SimulationinfoPanel, 'push');
            app.SaveFileName.ButtonPushedFcn = createCallbackFcn(app, @SaveFilename, true);
            app.SaveFileName.Text = '...';
            app.DiagramtypeListBoxLabel = uilabel(app.SimulationinfoPanel);
            app.DiagramtypeListBoxLabel.Tooltip = {'PT, Pressure Temperature and fixed composition'; 'TX, Temperature Composition and fixed pressure'; 'PX, Pressure Composition and fixed temperature'};
            app.DiagramtypeListBoxLabel.Text = {'Diagram'; 'type'};
            app.DiagramtypeListBox = uilistbox(app.SimulationinfoPanel);
            app.DiagramtypeListBox.Items = {'PT', 'TX', 'PX'};
            app.DiagramtypeListBox.ValueChangedFcn = createCallbackFcn(app, @changeDiagramType, true);
            app.DiagramtypeListBox.Tooltip = {''};
            app.DiagramtypeListBox.Value = 'PT';
            app.SimulationParametersPanel = uipanel(app.SimulationTab);
            app.SimulationParametersPanel.Title = 'Simulation Parameters';
            app.UITable = uitable(app.SimulationParametersPanel);
            app.UITable.ColumnName = {'from'; 'to'; 'step'};
            app.UITable.RowName = {'P [kbar]'; 'T [C]'; ''};
            app.UITable.ColumnEditable = [true true true true];
            app.UITable.Tooltip = {'Note that is is preferable to set the minimum pressure to 0.01 kbar'};
            app.CoarsegridresolutionLabel = uilabel(app.SimulationParametersPanel);
            app.CoarsegridresolutionLabel.Text = 'Coarse grid resolution';
            app.RefinementsDropDownLabel = uilabel(app.SimulationParametersPanel);
            app.RefinementsDropDownLabel.Text = 'Refinements';
            app.RefinementsDropDown = uidropdown(app.SimulationParametersPanel);
            app.RefinementsDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.RefinementsDropDown.Value = '8';
            app.VerboselevelDropDownLabel = uilabel(app.SimulationParametersPanel);
            app.VerboselevelDropDownLabel.Text = 'Verbose level';
            app.VerboselevelDropDown = uidropdown(app.SimulationParametersPanel);
            app.VerboselevelDropDown.Items = {'0', '-1', '1'};
            app.VerboselevelDropDown.Tooltip = {'-1, no verbose'; '0, minimum verbose, 1, maximum verbose'};
            app.VerboselevelDropDown.Value = '0';
            app.ParallelcomputationsCheckBox = uicheckbox(app.SimulationParametersPanel);
            app.ParallelcomputationsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ParallelcomputationsCheckBoxValueChanged, true);
            app.ParallelcomputationsCheckBox.Text = 'Parallel computations';
            app.ParallelcomputationsCheckBox.Value = true;
            app.GammaestimationCheckBox = uicheckbox(app.SimulationParametersPanel);
            app.GammaestimationCheckBox.ValueChangedFcn = createCallbackFcn(app, @GammaestimationCheckBoxValueChanged, true);
            app.GammaestimationCheckBox.Enable = 'off';
            app.GammaestimationCheckBox.Text = 'Gamma estimation';
            app.MPIRanksEditFieldLabel_2 = uieditfield(app.SimulationParametersPanel, 'text');
            app.MPIRanksEditFieldLabel_2.Enable = 'off';
            app.MPIRanksEditFieldLabel_2.Value = '1';
            app.MPIRanksEditFieldLabel_1 = uilabel(app.SimulationParametersPanel);
            app.MPIRanksEditFieldLabel_1.Enable = 'off';
            app.MPIRanksEditFieldLabel_1.Text = '# of cores';
            app.MPIRanksEditFieldLabel_3 = uilabel(app.SimulationParametersPanel);
            app.MPIRanksEditFieldLabel_3.Enable = 'off';
            app.MPIRanksEditFieldLabel_3.Text = 'mpiexec path:';
            app.MPIRanksEditFieldLabel_4 = uieditfield(app.SimulationParametersPanel, 'text');
            app.MPIRanksEditFieldLabel_4.Enable = 'off';
            app.MPIRanksEditFieldLabel_4.Value = '/usr/bin/';
            app.SelectMPIexec = uibutton(app.SimulationParametersPanel, 'push');
            app.SelectMPIexec.ButtonPushedFcn = createCallbackFcn(app, @SelectMPIexecButtonPushed, true);
            app.SelectMPIexec.Enable = 'off';
            app.SelectMPIexec.Text = '...';
            app.UsexEOSuponrefinementCheckBox = uicheckbox(app.SimulationParametersPanel);
            app.UsexEOSuponrefinementCheckBox.ValueChangedFcn = createCallbackFcn(app, @UsexEOSuponrefinementCheckBoxValueChanged, true);
            app.UsexEOSuponrefinementCheckBox.Enable = 'off';
            app.UsexEOSuponrefinementCheckBox.Text = 'Use x-EOS upon refinement';
            app.xEOSDropDown = uidropdown(app.SimulationParametersPanel);
            app.xEOSDropDown.Items = {'Inherit from coarse mesh', 'Average surrounding points', 'Average whole domain', 'Neural Network'};
            app.xEOSDropDown.ValueChangedFcn = createCallbackFcn(app, @xEOSDropDownValueChanged, true);
            app.xEOSDropDown.Enable = 'off';
            app.xEOSDropDown.Value = 'Inherit from coarse mesh';
            app.num_Neighbours = uieditfield(app.SimulationParametersPanel, 'numeric');
            app.num_Neighbours.Enable = 'off';
            app.num_Neighbours.Value = 9;
            app.Gamma_Dropdown = uidropdown(app.SimulationParametersPanel);
            app.Gamma_Dropdown.Items = {'Inherit from coarse mesh', 'AMR interpolation', 'Neural Network 1'};
            app.Gamma_Dropdown.Enable = 'off';
            app.Gamma_Dropdown.Value = 'Inherit from coarse mesh';
            app.EMFracDropDown = uidropdown(app.SimulationParametersPanel);
            app.EMFracDropDown.Items = {'Inherit from coarse mesh', 'Average surrounding points', 'Average whole domain', 'Neural Network'};
            app.EMFracDropDown.ValueChangedFcn = createCallbackFcn(app, @EMFracDropDownValueChanged, true);
            app.EMFracDropDown.Enable = 'off';
            app.EMFracDropDown.Value = 'Inherit from coarse mesh';
            app.UseEMFractionuponrefinementCheckBox = uicheckbox(app.SimulationParametersPanel);
            app.UseEMFractionuponrefinementCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseEMFractionuponrefinementCheckBoxValueChanged, true);
            app.UseEMFractionuponrefinementCheckBox.Enable = 'off';
            app.UseEMFractionuponrefinementCheckBox.Text = 'Use EM Fraction upon refinement';
            app.num_Neighbours_EMFrac = uieditfield(app.SimulationParametersPanel, 'numeric');
            app.num_Neighbours_EMFrac.Enable = 'off';
            app.num_Neighbours_EMFrac.Value = 9;
            app.PerformcomputationsonremoteserverCheckBox = uicheckbox(app.SimulationParametersPanel);
            app.PerformcomputationsonremoteserverCheckBox.Enable = 'off';
            app.PerformcomputationsonremoteserverCheckBox.Text = 'Perform computations on remote server';
            app.MAGEMinexecutableLabel = uilabel(app.SimulationParametersPanel);
            app.MAGEMinexecutableLabel.Text = 'MAGEMin executable:';
            app.Binary_Switch = uiswitch(app.SimulationParametersPanel, 'slider');
            app.Binary_Switch.Items = {'Default', 'Local'};
            app.Binary_Switch.ValueChangedFcn = createCallbackFcn(app, @Binary_SwitchValueChanged, true);
            app.Binary_Switch.Enable = 'off';
            app.Binary_Switch.Value = 'Default';
            app.SolverDropDownLabel = uilabel(app.SimulationParametersPanel);
            app.SolverDropDownLabel.Text = 'Solver';
            app.SolverDropDown = uidropdown(app.SimulationParametersPanel);
            app.SolverDropDown.Items = {'PGE', 'Legacy', 'Hybrid(default)'};
            app.SolverDropDown.ItemsData = [1 0 2];
            app.SolverDropDown.Tooltip = {'By default the solver is hybrid PGE/LP (Partionning Gibbs Energy). In some cases the solver might fail, mostly when Gibbs Duhem cannot be properly enforced. In these cases using the Legacy solver may help.'};
            app.SolverDropDown.Value = 2;
            app.PressureLabel = uilabel(app.SimulationParametersPanel);
            app.PressureLabel.Enable = 'off';
            app.PressureLabel.Text = 'Pressure';
            app.TemperatureLabel = uilabel(app.SimulationParametersPanel);
            app.TemperatureLabel.Enable = 'off';
            app.TemperatureLabel.Text = 'Temperature';
            app.TemperatureEditField = uieditfield(app.SimulationParametersPanel, 'numeric');
            app.TemperatureEditField.Enable = 'off';
            app.TemperatureEditField.Value = 800;
            app.PressureEditField = uieditfield(app.SimulationParametersPanel, 'numeric');
            app.PressureEditField.Enable = 'off';
            app.PressureEditField.Value = 10;
            app.Labelstyle = uilabel(app.SimulationParametersPanel);
            app.Labelstyle.Text = {'Label '; 'style'};
            app.LabelstyleDropDown = uidropdown(app.SimulationParametersPanel);
            app.LabelstyleDropDown.Items = {'THERMOCALC', 'Phase only', 'Dominant Endmmeber'};
            app.LabelstyleDropDown.ItemsData = [0 1 2];
            app.LabelstyleDropDown.Value = 1;
            app.BufferDropDownLabel = uilabel(app.SimulationParametersPanel);
            app.BufferDropDownLabel.Text = 'Buffer';
            app.buffer = uidropdown(app.SimulationParametersPanel);
            app.buffer.Items = {'none', 'MW', 'QFM', 'CCO', 'NNO', 'HM', 'QIF'};
            app.buffer.ItemsData = {'none', 'mw', 'qfm', 'cco', 'nno', 'hm', 'qif'};
            app.buffer.Value = 'none';
            app.MultiplierLabel = uilabel(app.SimulationParametersPanel);
            app.MultiplierLabel.Text = 'Multiplier';
            app.buffer_n = uieditfield(app.SimulationParametersPanel, 'numeric');
            app.ChemicalcompositionPanel = uipanel(app.SimulationTab);
            app.ChemicalcompositionPanel.Title = 'Chemical composition';
            app.UITableChemistry = uitable(app.ChemicalcompositionPanel);
            app.UITableChemistry.ColumnName = {'Oxide'; 'mol Frac1'; 'mol Frac2'};
            app.UITableChemistry.RowName = {};
            app.UITableChemistry.ColumnEditable = [true true true];
            app.PredefinedtestDropDownLabel = uilabel(app.ChemicalcompositionPanel);
            app.PredefinedtestDropDownLabel.Text = 'Predefined test';
            app.PredefinedtestDropDown = uidropdown(app.ChemicalcompositionPanel);
            app.PredefinedtestDropDown.Items = {'0', '1', '2', '3', '4', '5', '6', '7'};
            app.PredefinedtestDropDown.ValueChangedFcn = createCallbackFcn(app, @PredefinedtestDropDownValueChanged, true);
            app.PredefinedtestDropDown.Value = '0';
            app.Switch = uiswitch(app.ChemicalcompositionPanel, 'slider');
            app.Switch.Items = {'Preset bulk', 'Custom bulk'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @ChemistrySelectionSwitch, true);
            app.Switch.Value = 'Preset bulk';
            app.LoadfromfileButton = uibutton(app.ChemicalcompositionPanel, 'push');
            app.LoadfromfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfromfileButtonPushed, true);
            app.LoadfromfileButton.Enable = 'off';
            app.LoadfromfileButton.Tooltip = {'The bulk composition should be provided in a ".dat" file with a header line such as "SiO2 Al2O3 ..." and the second line with the corresponding oxide content.'; 'Note that the input system unit is selected above and will be converted into mol.'; 'Example given in "/examples/bulk1.dat"'};
            app.LoadfromfileButton.Text = 'Load from file';
            app.SystemunitDropDown = uidropdown(app.ChemicalcompositionPanel);
            app.SystemunitDropDown.Items = {'mol', 'wt'};
            app.SystemunitDropDown.Enable = 'off';
            app.SystemunitDropDown.Tooltip = {'Don''t forget to select the right system unit before loading the file.'};
            app.SystemunitDropDown.Value = 'mol';
            app.BulkCaption = uilabel(app.ChemicalcompositionPanel);
            app.BulkCaption.Text = 'Bulk-rock composition';
            app.TitleEditField = uieditfield(app.SimulationTab, 'text');
            app.TitleEditField.ValueChangedFcn = createCallbackFcn(app, @ChangeTitle, true);
            app.TitleEditField.Value = 'KLB-1';
            app.TitleEditFieldLabel = uilabel(app.SimulationTab);
            app.TitleEditFieldLabel.Text = 'Title';
            app.FilenameEditField = uieditfield(app.SimulationTab, 'text');
            app.FilenameEditField.Value = 'test';
            app.FilenameEditFieldLabel = uilabel(app.SimulationTab);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.ThermodynamicdatabasePanel = uipanel(app.SimulationTab);
            app.ThermodynamicdatabasePanel.Title = 'Thermodynamic database';
            app.DatabaseListBox = uilistbox(app.ThermodynamicdatabasePanel);
            app.DatabaseListBox.Items = {'Igneous (Holland et al., 2018)', 'Metapelite (White et al., 2014)', 'Metabasite (Green et al., 2016)', 'Ultramafic (Evans&Frost, 2021)'};
            app.DatabaseListBox.ItemsData = {'ig', 'mp', 'mb', 'um'};
            app.DatabaseListBox.ValueChangedFcn = createCallbackFcn(app, @PredefinedtestDropDownValueChanged, true);
            app.DatabaseListBox.Tooltip = {'Here you can select between available database. Note that the chemical system can change between database'; ''};
            app.DatabaseListBox.Value = 'ig';
            app.LimitCaopxCheckBox = uicheckbox(app.ThermodynamicdatabasePanel);
            app.LimitCaopxCheckBox.Tooltip = {'This limit the stability of CaO-rich opx for the igneous database'};
            app.LimitCaopxCheckBox.Text = 'Limit Ca-opx';
            app.SwitchCpx = uiswitch(app.ThermodynamicdatabasePanel, 'slider');
            app.SwitchCpx.Items = {'Omph', 'Aug'};
            app.SwitchCpx.Enable = 'off';
            app.SwitchCpx.Tooltip = {'Omph is the low temperature cpx, white Aug has to be used in sub-solidus to supra-solidus conditions'; ''};
            app.SwitchCpx.Value = 'Omph';
            app.CaOpxLim = uieditfield(app.ThermodynamicdatabasePanel, 'numeric');
            app.CaOpxLim.Value = 0.5;
            app.StartnewcomputationButton = uibutton(app.SimulationTab, 'push');
            app.StartnewcomputationButton.ButtonPushedFcn = createCallbackFcn(app, @StartNewComputation, true);
            app.StartnewcomputationButton.Text = 'Start new computation';
            app.Image2 = uiimage(app.SimulationTab);
            app.Image2.ImageSource = 'Logo_MagMin4.png';
            app.v137Label = uilabel(app.SimulationTab);
            app.v137Label.Text = 'v1.3.7';
            app.Image = uiimage(app.SimulationTab);
            app.Image.ImageSource = 'JGR_MAGMA_ERC.png';
            app.PseudosectionTab = uitab(app.TabGroup3);
            app.PseudosectionTab.Title = 'Pseudosection';
            app.TabGroup6 = uitabgroup(app.PseudosectionTab);
            app.InformationRefineTab = uitab(app.TabGroup6);
            app.InformationRefineTab.Title = 'Information/Refine';
            app.AxesFontsizeLabel = uilabel(app.InformationRefineTab);
            app.AxesFontsizeLabel.Text = 'Axes Fontsize';
            app.Axes_Fontsize = uieditfield(app.InformationRefineTab, 'numeric');
            app.Axes_Fontsize.ValueChangedFcn = createCallbackFcn(app, @Axes_FontsizeValueChanged, true);
            app.Axes_Fontsize.Value = 16;
            app.TitleFontsizeLabel = uilabel(app.InformationRefineTab);
            app.TitleFontsizeLabel.Text = 'Title Fontsize';
            app.Title_Fontsize = uieditfield(app.InformationRefineTab, 'numeric');
            app.Title_Fontsize.ValueChangedFcn = createCallbackFcn(app, @Title_FontsizeValueChanged, true);
            app.Title_Fontsize.Value = 20;
            app.ColoraxisLabel = uilabel(app.InformationRefineTab);
            app.ColoraxisLabel.Text = 'Color axis';
            app.Caxis_min = uieditfield(app.InformationRefineTab, 'numeric');
            app.Caxis_min.ValueChangedFcn = createCallbackFcn(app, @Caxis_minValueChanged, true);
            app.Caxis_min.Value = 1;
            app.Caxis_max = uieditfield(app.InformationRefineTab, 'numeric');
            app.Caxis_max.ValueChangedFcn = createCallbackFcn(app, @Caxis_maxValueChanged, true);
            app.Caxis_max.Value = 7;
            app.DISPLAYOPTIONSLabel = uilabel(app.InformationRefineTab);
            app.DISPLAYOPTIONSLabel.Text = 'DISPLAY OPTIONS';
            app.AddlabelsCheckBox = uicheckbox(app.InformationRefineTab);
            app.AddlabelsCheckBox.Text = 'Add labels';
            app.removealllabelsButton = uibutton(app.InformationRefineTab, 'push');
            app.removealllabelsButton.ButtonPushedFcn = createCallbackFcn(app, @removealllabelsButtonPushed, true);
            app.removealllabelsButton.Text = 'remove all labels';
            app.removelastlabelButton = uibutton(app.InformationRefineTab, 'push');
            app.removelastlabelButton.ButtonPushedFcn = createCallbackFcn(app, @removelastlabelButtonPushed, true);
            app.removelastlabelButton.Text = 'remove last label';
            app.AddlabellineCheckBox = uicheckbox(app.InformationRefineTab);
            app.AddlabellineCheckBox.Text = 'Add label line';
            app.AddlabellineCheckBox.Value = true;
            app.LABELPHASEASSEMBLAGELabel = uilabel(app.InformationRefineTab);
            app.LABELPHASEASSEMBLAGELabel.Text = 'LABEL PHASE ASSEMBLAGE';
            app.SINGLEPOINTOPTIONSLabel = uilabel(app.InformationRefineTab);
            app.SINGLEPOINTOPTIONSLabel.Text = 'SINGLE POINT OPTIONS';
            app.REFINEGRIDCALCULATIONLabel = uilabel(app.InformationRefineTab);
            app.REFINEGRIDCALCULATIONLabel.Text = 'REFINE GRID CALCULATION';
            app.StartrefinementButton = uibutton(app.InformationRefineTab, 'push');
            app.StartrefinementButton.ButtonPushedFcn = createCallbackFcn(app, @StartRefinement, true);
            app.StartrefinementButton.Text = 'Start refinement';
            app.ShowcellcentersselectpointsButton = uibutton(app.InformationRefineTab, 'push');
            app.ShowcellcentersselectpointsButton.ButtonPushedFcn = createCallbackFcn(app, @ShowCellCenters, true);
            app.ShowcellcentersselectpointsButton.Text = 'Show cell centers & select points';
            app.RefineselectedareaButton = uibutton(app.InformationRefineTab, 'push');
            app.RefineselectedareaButton.ButtonPushedFcn = createCallbackFcn(app, @RefineMesh, true);
            app.RefineselectedareaButton.Text = 'Refine selected area';
            app.RefineallphaseboundariesButton = uibutton(app.InformationRefineTab, 'push');
            app.RefineallphaseboundariesButton.ButtonPushedFcn = createCallbackFcn(app, @RefinePhaseBounds, true);
            app.RefineallphaseboundariesButton.Text = 'Refine all phase boundaries';
            app.UniformrefinementButton = uibutton(app.InformationRefineTab, 'push');
            app.UniformrefinementButton.ButtonPushedFcn = createCallbackFcn(app, @UniformrefinementButtonPushed, true);
            app.UniformrefinementButton.Text = 'Uniform refinement';
            app.RefineunivariantpointsButton = uibutton(app.InformationRefineTab, 'push');
            app.RefineunivariantpointsButton.ButtonPushedFcn = createCallbackFcn(app, @RefineunivariantpointsButtonPushed, true);
            app.RefineunivariantpointsButton.Text = 'Refine univariant points';
            app.StableAssemblagePanel = uipanel(app.InformationRefineTab);
            app.StableAssemblagePanel.Title = 'Stable Assemblage';
            app.UITableStableAssemblage = uitable(app.StableAssemblagePanel);
            app.UITableStableAssemblage.ColumnName = {'Stable Phase'; 'Mol fraction'};
            app.UITableStableAssemblage.RowName = {};
            app.UITableStableAssemblage.ColumnEditable = [false true];
            app.DatapointPanel = uipanel(app.InformationRefineTab);
            app.DatapointPanel.Title = 'Data point';
            app.TextAreaInfo = uitextarea(app.DatapointPanel);
            app.SaveequilibriumdataButton = uibutton(app.DatapointPanel, 'push');
            app.SaveequilibriumdataButton.ButtonPushedFcn = createCallbackFcn(app, @savePointData, true);
            app.SaveequilibriumdataButton.Enable = 'off';
            app.SaveequilibriumdataButton.Tooltip = {'This runs a single point calculation on the selected grid point and saves it using the "matlab_output" format'};
            app.SaveequilibriumdataButton.Text = 'Save equilibrium data ';
            app.newpointsTextAreaLabel = uilabel(app.InformationRefineTab);
            app.newpointsTextAreaLabel.Text = '# new points';
            app.newpointsTextArea = uitextarea(app.InformationRefineTab);
            app.newpointsTextArea.Value = {'0'};
            app.StyleLabel_3 = uilabel(app.InformationRefineTab);
            app.StyleLabel_3.Text = 'Style';
            app.SelectPt_MarkerStyle = uieditfield(app.InformationRefineTab, 'text');
            app.SelectPt_MarkerStyle.Value = 'r+';
            app.ColormapDropDownLabel = uilabel(app.InformationRefineTab);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.ColormapDropDown = uidropdown(app.InformationRefineTab);
            app.ColormapDropDown.Items = {'Parula', 'Gray', 'Bone', 'Pink', 'jet'};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown.Value = 'Pink';
            app.DisplayfieldDropDownLabel = uilabel(app.InformationRefineTab);
            app.DisplayfieldDropDownLabel.Text = 'Display field';
            app.DisplayfieldDropDown = uidropdown(app.InformationRefineTab);
            app.DisplayfieldDropDown.Items = {'# of stable phases', 'Variance', 'liquid fraction', 'Gibbs', 'entropy', 'Gamma', 'Density', 'Density_liquid', 'Density_solid', 'Vp', 'Vs', 'Vp/Vs', 'Status', 'NumAssemblage', 'Field'};
            app.DisplayfieldDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotColor, true);
            app.DisplayfieldDropDown.Value = '# of stable phases';
            app.ShowgridSwitchLabel = uilabel(app.InformationRefineTab);
            app.ShowgridSwitchLabel.Text = 'Show grid';
            app.ShowgridSwitch = uiswitch(app.InformationRefineTab, 'slider');
            app.ShowgridSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowgridSwitchValueChanged, true);
            app.ShowgridSwitch.Value = 'On';
            app.ShowreactionlineSwitchLabel = uilabel(app.InformationRefineTab);
            app.ShowreactionlineSwitchLabel.Text = 'Show reaction line';
            app.ShowreactionlineSwitch = uiswitch(app.InformationRefineTab, 'slider');
            app.ShowreactionlineSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowReactionLine, true);
            app.WidthLabel_3 = uilabel(app.InformationRefineTab);
            app.WidthLabel_3.Text = 'Width';
            app.SelectPt_MarkerWidth = uieditfield(app.InformationRefineTab, 'numeric');
            app.SelectPt_MarkerWidth.Value = 1.5;
            app.SizeLabel = uilabel(app.InformationRefineTab);
            app.SizeLabel.Text = 'Size';
            app.SelectPt_MarkerSize = uieditfield(app.InformationRefineTab, 'numeric');
            app.SelectPt_MarkerSize.Value = 20;
            app.IsocontourTab_3 = uitab(app.TabGroup6);
            app.IsocontourTab_3.Title = 'Isocontour';
            app.CHOOSEFIELDTOCONTOURLabel = uilabel(app.IsocontourTab_3);
            app.CHOOSEFIELDTOCONTOURLabel.Text = 'CHOOSE FIELD TO CONTOUR';
            app.DataListBox = uilistbox(app.IsocontourTab_3);
            app.DataListBox.Items = {'mode', 'em frac'};
            app.DataListBox.ValueChangedFcn = createCallbackFcn(app, @SelectContourMode, true);
            app.DataListBox.Value = 'mode';
            app.emnamesListBox = uilistbox(app.IsocontourTab_3);
            app.emnamesListBox.Items = {'em1', 'em2', 'em3', ''};
            app.emnamesListBox.Enable = 'off';
            app.emnamesListBox.Value = 'em1';
            app.phaseEditField = uieditfield(app.IsocontourTab_3, 'text');
            app.phaseEditField.ValueChangedFcn = createCallbackFcn(app, @SelectContourMode, true);
            app.phaseEditFieldLabel = uilabel(app.IsocontourTab_3);
            app.phaseEditFieldLabel.Text = 'phase';
            app.displayisocontourButton = uibutton(app.IsocontourTab_3, 'push');
            app.displayisocontourButton.ButtonPushedFcn = createCallbackFcn(app, @displayIsocontourFunction, true);
            app.displayisocontourButton.Text = 'display isocontour';
            app.removeallButton = uibutton(app.IsocontourTab_3, 'push');
            app.removeallButton.ButtonPushedFcn = createCallbackFcn(app, @clearIsocontour, true);
            app.removeallButton.Text = 'remove all';
            app.removelastsButton = uibutton(app.IsocontourTab_3, 'push');
            app.removelastsButton.ButtonPushedFcn = createCallbackFcn(app, @clearlast, true);
            app.removelastsButton.Text = 'remove last(s)';
            app.Iso_label = uilabel(app.IsocontourTab_3);
            app.Isopleth_2 = uilabel(app.IsocontourTab_3);
            app.Isopleth_2.Text = 'Line';
            app.SELECTRANGEANDSETRESOLUTIONLabel = uilabel(app.IsocontourTab_3);
            app.SELECTRANGEANDSETRESOLUTIONLabel.Text = 'SELECT RANGE AND SET RESOLUTION';
            app.CHOOSEPLOTTINGSTYLELabel = uilabel(app.IsocontourTab_3);
            app.CHOOSEPLOTTINGSTYLELabel.Text = 'CHOOSE PLOTTING STYLE';
            app.CONTROLISOPLETHSLabel = uilabel(app.IsocontourTab_3);
            app.CONTROLISOPLETHSLabel.Text = 'CONTROL ISOPLETHS';
            app.minEditFieldLabel = uilabel(app.IsocontourTab_3);
            app.minEditFieldLabel.Text = 'min';
            app.minEditField = uieditfield(app.IsocontourTab_3, 'text');
            app.minEditField.Value = '0.0';
            app.stepEditField = uieditfield(app.IsocontourTab_3, 'text');
            app.stepEditField.Value = '0.1';
            app.stepEditFieldLabel = uilabel(app.IsocontourTab_3);
            app.stepEditFieldLabel.Text = 'step';
            app.maxEditField = uieditfield(app.IsocontourTab_3, 'text');
            app.maxEditField.Value = '1.0';
            app.maxEditFieldLabel = uilabel(app.IsocontourTab_3);
            app.maxEditFieldLabel.Text = 'max';
            app.resEditField = uieditfield(app.IsocontourTab_3, 'text');
            app.resEditField.Value = '50';
            app.resEditFieldLabel = uilabel(app.IsocontourTab_3);
            app.resEditFieldLabel.Text = 'res';
            app.TCLines_LineColor_4 = uieditfield(app.IsocontourTab_3, 'text');
            app.TCLines_LineColor_4.Value = 'black';
            app.ColorLabel = uilabel(app.IsocontourTab_3);
            app.ColorLabel.Text = 'Color';
            app.TCLines_LineColor_5 = uieditfield(app.IsocontourTab_3, 'text');
            app.TCLines_LineColor_5.Value = '8';
            app.FontsizeLabel = uilabel(app.IsocontourTab_3);
            app.FontsizeLabel.Text = 'Fontsize';
            app.TCLines_LineWidth_1 = uieditfield(app.IsocontourTab_3, 'numeric');
            app.TCLines_LineWidth_1.Value = 0.1;
            app.WidthLabel_2 = uilabel(app.IsocontourTab_3);
            app.WidthLabel_2.Text = 'Width';
            app.TCLines_LineColor_2 = uieditfield(app.IsocontourTab_3, 'text');
            app.TCLines_LineColor_2.Value = 'r-';
            app.StyleLabel_2 = uilabel(app.IsocontourTab_3);
            app.StyleLabel_2.Text = 'Style';
            app.ExportfigureButton = uibutton(app.PseudosectionTab, 'push');
            app.ExportfigureButton.ButtonPushedFcn = createCallbackFcn(app, @ExportFigure, true);
            app.ExportfigureButton.Text = 'Export figure';
            app.FileEditFieldLabel = uilabel(app.PseudosectionTab);
            app.FileEditFieldLabel.Text = 'File:';
            app.FileEditField = uieditfield(app.PseudosectionTab, 'text');
            app.FileEditField.Value = './fig_name';
            app.NumPointsLabel = uilabel(app.PseudosectionTab);
            app.NumPointsLabel.Text = 'Points';
            app.UIAxes = uiaxes(app.PseudosectionTab);
            title(app.UIAxes, 'Pseudosection')
            xlabel(app.UIAxes, 'Temperature [Celsius]')
            ylabel(app.UIAxes, 'Pressure [kbar]')
            zlabel(app.UIAxes, 'Z')
            app.PTXpathTab = uitab(app.TabGroup3);
            app.PTXpathTab.Title = 'PTX path';
            app.StableAssemblagePTX = uipanel(app.PTXpathTab);
            app.StableAssemblagePTX.Title = 'Stable Assemblage';
            app.UITableStableAssemblagePTX = uitable(app.StableAssemblagePTX);
            app.UITableStableAssemblagePTX.ColumnName = {'Stable phase'; 'Mol fraction'};
            app.UITableStableAssemblagePTX.RowName = {};
            app.TextAreaInfoPTX = uitextarea(app.StableAssemblagePTX);
            app.GridSwitch_2Label = uilabel(app.PTXpathTab);
            app.GridSwitch_2Label.Text = 'Grid';
            app.GridSwitchPTX = uiswitch(app.PTXpathTab, 'slider');
            app.GridSwitchPTX.ValueChangedFcn = createCallbackFcn(app, @ShowGridPTX, true);
            app.GridSwitchPTX.Value = 'On';
            app.ColormapDropDown_3Label = uilabel(app.PTXpathTab);
            app.ColormapDropDown_3Label.Text = 'Colormap';
            app.ColormapDropDownPTX = uidropdown(app.PTXpathTab);
            app.ColormapDropDownPTX.Items = {'Parula', 'Gray', 'Bone', 'Pink', 'jet'};
            app.ColormapDropDownPTX.ValueChangedFcn = createCallbackFcn(app, @ColormapPTXChanged, true);
            app.ColormapDropDownPTX.Value = 'jet';
            app.FieldLabel = uilabel(app.PTXpathTab);
            app.FieldLabel.Text = 'Field';
            app.DisplayfieldDropDownPTX = uidropdown(app.PTXpathTab);
            app.DisplayfieldDropDownPTX.Items = {'# of stable phases', 'Variance', 'liquid fraction', 'Gibbs', 'entropy', 'Gamma', 'Density', 'Density_liquid', 'Density_solid', 'Vp', 'Vs', 'Vp/Vs', 'Status', 'NumAssemblage', 'Field'};
            app.DisplayfieldDropDownPTX.ValueChangedFcn = createCallbackFcn(app, @PlotColorPTX, true);
            app.DisplayfieldDropDownPTX.Value = '# of stable phases';
            app.AddpointButton = uibutton(app.PTXpathTab, 'push');
            app.AddpointButton.ButtonPushedFcn = createCallbackFcn(app, @AddPTXPoint, true);
            app.AddpointButton.Tooltip = {'First click on the grid then add points to the table to generate a 2-D area profile accross a PTX path'};
            app.AddpointButton.Text = 'Add point';
            app.RemovelastButton = uibutton(app.PTXpathTab, 'push');
            app.RemovelastButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveLastPTXPoint, true);
            app.RemovelastButton.Text = 'Remove last';
            app.RemoveallButton = uibutton(app.PTXpathTab, 'push');
            app.RemoveallButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveAllPTXPoint, true);
            app.RemoveallButton.Text = 'Remove all';
            app.RemovepointButton = uibutton(app.PTXpathTab, 'push');
            app.RemovepointButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveNPTXPoint, true);
            app.RemovepointButton.Text = 'Remove point';
            app.PressureTemperatureCompositionlistofpointsPanel = uipanel(app.PTXpathTab);
            app.PressureTemperatureCompositionlistofpointsPanel.Title = 'Pressure/Temperature/Composition list of points';
            app.UITablePTX = uitable(app.PressureTemperatureCompositionlistofpointsPanel);
            app.UITablePTX.ColumnName = {'Point'; 'P [kbar]'; 'T [°C]'};
            app.UITablePTX.RowName = {};
            app.PointidLabel = uilabel(app.PTXpathTab);
            app.PointidLabel.Text = 'Point id';
            app.PointidEditField = uieditfield(app.PTXpathTab, 'numeric');
            app.PointidEditField.Limits = [1 Inf];
            app.PointidEditField.Value = 1;
            app.TabGroup7 = uitabgroup(app.PTXpathTab);
            app.ModeTab = uitab(app.TabGroup7);
            app.ModeTab.Title = 'Mode';
            app.UIAxesArea = uiaxes(app.ModeTab);
            title(app.UIAxesArea, 'Area diagram')
            xlabel(app.UIAxesArea, 'PTX point')
            ylabel(app.UIAxesArea, 'Mode [wt fraction]')
            zlabel(app.UIAxesArea, 'Z')
            app.ph1Tab = uitab(app.TabGroup7);
            app.ph1Tab.Title = ' ';
            app.PhComp1 = uiaxes(app.ph1Tab);
            title(app.PhComp1, 'Area diagram')
            xlabel(app.PhComp1, 'PTX point')
            ylabel(app.PhComp1, 'Oxides [wt fraction]')
            zlabel(app.PhComp1, 'Z')
            app.ph2Tab = uitab(app.TabGroup7);
            app.ph2Tab.Title = ' ';
            app.PhComp2 = uiaxes(app.ph2Tab);
            title(app.PhComp2, 'Area diagram')
            xlabel(app.PhComp2, 'PTX point')
            ylabel(app.PhComp2, 'Oxides [wt fraction]')
            zlabel(app.PhComp2, 'Z')
            app.ph3Tab = uitab(app.TabGroup7);
            app.ph3Tab.Title = ' ';
            app.PhComp3 = uiaxes(app.ph3Tab);
            title(app.PhComp3, 'Area diagram')
            xlabel(app.PhComp3, 'PTX point')
            ylabel(app.PhComp3, 'Oxides [wt fraction]')
            zlabel(app.PhComp3, 'Z')
            app.ph4Tab = uitab(app.TabGroup7);
            app.ph4Tab.Title = ' ';
            app.PhComp4 = uiaxes(app.ph4Tab);
            title(app.PhComp4, 'Area diagram')
            xlabel(app.PhComp4, 'PTX point')
            ylabel(app.PhComp4, 'Oxides [wt fraction]')
            zlabel(app.PhComp4, 'Z')
            app.ph5Tab = uitab(app.TabGroup7);
            app.ph5Tab.Title = ' ';
            app.PhComp5 = uiaxes(app.ph5Tab);
            title(app.PhComp5, 'Area diagram')
            xlabel(app.PhComp5, 'PTX point')
            ylabel(app.PhComp5, 'Oxides [wt fraction]')
            zlabel(app.PhComp5, 'Z')
            app.ph6Tab = uitab(app.TabGroup7);
            app.ph6Tab.Title = ' ';
            app.PhComp6 = uiaxes(app.ph6Tab);
            title(app.PhComp6, 'Area diagram')
            xlabel(app.PhComp6, 'PTX point')
            ylabel(app.PhComp6, 'Oxides [wt fraction]')
            zlabel(app.PhComp6, 'Z')
            app.ph7Tab = uitab(app.TabGroup7);
            app.ph7Tab.Title = ' ';
            app.PhComp7 = uiaxes(app.ph7Tab);
            title(app.PhComp7, 'Area diagram')
            xlabel(app.PhComp7, 'PTX point')
            ylabel(app.PhComp7, 'Oxides [wt fraction]')
            zlabel(app.PhComp7, 'Z')
            app.ph8Tab = uitab(app.TabGroup7);
            app.ph8Tab.Title = ' ';
            app.PhComp8 = uiaxes(app.ph8Tab);
            title(app.PhComp8, 'Area diagram')
            xlabel(app.PhComp8, 'PTX point')
            ylabel(app.PhComp8, 'Oxides [wt fraction]')
            zlabel(app.PhComp8, 'Z')
            app.ph9Tab = uitab(app.TabGroup7);
            app.ph9Tab.Title = ' ';
            app.PhComp9 = uiaxes(app.ph9Tab);
            title(app.PhComp9, 'Area diagram')
            xlabel(app.PhComp9, 'PTX point')
            ylabel(app.PhComp9, 'Oxides [wt fraction]')
            zlabel(app.PhComp9, 'Z')
            app.ph10Tab = uitab(app.TabGroup7);
            app.ph10Tab.Title = ' ';
            app.PhComp10 = uiaxes(app.ph10Tab);
            title(app.PhComp10, 'Area diagram')
            xlabel(app.PhComp10, 'PTX point')
            ylabel(app.PhComp10, 'Oxides [wt fraction]')
            zlabel(app.PhComp10, 'Z')
            app.ph11Tab = uitab(app.TabGroup7);
            app.ph11Tab.Title = ' ';
            app.PhComp11 = uiaxes(app.ph11Tab);
            title(app.PhComp11, 'Area diagram')
            xlabel(app.PhComp11, 'PTX point')
            ylabel(app.PhComp11, 'Oxides [wt fraction]')
            zlabel(app.PhComp11, 'Z')
            app.ph12Tab = uitab(app.TabGroup7);
            app.ph12Tab.Title = ' ';
            app.PhComp12 = uiaxes(app.ph12Tab);
            title(app.PhComp12, 'Area diagram')
            xlabel(app.PhComp12, 'PTX point')
            ylabel(app.PhComp12, 'Oxides [wt fraction]')
            zlabel(app.PhComp12, 'Z')
            app.ph13Tab = uitab(app.TabGroup7);
            app.ph13Tab.Title = ' ';
            app.PhComp13 = uiaxes(app.ph13Tab);
            title(app.PhComp13, 'Area diagram')
            xlabel(app.PhComp13, 'PTX point')
            ylabel(app.PhComp13, 'Oxides [wt fraction]')
            zlabel(app.PhComp13, 'Z')
            app.ph14Tab = uitab(app.TabGroup7);
            app.ph14Tab.Title = ' ';
            app.PhComp14 = uiaxes(app.ph14Tab);
            title(app.PhComp14, 'Area diagram')
            xlabel(app.PhComp14, 'PTX point')
            ylabel(app.PhComp14, 'Oxides [wt fraction]')
            zlabel(app.PhComp14, 'Z')
            app.ph15Tab = uitab(app.TabGroup7);
            app.ph15Tab.Title = ' ';
            app.PhComp15 = uiaxes(app.ph15Tab);
            title(app.PhComp15, 'Area diagram')
            xlabel(app.PhComp15, 'PTX point')
            ylabel(app.PhComp15, 'Oxides [wt fraction]')
            zlabel(app.PhComp15, 'Z')
            app.ph16Tab = uitab(app.TabGroup7);
            app.ph16Tab.Title = ' ';
            app.PhComp16 = uiaxes(app.ph16Tab);
            title(app.PhComp16, 'Area diagram')
            xlabel(app.PhComp16, 'PTX point')
            ylabel(app.PhComp16, 'Oxides [wt fraction]')
            zlabel(app.PhComp16, 'Z')
            app.ph17Tab = uitab(app.TabGroup7);
            app.ph17Tab.Title = ' ';
            app.PhComp17 = uiaxes(app.ph17Tab);
            title(app.PhComp17, 'Area diagram')
            xlabel(app.PhComp17, 'PTX point')
            ylabel(app.PhComp17, 'Oxides [wt fraction]')
            zlabel(app.PhComp17, 'Z')
            app.ph18Tab = uitab(app.TabGroup7);
            app.ph18Tab.Title = ' ';
            app.PhComp18 = uiaxes(app.ph18Tab);
            title(app.PhComp18, 'Area diagram')
            xlabel(app.PhComp18, 'PTX point')
            ylabel(app.PhComp18, 'Oxides [wt fraction]')
            zlabel(app.PhComp18, 'Z')
            app.ph19Tab = uitab(app.TabGroup7);
            app.ph19Tab.Title = ' ';
            app.PhComp19 = uiaxes(app.ph19Tab);
            title(app.PhComp19, 'Area diagram')
            xlabel(app.PhComp19, 'PTX point')
            ylabel(app.PhComp19, 'Oxides [wt fraction]')
            zlabel(app.PhComp19, 'Z')
            app.ph20Tab = uitab(app.TabGroup7);
            app.ph20Tab.Title = ' ';
            app.PhComp20 = uiaxes(app.ph20Tab);
            title(app.PhComp20, 'Area diagram')
            xlabel(app.PhComp20, 'PTX point')
            ylabel(app.PhComp20, 'Oxides [wt fraction]')
            zlabel(app.PhComp20, 'Z')
            app.ComputecompositionCheckBox = uicheckbox(app.PTXpathTab);
            app.ComputecompositionCheckBox.Tooltip = {'This option compute the compositional profiles of all stable phases and display them as 2-D areas'};
            app.ComputecompositionCheckBox.Text = {'Compute '; 'composition?'};
            app.UIAxesPTX = uiaxes(app.PTXpathTab);
            title(app.UIAxesPTX, 'Pseudosection')
            xlabel(app.UIAxesPTX, 'Temperature [Celcius]')
            ylabel(app.UIAxesPTX, 'Pressure [kbar]')
            zlabel(app.UIAxesPTX, 'Z')
            app.TraceElementsTab = uitab(app.TabGroup3);
            app.TraceElementsTab.Title = 'TraceElements';
            app.ExportfigureButton_2 = uibutton(app.TraceElementsTab, 'push');
            app.ExportfigureButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportFigTE, true);
            app.ExportfigureButton_2.Text = 'Export figure';
            app.FileEditField_2Label = uilabel(app.TraceElementsTab);
            app.FileEditField_2Label.Text = 'File:';
            app.FileEditField_2 = uieditfield(app.TraceElementsTab, 'text');
            app.FileEditField_2.Value = './fig_name_TE';
            app.StableAssemblagePanel_2 = uipanel(app.TraceElementsTab);
            app.StableAssemblagePanel_2.Title = 'Stable Assemblage';
            app.UITableStableAssemblageTE = uitable(app.StableAssemblagePanel_2);
            app.UITableStableAssemblageTE.ColumnName = {'Stable phase'; 'Mol fraction'};
            app.UITableStableAssemblageTE.RowName = {};
            app.TextAreaInfoTE = uitextarea(app.StableAssemblagePanel_2);
            app.SaveSpectraButton = uibutton(app.TraceElementsTab, 'push');
            app.SaveSpectraButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSpectraToFile, true);
            app.SaveSpectraButton.Enable = 'off';
            app.SaveSpectraButton.Text = 'Save Spectra';
            app.phaseeqCheckBox = uicheckbox(app.TraceElementsTab);
            app.phaseeqCheckBox.Enable = 'off';
            app.phaseeqCheckBox.Text = '+ phase eq?';
            app.ReferenceTextArea = uitextarea(app.TraceElementsTab);
            app.ReferenceTextArea.Editable = 'off';
            app.ReferenceTextArea.Value = {'MagmaX allow to predict Trace Element partitioning at suprasolidus conditions for  mafic to ultramafic systems. '; 'The routine has been developped by Julien Cornet '; '2018, MsC thesis, Durham University, DOI: 10.13140/RG.2.2.25669.14562.'; 'Julien''s work accounts for olivine, amphibole, plagioclase, garnet, clinopyroxene and orthopyroxene.'};
            app.ComputetraceelementpartitioningButton = uibutton(app.TraceElementsTab, 'push');
            app.ComputetraceelementpartitioningButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeTEpartitioning, true);
            app.ComputetraceelementpartitioningButton.Text = {'Compute trace element '; 'partitioning'};
            app.UITableTraceElement = uitable(app.TraceElementsTab);
            app.UITableTraceElement.ColumnName = {'El'; 'C [ppm]'};
            app.UITableTraceElement.RowName = {};
            app.UITableTraceElement.ColumnEditable = [true true];
            app.Switch_2 = uiswitch(app.TraceElementsTab, 'slider');
            app.Switch_2.Items = {'Preset', 'Custom'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @TraceElementSelectionSwitch, true);
            app.Switch_2.Value = 'Preset';
            app.LoadfromfileButton_2 = uibutton(app.TraceElementsTab, 'push');
            app.LoadfromfileButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadTEBulkFromFile, true);
            app.LoadfromfileButton_2.Enable = 'off';
            app.LoadfromfileButton_2.Text = 'Load from file';
            app.PLOTFUNCTIONegelementratioLabel = uilabel(app.TraceElementsTab);
            app.PLOTFUNCTIONegelementratioLabel.Text = 'PLOT FUNCTION (e.g., element ratio)';
            app.CHOOSETRACECOMPOSITIONLabel = uilabel(app.TraceElementsTab);
            app.CHOOSETRACECOMPOSITIONLabel.Text = 'CHOOSE TRACE COMPOSITION';
            app.Validsufficesare_s_m_hb_cpx_opx_pl_g_olLabel = uilabel(app.TraceElementsTab);
            app.Validsufficesare_s_m_hb_cpx_opx_pl_g_olLabel.Text = {'''s'', ''m'', ''hb'', ''cpx'', ''opx'', ''pl'', ''g'', ''ol'''; 'where ''s'' and ''m'' stand for solid and melt'};
            app.PrefixLabel = uilabel(app.TraceElementsTab);
            app.PrefixLabel.Text = 'Prefix';
            app.DisplayButton = uibutton(app.TraceElementsTab, 'push');
            app.DisplayButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTE, true);
            app.DisplayButton.Text = 'Display';
            app.TitleEditFieldTE = uieditfield(app.TraceElementsTab, 'text');
            app.TitleEditFieldTE.Value = 'Average Peridotite (USGS)';
            app.ExampleDropDownLabel = uilabel(app.TraceElementsTab);
            app.ExampleDropDownLabel.Text = 'Example';
            app.ExampleDropDown = uidropdown(app.TraceElementsTab);
            app.ExampleDropDown.Items = {'0', '1'};
            app.ExampleDropDown.ValueChangedFcn = createCallbackFcn(app, @ExampleTE, true);
            app.ExampleDropDown.Value = '0';
            app.CommandwindowLabel = uilabel(app.TraceElementsTab);
            app.CommandwindowLabel.Text = {'Command '; 'window'};
            app.CommandwindowTextArea = uitextarea(app.TraceElementsTab);
            app.CommandwindowTextArea.Tooltip = {'Here you can enter what you want to display. For instance "s.Dy/s.Yb" will compute the ratio "Dy/Yb" for the solid part'};
            app.CommandwindowTextArea.Value = {'s.Dy ./ s.Yb'};
            app.GridSwitchLabel = uilabel(app.TraceElementsTab);
            app.GridSwitchLabel.Text = 'Grid';
            app.GridSwitch = uiswitch(app.TraceElementsTab, 'slider');
            app.GridSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowGridTE, true);
            app.GridSwitch.Value = 'On';
            app.ColormapDropDown_2Label = uilabel(app.TraceElementsTab);
            app.ColormapDropDown_2Label.Text = 'Colormap';
            app.ColormapDropDown_2 = uidropdown(app.TraceElementsTab);
            app.ColormapDropDown_2.Items = {'Parula', 'Gray', 'Bone', 'Pink', 'jet'};
            app.ColormapDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ColormapTEchanged, true);
            app.ColormapDropDown_2.Value = 'jet';
            app.Image3 = uiimage(app.TraceElementsTab);
            app.Image3.ImageSource = 'MagmaX2.png';
            app.UIAxesTE = uiaxes(app.TraceElementsTab);
            title(app.UIAxesTE, 'Trace Elements Pseudosection')
            xlabel(app.UIAxesTE, 'Temperature [Celcius]')
            ylabel(app.UIAxesTE, 'Pressure [kbar]')
            zlabel(app.UIAxesTE, 'Z')
            app.UIAxesTESpectrum = uiaxes(app.TraceElementsTab);
            title(app.UIAxesTESpectrum, 'Spectra')
            xlabel(app.UIAxesTESpectrum, 'Trace Element')
            ylabel(app.UIAxesTESpectrum, 'Concentration [ppm]')
            zlabel(app.UIAxesTESpectrum, 'Z')
            app.UIAxesTESpectrum.XTick = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28];
            app.UIAxesTESpectrum.XTickLabel = {'Cs'; 'Rb'; 'K'; 'Ba'; 'Sr'; 'La'; 'Ce'; 'Pr'; 'Nd'; 'Sm'; 'Eu'; 'Gd'; 'Tb'; 'Dy'; 'Y'; 'Ho'; 'Er'; 'Tm'; 'Yb'; 'Lu'; 'Sc'; 'Ti'; 'Hf'; 'Zr'; 'U'; 'Th'; 'Ta'; 'Nb'};
            app.ComputeTCsolutionforeverypointCheckBox = uicheckbox(app.MAGEMinPseudoSectionViewerUIFigure);
            app.ComputeTCsolutionforeverypointCheckBox.Enable = 'off';
            app.ComputeTCsolutionforeverypointCheckBox.Visible = 'off';
            app.ComputeTCsolutionforeverypointCheckBox.Text = 'Compute TC solution for every point';
            % Show the figure after all components are created
            app.MAGEMinPseudoSectionViewerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PlotPseudosection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MAGEMinPseudoSectionViewerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MAGEMinPseudoSectionViewerUIFigure)
        end
    end
end